classdef Dmd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DigitalMicromirrorDeviceUIFigure  matlab.ui.Figure
        TextArea      matlab.ui.control.TextArea
        Step2Button   matlab.ui.control.Button
        Step1Button   matlab.ui.control.Button
        TextArea_2    matlab.ui.control.TextArea
        TextArea_3    matlab.ui.control.TextArea
        Step3Button   matlab.ui.control.Button
        TextArea_4    matlab.ui.control.TextArea
        Step4Button   matlab.ui.control.Button
        TextArea_5    matlab.ui.control.TextArea
        Step5Button   matlab.ui.control.Button
        TextArea_6    matlab.ui.control.TextArea
        RunAllButton  matlab.ui.control.Button
        Step6Button   matlab.ui.control.Button
        TextArea_7    matlab.ui.control.TextArea
        Step7Button   matlab.ui.control.Button
        TextArea_8    matlab.ui.control.TextArea
    end


    properties (Access = private)
        uiStep1Gaussian    otslm.ui.simple.gaussian
        uiStep2LgMode      otslm.ui.simple.lgmode
        uiStep3Linear      otslm.ui.simple.linear
        uiStep3Combine     otslm.ui.tools.Combine
        uiStep4Finalize    otslm.ui.tools.Finalize
        uiStep5Dither      otslm.ui.tools.Dither
        uiStep6Finalize    otslm.ui.tools.Finalize
        uiStep6FinalizeInc otslm.ui.tools.Finalize
        uiStep7Visualise   otslm.ui.tools.Visualise
    end
    
    properties (Constant)
        szPattern = '256,512';              % Size of the pattern
        
        nameStep1Gaussian='incidentBeam'    % Pattern name for incident beam
        nameStep2LgMode='lgmode'            % Pattern name for lgmode pattern
        nameStep3Linear='linear'            % Pattern name for linear grating
        nameStep3Combined='combinedPhase'   % Pattern name for combined phase pattern
        nameStep4Finalize='amplitudeOnly'   % Pattern name for amplitude only pattern
        nameStep5Dither='binaryAmplitude'   % Pattern name for binary amplitude grating
        nameStep6Finalize='visBinaryAmplitude' % Pattern name for binary amplitude grating for visualisation
        nameStep6FinalizeInc='visIncidentBeam' % Pattern name for incident beam rotated by 45 degrees
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Step1Button
        function Step1ButtonPushed(app, event)
            
            % Initialize the user interface
            if isempty(app.uiStep1Gaussian) || ~isvalid(app.uiStep1Gaussian)
                app.uiStep1Gaussian = otslm.ui.simple.gaussian();
            end
            
            % Configure the window
            app.uiStep1Gaussian.NameEditField.Value = app.nameStep1Gaussian;
            app.uiStep1Gaussian.SizeEditField.Value = app.szPattern;
            app.uiStep1Gaussian.ProfileDropDown.Value = '2d';
            app.uiStep1Gaussian.SigmaSpinner.Value = 128;
            app.uiStep1Gaussian.AspectSpinner.Value = 2;
            app.uiStep1Gaussian.DisplayDropDown.Value = 'Raw phase mask';
            app.uiStep1Gaussian.CentreEditField.Value = '256, 128';
            
            % Update window display
            app.uiStep1Gaussian.updateView();

        end

        % Button pushed function: Step2Button
        function Step2ButtonPushed(app, event)
            
            % Initialize the user interface
            if isempty(app.uiStep2LgMode) || ~isvalid(app.uiStep2LgMode)
                app.uiStep2LgMode = otslm.ui.simple.lgmode();
            end
            
            % Configure the window
            app.uiStep2LgMode.NameEditField.Value = app.nameStep2LgMode;
            app.uiStep2LgMode.SizeEditField.Value = app.szPattern;
            app.uiStep2LgMode.RadialSpinner.Value = 3;
            app.uiStep2LgMode.AzimuthalSpinner.Value = 3;
            app.uiStep2LgMode.CorrectionSpinner.Value = 1.0;
            app.uiStep2LgMode.AspectSpinner.Value = 2.0;
            app.uiStep2LgMode.DisplayDropDown.Value = 'raw';
            app.uiStep2LgMode.CentreEditField.Value = '256, 128';
            app.uiStep2LgMode.RadiusSpinner.Value = 30;
            app.uiStep2LgMode.OutputTypeDropDown.Value = 'complex';
            
            % Update window display
            app.uiStep2LgMode.updateView();

        end

        % Button pushed function: Step4Button
        function Step4ButtonPushed(app, event)
            
            % Initialize the user interface
            if isempty(app.uiStep4Finalize) || ~isvalid(app.uiStep4Finalize)
                app.uiStep4Finalize = otslm.ui.tools.Finalize();
            end
            
            % Configure the window
            app.uiStep4Finalize.NameEditField.Value = app.nameStep4Finalize;
            app.uiStep4Finalize.PhaseDropDown.Value = app.nameStep3Combined;
            app.uiStep4Finalize.AmplitudeDropDown.Value = ['abs(' app.nameStep2LgMode ')'];
            app.uiStep4Finalize.ColormapDropDown.Value = 'gray';
            
            % Update window display
            app.uiStep4Finalize.updateView();
            
        end

        % Button pushed function: Step3Button
        function Step3ButtonPushed(app, event)
            
            %% Linear grating
            
            % Initialize the user interface
            if isempty(app.uiStep3Linear) || ~isvalid(app.uiStep3Linear)
                app.uiStep3Linear = otslm.ui.simple.linear();
            end
            
            % Configure the window
            app.uiStep3Linear.NameEditField.Value = app.nameStep3Linear;
            app.uiStep3Linear.SizeEditField.Value = app.szPattern;
            app.uiStep3Linear.AngledegSpinner.Value = 120;
            
            % Update window display
            app.uiStep3Linear.updateView();
            
            %% Combine interface
            
            % Initialize the user interface
            if isempty(app.uiStep3Combine) || ~isvalid(app.uiStep3Combine)
                app.uiStep3Combine = otslm.ui.tools.Combine();
            end
            
            % Configure the window
            app.uiStep3Combine.NameEditField.Value = app.nameStep3Combined;
            app.uiStep3Combine.UITable.Data(1, :).names = ['angle(' app.nameStep2LgMode ')./(2*pi)' ];
            app.uiStep3Combine.UITable.Data(2, :).names = app.nameStep3Linear;
            app.uiStep3Combine.MethodDropDown.Value = 'add';

            % Update window display
            app.uiStep3Combine.updateView();
            
        end

        % Button pushed function: Step5Button
        function Step5ButtonPushed(app, event)

            % Initialize the user interface
            if isempty(app.uiStep5Dither) || ~isvalid(app.uiStep5Dither)
                app.uiStep5Dither = otslm.ui.tools.Dither();
            end
            
            % Configure the window
            app.uiStep5Dither.NameEditField.Value = app.nameStep5Dither;
            app.uiStep5Dither.InputDropDown.Value = app.nameStep4Finalize;
            app.uiStep5Dither.MethodDropDown.Value = 'random';
            
            % Update window display
            app.uiStep5Dither.updateView();
            
        end

        % Button pushed function: RunAllButton
        function RunAllButtonPushed(app, event)
            
            % Run all the steps
            app.Step1ButtonPushed(event);
            app.Step2ButtonPushed(event);
            app.Step3ButtonPushed(event);
            app.Step4ButtonPushed(event);
            app.Step5ButtonPushed(event);
            app.Step6ButtonPushed(event);
            app.Step7ButtonPushed(event);
        end

        % Close request function: DigitalMicromirrorDeviceUIFigure
        function DigitalMicromirrorDeviceUIFigureCloseRequest(app, event)
            
            % Delete sub-apps
            if isvalid(app.uiStep1Gaussian)
                notify(app.uiStep1Gaussian.GaussianUIFigure, 'Close');
            end
            if isvalid(app.uiStep2LgMode)
                notify(app.uiStep2LgMode.LaguerreGaussianUIFigure, 'Close');
            end
            if isvalid(app.uiStep3Combine)
                notify(app.uiStep3Combine.CombineUIFigure, 'Close');
            end
            if isvalid(app.uiStep3Linear)
                notify(app.uiStep3Linear.LinearGratingUIFigure, 'Close');
            end
            if isvalid(app.uiStep4Finalize)
                notify(app.uiStep4Finalize.FinalizeUIFigure, 'Close');
            end
            if isvalid(app.uiStep5Dither)
                notify(app.uiStep5Dither.DitherUIFigure, 'Close');
            end
            if isvalid(app.uiStep6Finalize)
                notify(app.uiStep6Finalize.FinalizeUIFigure, 'Close');
            end
            if isvalid(app.uiStep6FinalizeInc)
                notify(app.uiStep6FinalizeInc.FinalizeUIFigure, 'Close');
            end
            if isvalid(app.uiStep7Visualise)
                notify(app.uiStep7Visualise.VisualiseUIFigure, 'Close');
            end
            
            % Close our app
            delete(app)
            
        end

        % Button pushed function: Step6Button
        function Step6ButtonPushed(app, event)
            
            % Initialize the user interface
            if isempty(app.uiStep6Finalize) || ~isvalid(app.uiStep6Finalize)
                app.uiStep6Finalize = otslm.ui.tools.Finalize();
            end
            
            % Configure the window
            app.uiStep6Finalize.NameEditField.Value = app.nameStep6Finalize;
            app.uiStep6Finalize.PhaseDropDown.Value = app.nameStep5Dither;
            app.uiStep6Finalize.RotationPackDropDown.Value = '45deg';
            app.uiStep6Finalize.ColormapDropDown.Value = 'gray';
            app.uiStep6Finalize.ModuloDropDown.Value = 'none';
            
            % Update window display
            app.uiStep6Finalize.updateView();
            
            %% Incident beam
            
            % Initialize the user interface
            if isempty(app.uiStep6FinalizeInc) || ~isvalid(app.uiStep6FinalizeInc)
                app.uiStep6FinalizeInc = otslm.ui.tools.Finalize();
            end
            
            % Configure the window
            app.uiStep6FinalizeInc.NameEditField.Value = app.nameStep6FinalizeInc;
            app.uiStep6FinalizeInc.PhaseDropDown.Value = app.nameStep1Gaussian;
            app.uiStep6FinalizeInc.RotationPackDropDown.Value = '45deg';
            app.uiStep6FinalizeInc.ColormapDropDown.Value = 'gray';
            app.uiStep6FinalizeInc.ModuloDropDown.Value = 'none';
            
            % Update window display
            app.uiStep6FinalizeInc.updateView();
            
        end

        % Button pushed function: Step7Button
        function Step7ButtonPushed(app, event)
            
            % Initialize the user interface
            if isempty(app.uiStep7Visualise) || ~isvalid(app.uiStep7Visualise)
                app.uiStep7Visualise = otslm.ui.tools.Visualise();
            end
            
            % Configure the window
            app.uiStep7Visualise.IncidentDropDown.Value = app.nameStep6FinalizeInc;
            app.uiStep7Visualise.AmplitudeDropDown.Value = app.nameStep6Finalize;
            app.uiStep7Visualise.LogscaleCheckBox.Value = true;

            % Update window display
            app.uiStep7Visualise.updateView();
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DigitalMicromirrorDeviceUIFigure = uifigure;
            app.DigitalMicromirrorDeviceUIFigure.Name = 'Digital Micro-mirror Device';
            app.DigitalMicromirrorDeviceUIFigure.CloseRequestFcn = createCallbackFcn(app, @DigitalMicromirrorDeviceUIFigureCloseRequest, true);
            app.TextArea = uitextarea(app.DigitalMicromirrorDeviceUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'This example demonstrates how a digital micromirror device (DMD) can be simulated using the toolbox.  A DMD is a amplitude only device with small mirrors which deflect light in two directions corresponding to a on state and an off state for each pixel.  In this example we create a binary amplitude pattern for transforming a Gaussian beam to a LG beam in the far-field.  This example shows how to generate binary amplitude-only phase patterns that can be used to modulate the phase and amplitude of the beam.'};
            app.Step2Button = uibutton(app.DigitalMicromirrorDeviceUIFigure, 'push');
            app.Step2Button.ButtonPushedFcn = createCallbackFcn(app, @Step2ButtonPushed, true);
            app.Step2Button.Text = 'Step 2';
            app.Step1Button = uibutton(app.DigitalMicromirrorDeviceUIFigure, 'push');
            app.Step1Button.ButtonPushedFcn = createCallbackFcn(app, @Step1ButtonPushed, true);
            app.Step1Button.Text = 'Step 1';
            app.TextArea_2 = uitextarea(app.DigitalMicromirrorDeviceUIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'The first step is to describe the incident illumination.  This step creates a new image using otslm.simple.gaussian to represent the intensity profile of the light on the SLM.  The DMD we are simulating has a 1:2 pixel aspect ratio, this results in a 1:2 aspect ratio for the size and aspect parameter.'};
            app.TextArea_3 = uitextarea(app.DigitalMicromirrorDeviceUIFigure);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'To create the LG beam we use otslm.simple.lgmode.  We use this to generate the complex field amplitude containing both the phase and amplitude information for the desired beam shape.  The amplitude and phase are given by abs(lgpattern) and angle(lgpattern) respectively.'};
            app.Step3Button = uibutton(app.DigitalMicromirrorDeviceUIFigure, 'push');
            app.Step3Button.ButtonPushedFcn = createCallbackFcn(app, @Step3ButtonPushed, true);
            app.Step3Button.Text = 'Step 3';
            app.TextArea_4 = uitextarea(app.DigitalMicromirrorDeviceUIFigure);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'To control the position of the beam we add in a linear grating generated using otslm.simple.linear.  The grating and angle(lgpattern) can then be combined with otslm.simple.combine.  We need to divide angle(lgpattern) by 2pi so that it matches the'; 'input of otslm.simple.combine.'};
            app.Step4Button = uibutton(app.DigitalMicromirrorDeviceUIFigure, 'push');
            app.Step4Button.ButtonPushedFcn = createCallbackFcn(app, @Step4ButtonPushed, true);
            app.Step4Button.Text = 'Step 4';
            app.TextArea_5 = uitextarea(app.DigitalMicromirrorDeviceUIFigure);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'To combine the phase and amplitude patterns into a amplitude-only pattern we use otslm.tools.finalize.  We set the output as a amplitude pattern with a gray colormap.'};
            app.Step5Button = uibutton(app.DigitalMicromirrorDeviceUIFigure, 'push');
            app.Step5Button.ButtonPushedFcn = createCallbackFcn(app, @Step5ButtonPushed, true);
            app.Step5Button.Text = 'Step 5';
            app.TextArea_6 = uitextarea(app.DigitalMicromirrorDeviceUIFigure);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'otslm.tools.finalize is able to convert the pattern to binary-amplitude however, instead, we can use otslm.tools.dither in order to have more control over the method used.'};
            app.RunAllButton = uibutton(app.DigitalMicromirrorDeviceUIFigure, 'push');
            app.RunAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllButtonPushed, true);
            app.RunAllButton.Text = 'Run All';
            app.Step6Button = uibutton(app.DigitalMicromirrorDeviceUIFigure, 'push');
            app.Step6Button.ButtonPushedFcn = createCallbackFcn(app, @Step6ButtonPushed, true);
            app.Step6Button.Text = 'Step 6';
            app.TextArea_7 = uitextarea(app.DigitalMicromirrorDeviceUIFigure);
            app.TextArea_7.Editable = 'off';
            app.TextArea_7.Value = {'The DMD we are simulating has pixels packed in a diamond configuration giving the device image a 1:2 aspect ratio.  To simulate this we use otslm.tools.finalize to pack the pattern into a larger image on a 45-degree angle.  This needs to be repeated for the illumination.'};
            app.Step7Button = uibutton(app.DigitalMicromirrorDeviceUIFigure, 'push');
            app.Step7Button.ButtonPushedFcn = createCallbackFcn(app, @Step7ButtonPushed, true);
            app.Step7Button.Text = 'Step 7';
            app.TextArea_8 = uitextarea(app.DigitalMicromirrorDeviceUIFigure);
            app.TextArea_8.Editable = 'off';
            app.TextArea_8.Value = {'The final step is to visualise the diffraction grating illuminated by our incident illumination.  We use otslm.tools.visualise to do this.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Dmd

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DigitalMicromirrorDeviceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DigitalMicromirrorDeviceUIFigure)
        end
    end
end