classdef MixingTwoBeams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MixingTwoBeamsUIFigure  matlab.ui.Figure
        TextArea                matlab.ui.control.TextArea
        Step2Button             matlab.ui.control.Button
        Step1Button             matlab.ui.control.Button
        TextArea_2              matlab.ui.control.TextArea
        TextArea_3              matlab.ui.control.TextArea
        Step3Button             matlab.ui.control.Button
        TextArea_4              matlab.ui.control.TextArea
        Step4Button             matlab.ui.control.Button
        TextArea_5              matlab.ui.control.TextArea
        Step5Button             matlab.ui.control.Button
        TextArea_6              matlab.ui.control.TextArea
        RunAllButton            matlab.ui.control.Button
    end


    properties (Access = private)
        uiStep1Gaussian    otslm.ui.simple.gaussian
        uiStep2Linear      otslm.ui.simple.linear
        uiStep3Linear      otslm.ui.simple.linear
        uiStep3LgMode      otslm.ui.simple.lgmode
        uiStep4Combine     otslm.ui.tools.Combine
        uiStep5Visualise   otslm.ui.tools.Visualise
    end
    
    properties (Constant)
        nameStep1Gaussian='incidentBeam'    % Pattern name for incident beam
        nameStep2Linear='linear1'           % Pattern name for linear grating 1
        nameStep3Linear='linear2'           % Pattern name for linear grating 2
        nameStep3LgMode='lgmode'            % Pattern name for lgmode pattern
        nameStep4Combine='phaseGrating'     % Pattern name for final grating
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Step1Button
        function Step1ButtonPushed(app, event)
            
            % Initialize the user interface
            if isempty(app.uiStep1Gaussian) || ~isvalid(app.uiStep1Gaussian)
                app.uiStep1Gaussian = otslm.ui.simple.gaussian();
            end
            
            % Configure the window
            app.uiStep1Gaussian.NameEditField.Value = app.nameStep1Gaussian;
            app.uiStep1Gaussian.ProfileDropDown.Value = '2d';
            app.uiStep1Gaussian.SigmaSpinner.Value = 128;
            app.uiStep1Gaussian.AngledegSpinner.Value = 35;
            app.uiStep1Gaussian.AspectSpinner.Value = 1.3;
            app.uiStep1Gaussian.DisplayDropDown.Value = 'Raw phase mask';
            
            % Update window display
            app.uiStep1Gaussian.updateView();

        end

        % Button pushed function: Step2Button
        function Step2ButtonPushed(app, event)
            
            % Initialize the user interface
            if isempty(app.uiStep2Linear) || ~isvalid(app.uiStep2Linear)
                app.uiStep2Linear = otslm.ui.simple.linear();
            end
            
            % Configure the window
            app.uiStep2Linear.NameEditField.Value = app.nameStep2Linear;
            app.uiStep2Linear.AngledegSpinner.Value = 45;
            
            % Update window display
            app.uiStep2Linear.updateView();

        end

        % Button pushed function: Step4Button
        function Step4ButtonPushed(app, event)
            
            % Initialize the user interface
            if isempty(app.uiStep4Combine) || ~isvalid(app.uiStep4Combine)
                app.uiStep4Combine = otslm.ui.tools.Combine();
            end
            
            % Configure the window
            app.uiStep4Combine.NameEditField.Value = app.nameStep4Combine;
            app.uiStep4Combine.UITable.Data(1, :).names = app.nameStep2Linear;
            app.uiStep4Combine.UITable.Data(2, :).names = [ app.nameStep3Linear, '+', app.nameStep3LgMode ];

            % Update window display
            app.uiStep4Combine.updateView();
            
        end

        % Button pushed function: Step3Button
        function Step3ButtonPushed(app, event)
            
            %% Linear grating
            
            % Initialize the user interface
            if isempty(app.uiStep3Linear) || ~isvalid(app.uiStep3Linear)
                app.uiStep3Linear = otslm.ui.simple.linear();
            end
            
            % Configure the window
            app.uiStep3Linear.NameEditField.Value = app.nameStep3Linear;
            app.uiStep3Linear.AngledegSpinner.Value = 120;
            
            % Update window display
            app.uiStep3Linear.updateView();
            
            %% LG Beam
                        
            % Initialize the user interface
            if isempty(app.uiStep3LgMode) || ~isvalid(app.uiStep3LgMode)
                app.uiStep3LgMode = otslm.ui.simple.lgmode();
            end
            
            % Configure the window
            app.uiStep3LgMode.NameEditField.Value = app.nameStep3LgMode;
            app.uiStep3LgMode.RadialSpinner.Value = 0;
            app.uiStep3LgMode.AzimuthalSpinner.Value = 3;
            
            % Update window display
            app.uiStep3LgMode.updateView();
            
        end

        % Button pushed function: Step5Button
        function Step5ButtonPushed(app, event)
            
            % Initialize the user interface
            if isempty(app.uiStep5Visualise) || ~isvalid(app.uiStep5Visualise)
                app.uiStep5Visualise = otslm.ui.tools.Visualise();
            end
            
            % Configure the window
            app.uiStep5Visualise.IncidentDropDown.Value = app.nameStep1Gaussian;
            app.uiStep5Visualise.PhaseDropDown.Value = app.nameStep4Combine;

            % Update window display
            app.uiStep5Visualise.updateView();
            
        end

        % Button pushed function: RunAllButton
        function RunAllButtonPushed(app, event)
            
            % Run all the steps
            app.Step1ButtonPushed(event);
            app.Step2ButtonPushed(event);
            app.Step3ButtonPushed(event);
            app.Step4ButtonPushed(event);
            app.Step5ButtonPushed(event);
        end

        % Close request function: MixingTwoBeamsUIFigure
        function MixingTwoBeamsUIFigureCloseRequest(app, event)
            
            % Delete sub-apps
            if isvalid(app.uiStep1Gaussian)
                notify(app.uiStep1Gaussian.GaussianUIFigure, 'Close');
            end
            if isvalid(app.uiStep2Linear)
                notify(app.uiStep2Linear.LinearGratingUIFigure, 'Close');
            end
            if isvalid(app.uiStep3Linear)
                notify(app.uiStep3Linear.LinearGratingUIFigure, 'Close');
            end
            if isvalid(app.uiStep3LgMode)
                notify(app.uiStep3LgMode.LaguerreGaussianUIFigure, 'Close');
            end
            if isvalid(app.uiStep4Combine)
                notify(app.uiStep4Combine.CombineUIFigure, 'Close');
            end
            if isvalid(app.uiStep5Visualise)
                notify(app.uiStep5Visualise.VisualiseUIFigure, 'Close');
            end
            
            % Close our app
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MixingTwoBeamsUIFigure = uifigure;
            app.MixingTwoBeamsUIFigure.Name = 'Mixing Two Beams';
            app.MixingTwoBeamsUIFigure.CloseRequestFcn = createCallbackFcn(app, @MixingTwoBeamsUIFigureCloseRequest, true);
            app.TextArea = uitextarea(app.MixingTwoBeamsUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'This example shows how you could combine the different tools in OTSLM to a diffraction grating which splits the incident beam into two spots: a Gaussian shaped spot and a Laguerre-Gaussian (LG) shaped spot.  These two beams can be individually positioned using the linear gratings.'};
            app.Step2Button = uibutton(app.MixingTwoBeamsUIFigure, 'push');
            app.Step2Button.ButtonPushedFcn = createCallbackFcn(app, @Step2ButtonPushed, true);
            app.Step2Button.Text = 'Step 2';
            app.Step1Button = uibutton(app.MixingTwoBeamsUIFigure, 'push');
            app.Step1Button.ButtonPushedFcn = createCallbackFcn(app, @Step1ButtonPushed, true);
            app.Step1Button.Text = 'Step 1';
            app.TextArea_2 = uitextarea(app.MixingTwoBeamsUIFigure);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'The first step is to describe the incident illumination.  This step creates a new image using otslm.simple.gaussian to represent the intensity profile of the light on the SLM.'};
            app.TextArea_3 = uitextarea(app.MixingTwoBeamsUIFigure);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'The first beam in this example is just the original beam deflected in another direction.  This is done using a otslm.simple.linear diffraction grating.'};
            app.Step3Button = uibutton(app.MixingTwoBeamsUIFigure, 'push');
            app.Step3Button.ButtonPushedFcn = createCallbackFcn(app, @Step3ButtonPushed, true);
            app.Step3Button.Text = 'Step 3';
            app.TextArea_4 = uitextarea(app.MixingTwoBeamsUIFigure);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'The second beam in this example is a LG beam generated using otslm.simple.lgmode.  We also generate a second diffraction grating to control the position of this beam.  The final diffraction grating for this beam is just the sum of the two patterns.'};
            app.Step4Button = uibutton(app.MixingTwoBeamsUIFigure, 'push');
            app.Step4Button.ButtonPushedFcn = createCallbackFcn(app, @Step4ButtonPushed, true);
            app.Step4Button.Text = 'Step 4';
            app.TextArea_5 = uitextarea(app.MixingTwoBeamsUIFigure);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'The phase patterns can be combined to generate the diffraction grating to place on the SLM.  To do this we use otslm.tools.combine to combine the two beams from step 2 and 3.  To mix the LG beam grating with the linear grating we enter both beam names separated by a addition (+) symbol.'};
            app.Step5Button = uibutton(app.MixingTwoBeamsUIFigure, 'push');
            app.Step5Button.ButtonPushedFcn = createCallbackFcn(app, @Step5ButtonPushed, true);
            app.Step5Button.Text = 'Step 5';
            app.TextArea_6 = uitextarea(app.MixingTwoBeamsUIFigure);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'The final step is to visualise the diffraction grating illuminated by our incident illumination.  We use otslm.tools.visualise to do this.'};
            app.RunAllButton = uibutton(app.MixingTwoBeamsUIFigure, 'push');
            app.RunAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllButtonPushed, true);
            app.RunAllButton.Text = 'Run All';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MixingTwoBeams

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MixingTwoBeamsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MixingTwoBeamsUIFigure)
        end
    end
end