classdef GerchbergSaxton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GerchbergSaxtonUIFigure        matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        GuessDropDownLabel             matlab.ui.control.Label
        GuessDropDown                  matlab.ui.control.DropDown
        IncidentDropDownLabel          matlab.ui.control.Label
        IncidentDropDown               matlab.ui.control.DropDown
        AdaptiveAdaptiveSpinnerLabel   matlab.ui.control.Label
        AdaptiveAdaptiveSpinner        matlab.ui.control.Spinner
        TargetDropDownLabel            matlab.ui.control.Label
        TargetDropDown                 matlab.ui.control.DropDown
        RunButton                      matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        IterationsSpinnerLabel         matlab.ui.control.Label
        IterationsSpinner              matlab.ui.control.Spinner
        GUIUpdateFreqSpinnerLabel      matlab.ui.control.Label
        GUIUpdateFreqSpinner           matlab.ui.control.Spinner
        Gauge                          matlab.ui.control.LinearGauge
        UIAxes                         matlab.ui.control.UIAxes
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end


    properties (Access = private)
        bRunning        % Is the method currently running
        nIteration      % The iteration we are currently up to
        iterTimer       % Callback that runs the gs method
        result          % The resulting pattern
        
        watchTimer      % Timer to watch changes to input images
        oldTarget       % Old target iamge
        oldGuess        % Old guess image
        oldIncident     % Old incident image
        
        itermethod      % The iterative method object
    end

    methods (Access = private)
        
        function image = getSingleImage(app, name)
            % Get images in a safe way
            
            if isempty(name)
                image = [];
            else
                try
                    image = evalin('base', name);
                catch ME
                    warning(ME.message);
                    image = [];
                end
            end
        end
    
        function startIterations(app)
            % Change the text of the button to stop
            app.RunButton.Text = 'Stop';
            
            % Disable most of the user interface
            app.TargetDropDown.Enable = 'off';
            app.GuessDropDown.Enable = 'off';
            app.IncidentDropDown.Enable = 'off';
            app.IterationsSpinner.Enable = 'off';
            app.AdaptiveAdaptiveSpinner.Enable = 'off';
            app.GUIUpdateFreqSpinner.Enable = 'off';
            
            % Start the timer running
            start(app.iterTimer);
        end
    
        function stopIteations(app)
            
            % Stop the iterations
            stop(app.iterTimer);
            
            % Change the text of the button to stop
            app.RunButton.Text = 'Run';
            
            % Disable most of the user interface
            app.TargetDropDown.Enable = 'on';
            app.GuessDropDown.Enable = 'on';
            app.IncidentDropDown.Enable = 'on';
            app.IterationsSpinner.Enable = 'on';
            app.AdaptiveAdaptiveSpinner.Enable = 'on';
            app.GUIUpdateFreqSpinner.Enable = 'on';
            
            % Change the bRunning state
            app.bRunning = false;
        end
    
        function resetIterations(app)
            
            % Store the images for auto-update checking
            app.oldTarget = app.getSingleImage(app.TargetDropDown.Value);
            app.oldGuess = app.getSingleImage(app.GuessDropDown.Value);
            app.oldIncident = app.getSingleImage(app.IncidentDropDown.Value);
            
            app.result = app.getSingleImage(app.GuessDropDown.Value);
            app.nIteration = 0;
            app.Gauge.Value = app.nIteration;
            app.itermethod = [];
            
        end
    
        function WatchForImageChanges(app, timer, event)
            
            % Only run when the main timer is off
            if strcmpi(app.iterTimer, 'on')
                return;
            end
            
            % Get new images
            newTarget = app.getSingleImage(app.TargetDropDown.Value);
            newGuess = app.getSingleImage(app.GuessDropDown.Value);
            newIncident = app.getSingleImage(app.IncidentDropDown.Value);
            newImages = {newTarget, newGuess, newIncident};
            oldImages = {app.oldTarget, app.oldGuess, app.oldIncident};
            
            % Check if any of the images changed
            changed = false;
            for ii = 1:length(newImages)
                if any(size(newImages{ii}) ~= size(oldImages{ii})) || ...
                    any(newImages{ii}(:) ~= oldImages{ii}(:))
                    changed = true;
                    break;
                end
            end
            
            % If images changed, stop the 
            if changed
                app.resetIterations();
            end
            
        end
    
        function UpdateDisplayGui(app)
            
            if isempty(app.result)
                return;
            end
            
            name = app.NameEditField.Value;
            pattern = app.result;
            
            % Save the pattern in the base workspace
            if ~isempty(name)
                if ~isvarname(name)
                    warning('Name must be valid variable name');
                end
                
                assignin('base', name, pattern);
            end
            
            % Display image on the device
            slm = [];
            sname = app.DeviceDropDown.Value;
            if ~isempty(sname)
                if ~isvarname(sname)
                    warning('Invalid device name specified');
                end
                slm = evalin('base', sname);
                
                % Validate the handle (make sure its not deleted)
                if ~isvalid(slm)
                    warning('Invalid or deleted device');
                    slm = [];
                end
            end
            if app.UpdateDeviceCheckBox.Value && ~isempty(slm)
                slm.show(pattern./(2*pi));
            end
            
            % Generate display pattern
            if app.EnableDisplayCheckBox.Value
                
                % Generate the pattern to display
                dpattern = [];
                switch app.DisplayDropDown.Value
                    case 'phase'
                        dpattern = pattern;
                    case 'error'
                        cla(app.UIAxes, 'reset');
                        if ~isempty(app.itermethod)
                            app.itermethod.showFitness('axes', app.UIAxes);
                        end
                    case 'device'
                        if ~isempty(slm)
                            dpattern = slm.view(pattern./(2*pi));
                        else
                            warning('Device not setup');
                        end
                    case 'farfield'
                        dpattern = otslm.tools.visualise(pattern, 'method', 'fft', 'trim_padding', true, ...
                            'padding', ceil(size(pattern)/2), 'incident', ones(size(pattern)));
                        dpattern = abs(dpattern).^2;
                    otherwise
                        error('Invalid drop down option selected');
                end
                
                % Show the pattern in the window
                if ~isempty(dpattern)
                    imagesc(app.UIAxes, dpattern);
                    axis(app.UIAxes, 'image');
                    set(app.UIAxes, 'xtick', [], 'xticklabel', []);
                    set(app.UIAxes, 'ytick', [], 'yticklabel', []);
                end
                
                % Write pattern to workspace
                if ~isempty(app.DisplayVariableEditField.Value)
                    dname = app.DisplayVariableEditField.Value;
                    if ~isvarname(dname)
                        warning('Display variable must be valid variable name');
                    end
                    
                    assignin('base', dname, dpattern);
                end
            end
        end
    
        function iterate(app, timer, event)
            
            % Get properties from user interface
            adaptive = app.AdaptiveAdaptiveSpinner.Value;
            iterations = app.IterationsSpinner.Value;
            guiIterations = app.GUIUpdateFreqSpinner.Value;
            incident = app.getSingleImage(app.IncidentDropDown.Value);
            target = app.getSingleImage(app.TargetDropDown.Value);
            
            % Check we have a target
            if isempty(target)
                app.stopIteations();
                warning('Must supply target image');
                return;
            end
            
            % Handle default incident
            if isempty(incident)
                incident = ones(size(target));
            end
            
            if app.nIteration < iterations
            
                % Generate pattern
                newNiterations = min(app.nIteration + guiIterations, iterations);
                if isempty(app.itermethod)
                    app.itermethod = otslm.iter.GerchbergSaxton(target, 'guess', app.result, ...
                        'visdata', {'incident', incident}, 'adaptive', adaptive);
                end
                app.result = app.itermethod.run(newNiterations - app.nIteration, 'show_progress', false);
                app.nIteration = newNiterations;
            else
                app.stopIteations();
            end
            
            % Update the GUI, we are done
            app.Gauge.Value = app.nIteration;
            app.UpdateDisplayGui();
           
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Populate input array with variables
            varnames = evalin('base', 'who');
            app.TargetDropDown.Items = varnames;
            app.IncidentDropDown.Items = varnames;
            app.GuessDropDown.Items = varnames;
            app.TargetDropDown.Value = '';
            app.IncidentDropDown.Value = '';
            app.GuessDropDown.Value = '';
            
            % Populate the display device field
            devices = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'otslm.utils.Showable')
                    devices{end+1} = varnames{ii};
                end
            end
            app.DeviceDropDown.Items = devices;
            app.DeviceDropDown.ItemsData = devices;
            app.DeviceDropDown.Value = '';
            
            % Generate the initial pattern
            % Setup the private variables
            app.bRunning = false;
            app.nIteration = 0;
            
            % Setup a timer for running the method
            app.iterTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedSpacing', 'BusyMode', 'drop');
            app.iterTimer.TimerFcn = @app.iterate;
            
            % Setup a timer for running the method
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.WatchForImageChanges;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            if strcmpi(app.iterTimer.Running, 'on')
                app.stopIteations();
            else
                app.startIterations();
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            % Stop the updates so we can modify the parameters
            oldState = app.iterTimer.Running;
            stop(app.iterTimer);
            
            % Result the initial guess, iteration number and gauge
            app.resetIterations();
            
            % Turn the timer back on if required
            if strcmpi(oldState, 'on')
                start(app.iterTimer);
            end
            
        end

        % Close request function: GerchbergSaxtonUIFigure
        function GerchbergSaxtonUIFigureCloseRequest(app, event)
            stop(app.iterTimer);
            delete(app.iterTimer);
            delete(app)
        end

        % Value changed function: DeviceDropDown, DisplayDropDown, 
        % DisplayVariableEditField, EnableDisplayCheckBox, 
        % NameEditField, UpdateDeviceCheckBox
        function patternChanged(app, event)
            
            app.UpdateDisplayGui();
            
        end

        % Value changed function: IterationsSpinner
        function IterationsSpinnerValueChanged(app, event)
            app.Gauge.Limits(2) = app.IterationsSpinner.Value;
        end

        % Value changed function: AdaptiveAdaptiveSpinner, 
        % GuessDropDown, IncidentDropDown, TargetDropDown
        function patternInputChanged(app, event)
            
            app.resetIterations();
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GerchbergSaxtonUIFigure = uifigure;
            app.GerchbergSaxtonUIFigure.Name = 'Gerchberg-Saxton';
            app.GerchbergSaxtonUIFigure.CloseRequestFcn = createCallbackFcn(app, @GerchbergSaxtonUIFigureCloseRequest, true);
            app.Panel = uipanel(app.GerchbergSaxtonUIFigure);
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternChanged, true);
            app.GuessDropDownLabel = uilabel(app.Panel);
            app.GuessDropDownLabel.Text = 'Guess';
            app.GuessDropDown = uidropdown(app.Panel);
            app.GuessDropDown.Items = {};
            app.GuessDropDown.Editable = 'on';
            app.GuessDropDown.ValueChangedFcn = createCallbackFcn(app, @patternInputChanged, true);
            app.GuessDropDown.Value = {};
            app.IncidentDropDownLabel = uilabel(app.Panel);
            app.IncidentDropDownLabel.Text = 'Incident';
            app.IncidentDropDown = uidropdown(app.Panel);
            app.IncidentDropDown.Items = {};
            app.IncidentDropDown.Editable = 'on';
            app.IncidentDropDown.ValueChangedFcn = createCallbackFcn(app, @patternInputChanged, true);
            app.IncidentDropDown.Value = {};
            app.AdaptiveAdaptiveSpinnerLabel = uilabel(app.Panel);
            app.AdaptiveAdaptiveSpinnerLabel.Text = 'Adaptive-Adaptive';
            app.AdaptiveAdaptiveSpinner = uispinner(app.Panel);
            app.AdaptiveAdaptiveSpinner.Step = 0.1;
            app.AdaptiveAdaptiveSpinner.ValueChangedFcn = createCallbackFcn(app, @patternInputChanged, true);
            app.AdaptiveAdaptiveSpinner.Value = 1;
            app.TargetDropDownLabel = uilabel(app.Panel);
            app.TargetDropDownLabel.Text = 'Target';
            app.TargetDropDown = uidropdown(app.Panel);
            app.TargetDropDown.Items = {};
            app.TargetDropDown.Editable = 'on';
            app.TargetDropDown.ValueChangedFcn = createCallbackFcn(app, @patternInputChanged, true);
            app.TargetDropDown.Value = {};
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.IterationsSpinnerLabel = uilabel(app.Panel);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.Panel);
            app.IterationsSpinner.Limits = [1 Inf];
            app.IterationsSpinner.RoundFractionalValues = 'on';
            app.IterationsSpinner.ValueDisplayFormat = '%.0f';
            app.IterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @IterationsSpinnerValueChanged, true);
            app.IterationsSpinner.Value = 100;
            app.GUIUpdateFreqSpinnerLabel = uilabel(app.Panel);
            app.GUIUpdateFreqSpinnerLabel.Text = 'GUI Update Freq.';
            app.GUIUpdateFreqSpinner = uispinner(app.Panel);
            app.GUIUpdateFreqSpinner.Limits = [1 Inf];
            app.GUIUpdateFreqSpinner.RoundFractionalValues = 'on';
            app.GUIUpdateFreqSpinner.ValueDisplayFormat = '%.0f';
            app.GUIUpdateFreqSpinner.Value = 10;
            app.Gauge = uigauge(app.Panel, 'linear');
            app.UIAxes = uiaxes(app.GerchbergSaxtonUIFigure);
            app.UpdateDeviceCheckBox = uicheckbox(app.GerchbergSaxtonUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.GerchbergSaxtonUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.GerchbergSaxtonUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.GerchbergSaxtonUIFigure);
            app.DisplayDropDown.Items = {'Phase pattern', 'Device image', 'Simulated farfield', 'Fitness'};
            app.DisplayDropDown.ItemsData = {'phase', 'device', 'farfield', 'error'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternChanged, true);
            app.DisplayDropDown.Value = 'phase';
            app.DisplayVariableEditFieldLabel = uilabel(app.GerchbergSaxtonUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.GerchbergSaxtonUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternChanged, true);
            app.DeviceDropDownLabel = uilabel(app.GerchbergSaxtonUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.GerchbergSaxtonUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = GerchbergSaxton

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GerchbergSaxtonUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GerchbergSaxtonUIFigure)
        end
    end
end