classdef sinc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SincUIFigure                   matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        SizeEditFieldLabel             matlab.ui.control.Label
        SizeEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        ProfileDropDownLabel           matlab.ui.control.Label
        ProfileDropDown                matlab.ui.control.DropDown
        Radius1SpinnerLabel            matlab.ui.control.Label
        Radius1Spinner                 matlab.ui.control.Spinner
        Radius2SpinnerLabel            matlab.ui.control.Label
        Radius2Spinner                 matlab.ui.control.Spinner
        AngledegSpinnerLabel           matlab.ui.control.Label
        AngledegSpinner                matlab.ui.control.Spinner
        CentreEditFieldLabel           matlab.ui.control.Label
        CentreEditField                matlab.ui.control.EditField
        AspectSpinnerLabel             matlab.ui.control.Label
        AspectSpinner                  matlab.ui.control.Spinner
        OutputTypeDropDownLabel        matlab.ui.control.Label
        OutputTypeDropDown             matlab.ui.control.DropDown
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end


    methods (Access = public)
    
        function updateView(app)
            % Redraw the main display
            app.patternValueChanged([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Populate the display device field
            varnames = evalin('base', 'who');
            devices = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'otslm.utils.Showable')
                    devices{end+1} = varnames{ii};
                end
            end
            app.DeviceDropDown.Items = devices;
            app.DeviceDropDown.ItemsData = devices;
            app.DeviceDropDown.Value = '';
            
            % Generate the initial pattern
            app.patternValueChanged([]);
        end

        % Value changed function: AngledegSpinner, AspectSpinner, 
        % CentreEditField, DeviceDropDown, DisplayDropDown, 
        % DisplayVariableEditField, EnableDisplayCheckBox, 
        % NameEditField, OutputTypeDropDown, Radius1Spinner, 
        % Radius2Spinner, SizeEditField, UpdateDeviceCheckBox
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            sz = evalin('base', ['[', app.SizeEditField.Value, ']']);
            ptype = app.OutputTypeDropDown.Value;
            radius = app.Radius1Spinner.Value;
            radius2 = app.Radius2Spinner.Value;
            angle_deg = app.AngledegSpinner.Value;
            aspect = app.AspectSpinner.Value;
            type = app.ProfileDropDown.Value;
            centre = evalin('base', ['[', app.CentreEditField.Value, ']']);
            
            % Combine two spacing values
            if strcmpi(app.Radius2Spinner.Enable, 'on')
                radius = [radius, radius2];
            end
            
            % Generate the pattern
            pattern = otslm.simple.sinc(sz, radius, ...
                'centre', centre, 'angle_deg', angle_deg, ...
                'aspect', aspect, 'type', type);
            
            % Generate the desired output pattern type
            if strcmpi(ptype, 'phase')
                pattern = angle(pattern)/2/pi;
            elseif strcmpi(ptype, 'complex')
                pattern = complex(pattern);
            end
            
            % Save the pattern in the base workspace
            if ~isempty(name)
                if ~isvarname(name)
                    warning('Name must be valid variable name');
                end
                
                assignin('base', name, pattern);
            end
            
            % Display image on the device
            slm = [];
            sname = app.DeviceDropDown.Value;
            if ~isempty(sname)
                if ~isvarname(sname)
                    warning('Invalid device name specified');
                end
                slm = evalin('base', sname);
                
                % Validate the handle (make sure its not deleted)
                if ~isvalid(slm)
                    warning('Invalid or deleted device');
                    slm = [];
                end
            end
            if app.UpdateDeviceCheckBox.Value && ~isempty(slm)
                if strcmpi(ptype, 'complex')
                    slm.showComplex(pattern);
                else
                    slm.show(pattern);
                end
            end
            
            % Generate display pattern
            if app.EnableDisplayCheckBox.Value
                
                % Generate the pattern to display
                dpattern = [];
                switch app.DisplayDropDown.Value
                    case 'Phase mask'
                        switch ptype
                            case 'complex'
                                dpattern = angle(pattern);
                            case 'phase'
                                dpattern = otslm.tools.finalize(pattern, 'colormap', 'pmpi');
                            case 'amplitude'
                                dpattern = ones(size(pattern));
                            otherwise
                                error('Invalid drop down value');
                        end
                    case 'Raw pattern'
                        switch ptype
                            case 'complex'
                                dpattern = abs(pattern);
                            case 'phase'
                                dpattern = pattern;
                            case 'amplitude'
                                dpattern = pattern;
                            otherwise
                                error('Invalid drop down value');
                        end
                    case 'Device image'
                        if ~isempty(slm)
                            if strcmpi(ptype, 'complex')
                                dpattern = slm.viewComplex(pattern);
                            else
                                dpattern = slm.view(pattern);
                            end
                        else
                            warning('Device not setup');
                        end
                    case 'Simulated farfield'
                        if strcmpi(ptype, 'phase')
                            dpattern = otslm.tools.finalize(pattern, 'colormap', 'pmpi');
                        else
                            dpattern = pattern;
                        end
                        if strcmpi(ptype, 'amplitude')
                            dpattern = otslm.tools.visualise(angle(dpattern), ...
                                'amplitude', abs(dpattern), 'method', 'fft', 'trim_padding', true, ...
                                'padding', ceil(size(dpattern)/2), 'incident', ones(size(dpattern)));
                        else
                            dpattern = otslm.tools.visualise(dpattern, 'method', 'fft', 'trim_padding', true, ...
                                'padding', ceil(size(dpattern)/2), 'incident', ones(size(dpattern)));
                        end
                        dpattern = abs(dpattern).^2;
                    otherwise
                        error('Invalid drop down option selected');
                end
                
                % Show the pattern in the window
                if ~isempty(dpattern)
                    imagesc(app.UIAxes, dpattern);
                    axis(app.UIAxes, 'image');
                    set(app.UIAxes, 'xtick', [], 'xticklabel', []);
                    set(app.UIAxes, 'ytick', [], 'yticklabel', []);
                end
                
                % Write pattern to workspace
                if ~isempty(app.DisplayVariableEditField.Value)
                    dname = app.DisplayVariableEditField.Value;
                    if ~isvarname(dname)
                        warning('Display variable must be valid variable name');
                    end
                    
                    assignin('base', dname, dpattern);
                end
            end
            
        end

        % Value changed function: ProfileDropDown
        function ProfileDropDownValueChanged(app, event)
            
            % If 2-D cartesian, display additional spacing spinner
            if strcmpi(app.ProfileDropDown.Value, '2dcart')
                app.Radius2Spinner.Enable = true;
            else
                app.Radius2Spinner.Enable = false;
            end
            
            % Continue processing as usual
            app.patternValueChanged(event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SincUIFigure = uifigure;
            app.SincUIFigure.Name = 'Sinc';
            app.UIAxes = uiaxes(app.SincUIFigure);
            app.Panel = uipanel(app.SincUIFigure);
            app.SizeEditFieldLabel = uilabel(app.Panel);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.Panel, 'text');
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.SizeEditField.Value = '256,256';
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.ProfileDropDownLabel = uilabel(app.Panel);
            app.ProfileDropDownLabel.Text = 'Profile';
            app.ProfileDropDown = uidropdown(app.Panel);
            app.ProfileDropDown.Items = {'1-Dimensional', '2-Dimensional Polar', '2-Dimensional Cartesian'};
            app.ProfileDropDown.ItemsData = {'1d', '2d', '2dcart'};
            app.ProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfileDropDownValueChanged, true);
            app.ProfileDropDown.Value = '1d';
            app.Radius1SpinnerLabel = uilabel(app.Panel);
            app.Radius1SpinnerLabel.Text = 'Radius 1';
            app.Radius1Spinner = uispinner(app.Panel);
            app.Radius1Spinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.Radius1Spinner.Value = 10;
            app.Radius2SpinnerLabel = uilabel(app.Panel);
            app.Radius2SpinnerLabel.Enable = 'off';
            app.Radius2SpinnerLabel.Text = 'Radius 2';
            app.Radius2Spinner = uispinner(app.Panel);
            app.Radius2Spinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.Radius2Spinner.Enable = 'off';
            app.Radius2Spinner.Value = 10;
            app.AngledegSpinnerLabel = uilabel(app.Panel);
            app.AngledegSpinnerLabel.Text = 'Angle (deg)';
            app.AngledegSpinner = uispinner(app.Panel);
            app.AngledegSpinner.Step = 5;
            app.AngledegSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.CentreEditFieldLabel = uilabel(app.Panel);
            app.CentreEditFieldLabel.Text = 'Centre';
            app.CentreEditField = uieditfield(app.Panel, 'text');
            app.CentreEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.CentreEditField.Value = '128, 128';
            app.AspectSpinnerLabel = uilabel(app.Panel);
            app.AspectSpinnerLabel.Text = 'Aspect';
            app.AspectSpinner = uispinner(app.Panel);
            app.AspectSpinner.Step = 0.1;
            app.AspectSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.AspectSpinner.Value = 1;
            app.OutputTypeDropDownLabel = uilabel(app.Panel);
            app.OutputTypeDropDownLabel.Text = 'Output Type';
            app.OutputTypeDropDown = uidropdown(app.Panel);
            app.OutputTypeDropDown.Items = {'Phase', 'Amplitude', 'Complex'};
            app.OutputTypeDropDown.ItemsData = {'phase', 'amplitude', 'complex'};
            app.OutputTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.OutputTypeDropDown.Value = 'phase';
            app.UpdateDeviceCheckBox = uicheckbox(app.SincUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.SincUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.SincUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.SincUIFigure);
            app.DisplayDropDown.Items = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw pattern'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Phase mask';
            app.DisplayVariableEditFieldLabel = uilabel(app.SincUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.SincUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDownLabel = uilabel(app.SincUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.SincUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = sinc

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SincUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SincUIFigure)
        end
    end
end