classdef Combine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CombineUIFigure                matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        MethodDropDownLabel            matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        UITable                        matlab.ui.control.Table
        RemoveButton                   matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        ModuloInputsCheckBox           matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end


    properties (Access = private)
        oldImages;      % Old images from last watch
        watchTimer;     % Timer to watch if input images change
    end

    methods (Access = private)
        
        function image = getSingleImage(app, name)
            
            if isundefined(name) || isempty(name)
                image = [];
            else
                name = char(name);
                try
                    image = evalin('base', name);
                catch ME
                    warning(ME.message);
                    image = [];
                end
            end
        end
    
        function images = getImages(app)
            
            images = cell(1, size(app.UITable.Data, 1));
            for ii = 1:length(images)
                row = app.UITable.Data(ii, :);
                images{ii} = app.getSingleImage(row.names);
            end
            
        end
    
        function checkImagesChanged(app, timer, event)
            
            % Get latest images
            newImages = app.getImages();
            
            % Check the number of images is equal
            changed = length(newImages) ~= length(app.oldImages);
            
            % Check contents of images are equal
            if ~changed
                for ii = 1:length(newImages)
                    if any(size(newImages{ii}) ~= size(app.oldImages{ii})) || ...
                        any(newImages{ii}(:) ~= app.oldImages{ii}(:))
                        changed = true;
                        break;
                    end
                end
            end
            
            % If we changed, update the display
            if changed
                app.patternValueChanged([]);
            end
        end
        
    end

    methods (Access = public)
    
        function updateView(app)
            % Redraw the main display
            app.patternValueChanged([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Populate input array with variables
            varnames = evalin('base', 'who');
            
            % Setup the table
            selected = [false; false];
            names = categorical({''; ''}, varnames, 'Protected', false);
            weights = [1; 1];
            initialtable = table(selected, names, weights, 'VariableNames', {'selected', 'names', 'weights'});
            app.UITable.Data = initialtable;
            
            % Populate the display device field
            devices = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'otslm.utils.Showable')
                    devices{end+1} = varnames{ii};
                end
            end
            app.DeviceDropDown.Items = devices;
            app.DeviceDropDown.ItemsData = devices;
            app.DeviceDropDown.Value = '';
            
            % Generate the initial pattern
            app.patternValueChanged([]);
            
            % Setup a timer to watch the images
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.checkImagesChanged;
            start(app.watchTimer);
        end

        % Callback function: DeviceDropDown, DisplayDropDown, 
        % DisplayVariableEditField, EnableDisplayCheckBox, 
        % MethodDropDown, ModuloInputsCheckBox, NameEditField, UITable, 
        % UpdateDeviceCheckBox
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            method = app.MethodDropDown.Value;
            
            % TODO: Add listeners for row.names content changes
            
            % Get images in a safe way
            app.oldImages = app.getImages();
            
            % Create lists of non-empty images and values
            inputImages = {};
            inputValues = [];
            for ii = 1:size(app.UITable.Data, 1)
                row = app.UITable.Data(ii, :);
                if ~isempty(app.oldImages{ii})
                    inputImages{end+1} = app.oldImages{ii};
                    inputValues(end+1) = row.weights;
                end
            end
            
            if app.ModuloInputsCheckBox.Value
                for ii = 1:length(inputImages)
                    inputImages{ii} = mod(inputImages{ii}, 1);
                end
            end
            
            % Check that we have work to do
            if isempty(inputImages)
                return;
            end
            
            % Generate the pattern
            pattern = otslm.tools.combine(inputImages, 'method', method, 'weights', inputValues);
            
            % Save the pattern in the base workspace
            if ~isempty(name)
                if ~isvarname(name)
                    warning('Name must be valid variable name');
                end
                
                assignin('base', name, pattern);
            end
            
            % Display image on the device
            slm = [];
            sname = app.DeviceDropDown.Value;
            if ~isempty(sname)
                if ~isvarname(sname)
                    warning('Invalid device name specified');
                end
                slm = evalin('base', sname);
                
                % Validate the handle (make sure its not deleted)
                if ~isvalid(slm)
                    warning('Invalid or deleted device');
                    slm = [];
                end
            end
            if app.UpdateDeviceCheckBox.Value && ~isempty(slm)
                slm.show(pattern);
            end
            
            % Generate display pattern
            if app.EnableDisplayCheckBox.Value
                
                % Generate the pattern to display
                dpattern = [];
                switch app.DisplayDropDown.Value
                    case 'Phase mask'
                        dpattern = otslm.tools.finalize(pattern, 'colormap', 'pmpi');
                    case 'Raw phase mask'
                        dpattern = pattern;
                    case 'Device image'
                        if ~isempty(slm)
                            dpattern = slm.view(pattern);
                        else
                            warning('Device not setup');
                        end
                    case 'Simulated farfield'
                        dpattern = otslm.tools.finalize(pattern, 'colormap', 'pmpi');
                        dpattern = otslm.tools.visualise(dpattern, 'method', 'fft', 'trim_padding', true, ...
                            'padding', ceil(size(dpattern)/2), 'incident', ones(size(dpattern)));
                        dpattern = abs(dpattern).^2;
                    otherwise
                        error('Invalid drop down option selected');
                end
                
                % Show the pattern in the window
                if ~isempty(dpattern)
                    imagesc(app.UIAxes, dpattern);
                    axis(app.UIAxes, 'image');
                    set(app.UIAxes, 'xtick', [], 'xticklabel', []);
                    set(app.UIAxes, 'ytick', [], 'yticklabel', []);
                end
                
                % Write pattern to workspace
                if ~isempty(app.DisplayVariableEditField.Value)
                    dname = app.DisplayVariableEditField.Value;
                    if ~isvarname(dname)
                        warning('Display variable must be valid variable name');
                    end
                    
                    assignin('base', dname, dpattern);
                end
            end
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            
            % Add a row to the table
            newrow = { false, '', 1 };
            app.UITable.Data = [app.UITable.Data; newrow];
            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            
            % Delete selected rows from table
            app.UITable.Data(logical(app.UITable.Data.selected), :) = [];
            
        end

        % Close request function: CombineUIFigure
        function CombineUIFigureCloseRequest(app, event)
            try
                stop(app.watchTimer);
                delete(app.watchTimer);
            catch
                warning('Timer might not have stopped');
            end
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CombineUIFigure = uifigure;
            app.CombineUIFigure.Name = 'Combine';
            app.CombineUIFigure.CloseRequestFcn = createCallbackFcn(app, @CombineUIFigureCloseRequest, true);
            app.Panel = uipanel(app.CombineUIFigure);
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.MethodDropDownLabel = uilabel(app.Panel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Panel);
            app.MethodDropDown.Items = {'Dither', 'Superposition', 'Random Superposition', 'Gerchberg-Saxton', 'Add', 'Multiply', 'Add Angle', 'Average'};
            app.MethodDropDown.ItemsData = {'dither', 'super', 'rsuper', 'gs', 'add', 'multiply', 'addangle', 'average'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.MethodDropDown.Value = 'super';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {''; 'Names'; 'Weights'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @patternValueChanged, true);
            app.RemoveButton = uibutton(app.Panel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.AddButton = uibutton(app.Panel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ModuloInputsCheckBox = uicheckbox(app.Panel);
            app.ModuloInputsCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.ModuloInputsCheckBox.Text = 'Modulo Inputs';
            app.ModuloInputsCheckBox.Value = true;
            app.UIAxes = uiaxes(app.CombineUIFigure);
            app.UpdateDeviceCheckBox = uicheckbox(app.CombineUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.CombineUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.CombineUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.CombineUIFigure);
            app.DisplayDropDown.Items = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Phase mask';
            app.DisplayVariableEditFieldLabel = uilabel(app.CombineUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.CombineUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDownLabel = uilabel(app.CombineUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.CombineUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Combine

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CombineUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CombineUIFigure)
        end
    end
end