classdef Encode1d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Encode1DUIFigure               matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        InputDropDownLabel             matlab.ui.control.Label
        InputDropDown                  matlab.ui.control.DropDown
        ScaleSpinnerLabel              matlab.ui.control.Label
        ScaleSpinner                   matlab.ui.control.Spinner
        AngledegSpinnerLabel           matlab.ui.control.Label
        AngledegSpinner                matlab.ui.control.Spinner
        UIAxes                         matlab.ui.control.UIAxes
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        oldImage;       % Old images from last watch
        watchTimer;     % Timer to watch if input images change
    end

    methods (Access = private)
    
        function image = getSingleImage(app, name)
            
            if isempty(name)
                image = [];
            else
                try
                    image = evalin('base', name);
                catch ME
                    warning(ME.message);
                    image = [];
                end
            end
        end
        
        function checkImagesChanged(app, timer, event)
            
            % Check if images have changed, if so, update display
            newImage = app.getSingleImage(app.InputDropDown.Value);
            if any(size(newImage) ~= size(app.oldImage)) || ...
                    any(newImage(:) ~= app.oldImage(:))
                app.patternValueChanged([]);
            end
        end
        
    end
    
    methods (Access = public)
        
        function updateView(app)
            % Redraw the main display
            app.calculateData([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            varnames = evalin('base', 'who');
            
            % Populate input array with variables
            app.InputDropDown.Items = varnames;
            app.InputDropDown.Value = '';
            
            % Populate the display device field
            devices = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'otslm.utils.Showable')
                    devices{end+1} = varnames{ii};
                end
            end
            app.DeviceDropDown.Items = devices;
            app.DeviceDropDown.ItemsData = devices;
            app.DeviceDropDown.Value = '';
            
            % Generate the initial pattern
            app.patternValueChanged([]);
            
            % Setup a timer to watch the images
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.checkImagesChanged;
            start(app.watchTimer);
        end

        % Value changed function: AngledegSpinner, DeviceDropDown, 
        % DisplayDropDown, DisplayVariableEditField, 
        % EnableDisplayCheckBox, InputDropDown, NameEditField, 
        % ScaleSpinner, UpdateDeviceCheckBox
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            inputname = app.InputDropDown.Value;
            angle_deg = app.AngledegSpinner.Value;
            scale = app.ScaleSpinner.Value;
            
            % Get the input image
            
            app.oldImage = app.getSingleImage(inputname);
            input = app.oldImage;
            
            % Generate the pattern
            pattern = otslm.tools.encode1d(input, 'scale', scale, 'angle_deg', angle_deg);
            
            % Save the pattern in the base workspace
            if ~isempty(name)
                if ~isvarname(name)
                    warning('Name must be valid variable name');
                end
                
                assignin('base', name, pattern);
            end
            
            % Display image on the device
            slm = [];
            sname = app.DeviceDropDown.Value;
            if ~isempty(sname)
                if ~isvarname(sname)
                    warning('Invalid device name specified');
                end
                slm = evalin('base', sname);
                
                % Validate the handle (make sure its not deleted)
                if ~isvalid(slm)
                    warning('Invalid or deleted device');
                    slm = [];
                end
            end
            if app.UpdateDeviceCheckBox.Value && ~isempty(slm)
                slm.show(pattern);
            end
            
            % Generate display pattern
            if app.EnableDisplayCheckBox.Value
                
                % Generate the pattern to display
                dpattern = [];
                switch app.DisplayDropDown.Value
                    case 'Phase mask'
                        dpattern = otslm.tools.finalize(pattern, 'colormap', 'pmpi');
                    case 'Raw phase mask'
                        dpattern = pattern;
                    case 'Device image'
                        if ~isempty(slm)
                            dpattern = slm.view(pattern);
                        else
                            warning('Device not setup');
                        end
                    case 'Simulated farfield'
                        dpattern = otslm.tools.finalize(pattern, 'colormap', 'pmpi');
                        dpattern = otslm.tools.visualise(dpattern, 'method', 'fft', 'trim_padding', true, ...
                            'padding', ceil(size(dpattern)/2), 'incident', ones(size(dpattern)));
                        dpattern = abs(dpattern).^2;
                    otherwise
                        error('Invalid drop down option selected');
                end
                
                % Show the pattern in the window
                if ~isempty(dpattern)
                    imagesc(app.UIAxes, dpattern);
                    axis(app.UIAxes, 'image');
                    set(app.UIAxes, 'xtick', [], 'xticklabel', []);
                    set(app.UIAxes, 'ytick', [], 'yticklabel', []);
                end
                
                % Write pattern to workspace
                if ~isempty(app.DisplayVariableEditField.Value)
                    dname = app.DisplayVariableEditField.Value;
                    if ~isvarname(dname)
                        warning('Display variable must be valid variable name');
                    end
                    
                    assignin('base', dname, dpattern);
                end
            end
            
        end

        % Close request function: Encode1DUIFigure
        function Encode1DUIFigureCloseRequest(app, event)
            stop(app.watchTimer);
            delete(app.watchTimer);
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Encode1DUIFigure = uifigure;
            app.Encode1DUIFigure.Name = 'Encode 1D';
            app.Encode1DUIFigure.CloseRequestFcn = createCallbackFcn(app, @Encode1DUIFigureCloseRequest, true);
            app.Panel = uipanel(app.Encode1DUIFigure);
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.InputDropDownLabel = uilabel(app.Panel);
            app.InputDropDownLabel.Text = 'Input';
            app.InputDropDown = uidropdown(app.Panel);
            app.InputDropDown.Items = {};
            app.InputDropDown.Editable = 'on';
            app.InputDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.InputDropDown.Value = {};
            app.ScaleSpinnerLabel = uilabel(app.Panel);
            app.ScaleSpinnerLabel.Text = 'Scale';
            app.ScaleSpinner = uispinner(app.Panel);
            app.ScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.ScaleSpinner.Value = 1;
            app.AngledegSpinnerLabel = uilabel(app.Panel);
            app.AngledegSpinnerLabel.Text = 'Angle (deg)';
            app.AngledegSpinner = uispinner(app.Panel);
            app.AngledegSpinner.Step = 5;
            app.AngledegSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UIAxes = uiaxes(app.Encode1DUIFigure);
            app.UpdateDeviceCheckBox = uicheckbox(app.Encode1DUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.Encode1DUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.Encode1DUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.Encode1DUIFigure);
            app.DisplayDropDown.Items = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Phase mask';
            app.DisplayVariableEditFieldLabel = uilabel(app.Encode1DUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.Encode1DUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDownLabel = uilabel(app.Encode1DUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.Encode1DUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Encode1d

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Encode1DUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Encode1DUIFigure)
        end
    end
end