classdef Finalize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FinalizeUIFigure               matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        Panel                          matlab.ui.container.Panel
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        PhaseDropDownLabel             matlab.ui.control.Label
        PhaseDropDown                  matlab.ui.control.DropDown
        RotationPackDropDownLabel      matlab.ui.control.Label
        RotationPackDropDown           matlab.ui.control.DropDown
        AmplitudeDropDownLabel         matlab.ui.control.Label
        AmplitudeDropDown              matlab.ui.control.DropDown
        ModuloDropDownLabel            matlab.ui.control.Label
        ModuloDropDown                 matlab.ui.control.DropDown
        ColormapDropDownLabel          matlab.ui.control.Label
        ColormapDropDown               matlab.ui.control.DropDown
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
        DeviceDropDown_2Label          matlab.ui.control.Label
        DeviceDropDown_2               matlab.ui.control.DropDown
    end


    properties (Access = private)
        oldImages;      % Old images from last watch
        watchTimer;     % Timer to watch if input images change
    end

    methods (Access = private)
    
        function checkImagesChanged(app, timer, event)
            
            % Get latest images
            phase = app.PhaseDropDown.Value;
            amplitude = app.AmplitudeDropDown.Value;
            if ~isempty(phase)
                phase = evalin('base', phase);
            end
            if ~isempty(amplitude)
                amplitude = evalin('base', amplitude);
            end
            inputImages = {phase, amplitude};
            
            % If images have changed, update the display
            if length(inputImages) == length(app.oldImages)
                allequal = true;
                for ii = 1:length(inputImages)
                    allequal = allequal & all(inputImages{ii} == app.oldImages{ii});
                end
            end
            if ~allequal
                app.patternValueChanged([]);
            end
        end
        
    end
    
    methods (Access = public)
        
        function updateView(app)
            % Redraw the main display
            app.patternValueChanged([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Populate input array with variables
            varnames = evalin('base', 'who');
            app.PhaseDropDown.Items = varnames;
            app.PhaseDropDown.Value = '';
            app.AmplitudeDropDown.Items = varnames;
            app.AmplitudeDropDown.Value = '';
            
            app.ModuloDropDown.Value = '1';
            
            % Populate the display device field
            devices = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'otslm.utils.Showable')
                    devices{end+1} = varnames{ii};
                end
            end
            app.DeviceDropDown.Items = devices;
            app.DeviceDropDown.ItemsData = devices;
            app.DeviceDropDown.Value = '';
            
            % Generate the initial pattern
            app.patternValueChanged([]);
            
            % Setup a timer to watch the images
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.checkImagesChanged;
            start(app.watchTimer);
        end

        % Value changed function: AmplitudeDropDown, 
        % ColormapDropDown, DeviceDropDown, DeviceDropDown_2, 
        % DisplayDropDown, DisplayVariableEditField, 
        % EnableDisplayCheckBox, ModuloDropDown, NameEditField, 
        % PhaseDropDown, RotationPackDropDown, UpdateDeviceCheckBox
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            phase = app.PhaseDropDown.Value;
            amplitude = app.AmplitudeDropDown.Value;
            
            device = app.DeviceDropDown.Value;
            modulo = app.ModuloDropDown.Value;
            colormap = app.ColormapDropDown.Value;
            rpack = app.RotationPackDropDown.Value;
            
            if ~isempty(phase)
                phase = evalin('base', phase);
            end
            if ~isempty(amplitude)
                amplitude = evalin('base', amplitude);
            end
            
            % Store last input images (for auto-update)
            app.oldImages = {phase, amplitude};
            
            % Check that we have work to do
            if isempty(phase) && isempty(amplitude)
                return;
            end
            
            % Convert modulo to actual value
            if ~any(strcmpi(modulo, app.ModuloDropDown.ItemsData))
                modulo = evalin('base', modulo);
            end
            
            % Convert colormap to actual value
            if ~any(strcmpi(colormap, app.ColormapDropDown.ItemsData))
                colormap = evalin('base', colormap);
            end
            
            % Generate the pattern
            pattern = otslm.tools.finalize(phase, 'amplitude', amplitude, ...
                'rpack', rpack, 'colormap', colormap, 'modulo', modulo, 'device', device);
            
            % Save the pattern in the base workspace
            if ~isempty(name)
                if ~isvarname(name)
                    warning('Name must be valid variable name');
                end
                
                assignin('base', name, pattern);
            end
            
            % Display image on the device
            slm = [];
            sname = app.DeviceDropDown.Value;
            if ~isempty(sname)
                if ~isvarname(sname)
                    warning('Invalid device name specified');
                end
                slm = evalin('base', sname);
                
                % Validate the handle (make sure its not deleted)
                if ~isvalid(slm)
                    warning('Invalid or deleted device');
                    slm = [];
                end
            end
            if app.UpdateDeviceCheckBox.Value && ~isempty(slm)
                slm.show(pattern);
            end
            
            % Generate display pattern
            if app.EnableDisplayCheckBox.Value
                
                % Generate the pattern to display
                dpattern = [];
                switch app.DisplayDropDown.Value
                    case 'Raw pattern'
                        dpattern = pattern;
                    case 'Device image'
                        if ~isempty(slm)
                            dpattern = slm.view(pattern);
                        else
                            warning('Device not setup');
                        end
                    case 'Simulated farfield'
                        if strcmpi(device, 'dmd')
                            dpattern = complex(pattern);   % Amplitude only
                        else
                            dpattern = pattern; % Phase only
                        end
                        dpattern = otslm.tools.visualise(dpattern, 'method', 'fft', 'trim_padding', true, ...
                            'padding', ceil(size(dpattern)/2), 'incident', ones(size(dpattern)));
                        dpattern = abs(dpattern).^2;
                    otherwise
                        error('Invalid drop down option selected');
                end
                
                % Show the pattern in the window
                if ~isempty(dpattern)
                    imagesc(app.UIAxes, dpattern);
                    axis(app.UIAxes, 'image');
                    set(app.UIAxes, 'xtick', [], 'xticklabel', []);
                    set(app.UIAxes, 'ytick', [], 'yticklabel', []);
                end
                
                % Write pattern to workspace
                if ~isempty(app.DisplayVariableEditField.Value)
                    dname = app.DisplayVariableEditField.Value;
                    if ~isvarname(dname)
                        warning('Display variable must be valid variable name');
                    end
                    
                    assignin('base', dname, dpattern);
                end
            end
            
        end

        % Close request function: FinalizeUIFigure
        function FinalizeUIFigureCloseRequest(app, event)
            stop(app.watchTimer);
            delete(app.watchTimer);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FinalizeUIFigure = uifigure;
            app.FinalizeUIFigure.Name = 'Finalize';
            app.FinalizeUIFigure.CloseRequestFcn = createCallbackFcn(app, @FinalizeUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.FinalizeUIFigure);
            app.UpdateDeviceCheckBox = uicheckbox(app.FinalizeUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.FinalizeUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.FinalizeUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.FinalizeUIFigure);
            app.DisplayDropDown.Items = {'Device image', 'Simulated farfield', 'Raw pattern'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Raw pattern';
            app.DisplayVariableEditFieldLabel = uilabel(app.FinalizeUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.FinalizeUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.Panel = uipanel(app.FinalizeUIFigure);
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.PhaseDropDownLabel = uilabel(app.Panel);
            app.PhaseDropDownLabel.Text = 'Phase';
            app.PhaseDropDown = uidropdown(app.Panel);
            app.PhaseDropDown.Items = {};
            app.PhaseDropDown.Editable = 'on';
            app.PhaseDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.PhaseDropDown.Value = {};
            app.RotationPackDropDownLabel = uilabel(app.Panel);
            app.RotationPackDropDownLabel.Text = 'Rotation Pack';
            app.RotationPackDropDown = uidropdown(app.Panel);
            app.RotationPackDropDown.Items = {'None', '45 Degree'};
            app.RotationPackDropDown.ItemsData = {'none', '45deg'};
            app.RotationPackDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.RotationPackDropDown.Value = 'none';
            app.AmplitudeDropDownLabel = uilabel(app.Panel);
            app.AmplitudeDropDownLabel.Text = 'Amplitude';
            app.AmplitudeDropDown = uidropdown(app.Panel);
            app.AmplitudeDropDown.Items = {};
            app.AmplitudeDropDown.Editable = 'on';
            app.AmplitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.AmplitudeDropDown.Value = {};
            app.ModuloDropDownLabel = uilabel(app.Panel);
            app.ModuloDropDownLabel.Text = 'Modulo';
            app.ModuloDropDown = uidropdown(app.Panel);
            app.ModuloDropDown.Items = {'None'};
            app.ModuloDropDown.ItemsData = {'none'};
            app.ModuloDropDown.Editable = 'on';
            app.ModuloDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.ModuloDropDown.Value = 'none';
            app.ColormapDropDownLabel = uilabel(app.Panel);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.Panel);
            app.ColormapDropDown.Items = {'+/- pi', '0 to 2pi', 'Binary', 'Gray'};
            app.ColormapDropDown.ItemsData = {'pmpi', '2pi', 'bin', 'gray'};
            app.ColormapDropDown.Editable = 'on';
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.ColormapDropDown.Value = 'pmpi';
            app.DeviceDropDownLabel = uilabel(app.Panel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.Panel);
            app.DeviceDropDown.Items = {'Amplitude Only', 'Phase Only'};
            app.DeviceDropDown.ItemsData = {'dmd', 'slm'};
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = 'dmd';
            app.DeviceDropDown_2Label = uilabel(app.FinalizeUIFigure);
            app.DeviceDropDown_2Label.Text = 'Device';
            app.DeviceDropDown_2 = uidropdown(app.FinalizeUIFigure);
            app.DeviceDropDown_2.Items = {};
            app.DeviceDropDown_2.Editable = 'on';
            app.DeviceDropDown_2.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown_2.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Finalize

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FinalizeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FinalizeUIFigure)
        end
    end
end