classdef MaskRegion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaskRegionsUIFigure            matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        RegionTab                      matlab.ui.container.Tab
        MaskDropDownLabel              matlab.ui.control.Label
        MaskDropDown                   matlab.ui.control.DropDown
        PatternDropDownLabel           matlab.ui.control.Label
        PatternDropDown                matlab.ui.control.DropDown
        Size1SpinnerLabel              matlab.ui.control.Label
        Size1Spinner                   matlab.ui.control.Spinner
        Size2SpinnerLabel              matlab.ui.control.Label
        Size2Spinner                   matlab.ui.control.Spinner
        ShapeDropDownLabel             matlab.ui.control.Label
        ShapeDropDown                  matlab.ui.control.DropDown
        PositionEditFieldLabel         matlab.ui.control.Label
        PositionEditField              matlab.ui.control.EditField
        AddRegionButton                matlab.ui.control.Button
        RemoveRegionButton             matlab.ui.control.Button
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        BackgroundDropDownLabel        matlab.ui.control.Label
        BackgroundDropDown             matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end


    properties (Access = private)
        oldImages;          % Old background image data
        watchTimer;     % Timer to watch if input images change
    end

    methods (Access = private)
    
        function checkImagesChanged(app, timer, event)
            
            background = evalin('base', app.BackgroundDropDown.Value);
            
            % Get latest images
            if ~all(background == app.oldImages)
                app.patternValueChanged([]);
            end
        end
        
    end
    
    methods (Access = public)
        
        function updateView(app)
            % Redraw the main display
            app.calculateData([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Hide the template tab
            app.RegionTab.Parent = [];
            app.MaskDropDown.UserData = 'mask';
            app.PatternDropDown.UserData = 'pattern';
            app.Size1Spinner.UserData = 'size1';
            app.Size2Spinner.UserData = 'size2';
            app.ShapeDropDown.UserData = 'shape';
            app.PositionEditField.UserData = 'position';
            app.RemoveRegionButton.Enable = 'off';
            
            % Populate input array with variables
            varnames = evalin('base', 'who');
            app.MaskDropDown.Items = varnames;
            app.PatternDropDown.Items = varnames;
            app.BackgroundDropDown.Items = varnames;
            app.MaskDropDown.Value = '';
            app.PatternDropDown.Value = '';
            app.BackgroundDropDown.Value = '';
            
            % Populate the display device field
            devices = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'otslm.utils.Showable')
                    devices{end+1} = varnames{ii};
                end
            end
            app.DeviceDropDown.Items = devices;
            app.DeviceDropDown.ItemsData = devices;
            app.DeviceDropDown.Value = '';
            
            % Generate the initial pattern
            app.patternValueChanged([]);
            
            % Setup a timer to watch the images
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.checkImagesChanged;
            start(app.watchTimer);
        end

        % Button pushed function: RemoveRegionButton
        function RemoveRegionButtonPushed(app, event)
            % Remove the current region
            selectedTab = app.TabGroup.SelectedTab;
            if ~isempty(selectedTab)
                selectedTab.Parent = [];
                if isempty(app.TabGroup.Children)
                    app.RemoveRegionButton.Enable = 'off';
                end
            end
            app.patternValueChanged(event);
        end

        % Button pushed function: AddRegionButton
        function AddRegionButtonPushed(app, event)
            tab = copyobj(app.RegionTab, app.TabGroup);
            
            % Add callbacks to new object
            maskEntry = findall(tab, 'UserData', 'mask');
            patternEntry = findall(tab, 'UserData', 'pattern');
            size1Entry = findall(tab, 'UserData', 'size1');
            size2Entry = findall(tab, 'UserData', 'size2');
            shapeEntry = findall(tab, 'UserData', 'shape');
            positionEntry = findall(tab, 'UserData', 'position');
            
            maskEntry.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            patternEntry.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            size1Entry.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            size2Entry.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            shapeEntry.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            positionEntry.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            
            app.RemoveRegionButton.Enable = 'on';
            app.patternValueChanged(event);
        end

        % Value changed function: BackgroundDropDown, DeviceDropDown, 
        % DisplayDropDown, DisplayVariableEditField, 
        % EnableDisplayCheckBox, NameEditField, PositionEditField, 
        % UpdateDeviceCheckBox
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            
            try
                background = evalin('base', app.BackgroundDropDown.Value);
            catch
                warning('Unable to evaluate background');
                return;
            end

            % Store last input images (for auto-update)
            app.oldImages = background;
            
            % Get parameters from user interface
            appature_patterns = {};
            mask_patterns = {};
            locations = {};
            sizes = {};
            shapes = {};
            masks = {};
            for ii = 1:length(app.TabGroup.Children)
                tab = app.TabGroup.Children(ii);
                
                maskEntry = findall(tab, 'UserData', 'mask');
                patternEntry = findall(tab, 'UserData', 'pattern');
                size1Entry = findall(tab, 'UserData', 'size1');
                size2Entry = findall(tab, 'UserData', 'size2');
                shapeEntry = findall(tab, 'UserData', 'shape');
                positionEntry = findall(tab, 'UserData', 'position');
                
                if strcmpi(shapeEntry.Value, 'custom')
                    try
                        cpattern = evalin('base', patternEntry.Value);
                    catch
                        warning('Unable to evaluate pattern');
                        continue;
                    end
                    try
                        cmask = evalin('base', maskEntry.Value);
                    catch
                        warning('Unable to evaluate mask');
                        continue;
                    end
                    
                    mask_patterns{end+1} = cpattern;
                    masks{end+1} = cmask;
                else
                    try
                        appature_patterns{end+1} = evalin('base', patternEntry.Value);
                    catch
                        warning('Unable to evaluate pattern');
                        continue;
                    end
                    
                    locations{end+1} = evalin('base', ['[', positionEntry.Value, ']']);
                    if strcmpi(size2Entry.Enable, 'on')
                        sizes{end+1} = [size1Entry.Value, size2Entry.Value];
                    else
                        sizes{end+1} = size1Entry.Value;
                    end
                    shapes{end+1} = shapeEntry.Value;
                        
                end
            end
            
            % Generate the pattern
            pattern = otslm.tools.mask_regions(background, appature_patterns, ...
                locations, sizes, 'shape', shapes);
            
            % Add additional masked regions
            for ii = 1:length(masks)
                pattern(masks{ii}) = mask_patterns{ii}(masks{ii});
            end
            
            % Save the pattern in the base workspace
            if ~isempty(name)
                if ~isvarname(name)
                    warning('Name must be valid variable name');
                end
                
                assignin('base', name, pattern);
            end
            
            % Display image on the device
            slm = [];
            sname = app.DeviceDropDown.Value;
            if ~isempty(sname)
                if ~isvarname(sname)
                    warning('Invalid device name specified');
                end
                slm = evalin('base', sname);
                
                % Validate the handle (make sure its not deleted)
                if ~isvalid(slm)
                    warning('Invalid or deleted device');
                    slm = [];
                end
            end
            if app.UpdateDeviceCheckBox.Value && ~isempty(slm)
                slm.show(pattern);
            end
            
            % Generate display pattern
            if app.EnableDisplayCheckBox.Value
                
                % Generate the pattern to display
                dpattern = [];
                switch app.DisplayDropDown.Value
                    case 'Phase mask'
                        dpattern = otslm.tools.finalize(pattern, 'colormap', 'pmpi');
                    case 'Raw phase mask'
                        dpattern = pattern;
                    case 'Device image'
                        if ~isempty(slm)
                            dpattern = slm.view(pattern);
                        else
                            warning('Device not setup');
                        end
                    case 'Simulated farfield'
                        dpattern = otslm.tools.finalize(pattern, 'colormap', 'pmpi');
                        dpattern = otslm.tools.visualise(dpattern, 'method', 'fft', 'trim_padding', true, ...
                            'padding', ceil(size(dpattern)/2), 'incident', ones(size(dpattern)));
                        dpattern = abs(dpattern).^2;
                    otherwise
                        error('Invalid drop down option selected');
                end
                
                % Show the pattern in the window
                if ~isempty(dpattern)
                    imagesc(app.UIAxes, dpattern);
                    axis(app.UIAxes, 'image');
                    set(app.UIAxes, 'xtick', [], 'xticklabel', []);
                    set(app.UIAxes, 'ytick', [], 'yticklabel', []);
                end
                
                % Write pattern to workspace
                if ~isempty(app.DisplayVariableEditField.Value)
                    dname = app.DisplayVariableEditField.Value;
                    if ~isvarname(dname)
                        warning('Display variable must be valid variable name');
                    end
                    
                    assignin('base', dname, dpattern);
                end
            end
            
        end

        % Close request function: MaskRegionsUIFigure
        function MaskRegionsUIFigureCloseRequest(app, event)
            stop(app.watchTimer);
            delete(app.watchTimer);
            delete(app);
        end

        % Value changed function: ShapeDropDown
        function ShapeDropDownValueChanged(app, event)

            % Get the tab that invoked the event
            tab = event.Source.Parent;
            
            % Get the entries on this tab
            maskEntry = findall(tab, 'UserData', 'mask');
            patternEntry = findall(tab, 'UserData', 'pattern');
            size1Entry = findall(tab, 'UserData', 'size1');
            size2Entry = findall(tab, 'UserData', 'size2');
            shapeEntry = findall(tab, 'UserData', 'shape');
            positionEntry = findall(tab, 'UserData', 'position');
            
            switch shapeEntry.Value
                case 'circle'
                    size1Entry.Enable = 'on';
                    size2Entry.Enable = 'off';
                    positionEntry.Enable = 'on';
                    maskEntry.Enable = 'off';
                case 'square'
                    size1Entry.Enable = 'on';
                    size2Entry.Enable = 'off';
                    positionEntry.Enable = 'on';
                    maskEntry.Enable = 'off';
                case 'rect'
                    size1Entry.Enable = 'on';
                    size2Entry.Enable = 'on';
                    positionEntry.Enable = 'on';
                    maskEntry.Enable = 'off';
                case 'ring'
                    size1Entry.Enable = 'on';
                    size2Entry.Enable = 'on';
                    positionEntry.Enable = 'on';
                    maskEntry.Enable = 'off';
                case 'custom'
                    size1Entry.Enable = 'off';
                    size2Entry.Enable = 'off';
                    positionEntry.Enable = 'off';
                    maskEntry.Enable = 'on';
                otherwise
                    error('Invalid drop down value');
            end
            
            % Continue processing as usual
            app.patternValueChanged(event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaskRegionsUIFigure = uifigure;
            app.MaskRegionsUIFigure.Name = 'Mask Regions';
            app.MaskRegionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @MaskRegionsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.MaskRegionsUIFigure);
            app.TabGroup = uitabgroup(app.Panel);
            app.RegionTab = uitab(app.TabGroup);
            app.RegionTab.Title = 'Region';
            app.MaskDropDownLabel = uilabel(app.RegionTab);
            app.MaskDropDownLabel.Enable = 'off';
            app.MaskDropDownLabel.Text = 'Mask';
            app.MaskDropDown = uidropdown(app.RegionTab);
            app.MaskDropDown.Items = {};
            app.MaskDropDown.Editable = 'on';
            app.MaskDropDown.Enable = 'off';
            app.MaskDropDown.Value = {};
            app.PatternDropDownLabel = uilabel(app.RegionTab);
            app.PatternDropDownLabel.Text = 'Pattern';
            app.PatternDropDown = uidropdown(app.RegionTab);
            app.PatternDropDown.Items = {};
            app.PatternDropDown.Editable = 'on';
            app.PatternDropDown.Value = {};
            app.Size1SpinnerLabel = uilabel(app.RegionTab);
            app.Size1SpinnerLabel.Text = 'Size 1';
            app.Size1Spinner = uispinner(app.RegionTab);
            app.Size1Spinner.Value = 10;
            app.Size2SpinnerLabel = uilabel(app.RegionTab);
            app.Size2SpinnerLabel.Enable = 'off';
            app.Size2SpinnerLabel.Text = 'Size 2';
            app.Size2Spinner = uispinner(app.RegionTab);
            app.Size2Spinner.Enable = 'off';
            app.Size2Spinner.Value = 10;
            app.ShapeDropDownLabel = uilabel(app.RegionTab);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.RegionTab);
            app.ShapeDropDown.Items = {'Circle', 'Square', 'Rectangle', 'Ring', 'Custom'};
            app.ShapeDropDown.ItemsData = {'circle', 'square', 'rect', 'ring', 'custom'};
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            app.ShapeDropDown.Value = 'circle';
            app.PositionEditFieldLabel = uilabel(app.RegionTab);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.RegionTab, 'text');
            app.PositionEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.PositionEditField.Value = '100,100';
            app.AddRegionButton = uibutton(app.Panel, 'push');
            app.AddRegionButton.ButtonPushedFcn = createCallbackFcn(app, @AddRegionButtonPushed, true);
            app.AddRegionButton.Text = 'Add Region';
            app.RemoveRegionButton = uibutton(app.Panel, 'push');
            app.RemoveRegionButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRegionButtonPushed, true);
            app.RemoveRegionButton.Text = 'Remove Region';
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.BackgroundDropDownLabel = uilabel(app.Panel);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.Panel);
            app.BackgroundDropDown.Items = {};
            app.BackgroundDropDown.Editable = 'on';
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.BackgroundDropDown.Value = {};
            app.UIAxes = uiaxes(app.MaskRegionsUIFigure);
            app.UpdateDeviceCheckBox = uicheckbox(app.MaskRegionsUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.MaskRegionsUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.MaskRegionsUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.MaskRegionsUIFigure);
            app.DisplayDropDown.Items = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ItemsData = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Phase mask';
            app.DisplayVariableEditFieldLabel = uilabel(app.MaskRegionsUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.MaskRegionsUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDownLabel = uilabel(app.MaskRegionsUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.MaskRegionsUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = MaskRegion

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaskRegionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaskRegionsUIFigure)
        end
    end
end