classdef SampleRegion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GenerateSampleRegionUIFigure   matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        RegionTab                      matlab.ui.container.Tab
        PositionEditFieldLabel         matlab.ui.control.Label
        PositionEditField              matlab.ui.control.EditField
        TargetEditFieldLabel           matlab.ui.control.Label
        TargetEditField                matlab.ui.control.EditField
        FilterDropDownLabel            matlab.ui.control.Label
        FilterDropDown                 matlab.ui.control.DropDown
        FilterParamsEditFieldLabel     matlab.ui.control.Label
        FilterParamsEditField          matlab.ui.control.EditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.EditField
        AddRegionButton                matlab.ui.control.Button
        RemoveRegionButton             matlab.ui.control.Button
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        BackgroundDropDownLabel        matlab.ui.control.Label
        BackgroundDropDown             matlab.ui.control.DropDown
        SizeEditFieldLabel             matlab.ui.control.Label
        SizeEditField                  matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end


    properties (Access = private)
        oldImages;          % Old background image data
        watchTimer;     % Timer to watch if input images change
    end

    methods (Access = private)
    
        function checkImagesChanged(app, timer, event)
            
            background = app.BackgroundDropDown.Value;
            if ~any(strcmpi(background, app.BackgroundDropDown.ItemsData))
                background = evalin('base', background);
            end
            
            % Check if there is anything to compare to
            if ischar(app.oldImages) || ischar(background)
                return;
            end

            % Get latest images
            if ~all(background == app.oldImages)
                app.patternValueChanged([]);
            end
        end
        
    end
    
    methods (Access = public)
        
        function updateView(app)
            % Redraw the main display
            app.calculateData([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            varnames = evalin('base', 'who');
            
            % Hide the template tab
            app.RegionTab.Parent = [];
            app.PositionEditField.UserData = 'location';
            app.TargetEditField.UserData = 'target';
            app.RadiusEditField.UserData = 'radius';
            app.FilterDropDown.UserData = 'filter';
            app.FilterParamsEditField.UserData = 'filter_params';
            app.RemoveRegionButton.Enable = 'off';
            
            % Populate the display device field
            devices = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'otslm.utils.Showable')
                    devices{end+1} = varnames{ii};
                end
            end
            app.DeviceDropDown.Items = devices;
            app.DeviceDropDown.ItemsData = devices;
            app.DeviceDropDown.Value = '';
            
            % Generate the initial pattern
            app.patternValueChanged([]);
            
            % Setup a timer to watch the images
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.checkImagesChanged;
            start(app.watchTimer);
        end

        % Button pushed function: RemoveRegionButton
        function RemoveRegionButtonPushed(app, event)
            % Remove the current region
            selectedTab = app.TabGroup.SelectedTab;
            if ~isempty(selectedTab)
                selectedTab.Parent = [];
                if isempty(app.TabGroup.Children)
                    app.RemoveRegionButton.Enable = 'off';
                end
            end
            app.patternValueChanged(event);
        end

        % Button pushed function: AddRegionButton
        function AddRegionButtonPushed(app, event)
            tab = copyobj(app.RegionTab, app.TabGroup);
            
            % Add callbacks to new object
            locationEntry = findall(tab, 'UserData', 'location');
            targetEntry = findall(tab, 'UserData', 'target');
            radiusEntry = findall(tab, 'UserData', 'radius');
            filterEntry = findall(tab, 'UserData', 'filter');
            filterParamsEntry = findall(tab, 'UserData', 'filter_params');
            locationEntry.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            targetEntry.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            radiusEntry.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            filterEntry.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            filterParamsEntry.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            
            app.RemoveRegionButton.Enable = 'on';
            app.patternValueChanged(event);
        end

        % Value changed function: BackgroundDropDown, DeviceDropDown, 
        % DisplayDropDown, DisplayVariableEditField, 
        % EnableDisplayCheckBox, FilterDropDown, FilterParamsEditField, 
        % NameEditField, PositionEditField, RadiusEditField, 
        % SizeEditField, TargetEditField, UpdateDeviceCheckBox
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            sz = evalin('base', ['[', app.SizeEditField.Value, ']']);
            background = app.BackgroundDropDown.Value;
            
            % Convert background to actual value
            if ~any(strcmpi(background, app.BackgroundDropDown.ItemsData))
                background = evalin('base', background) .* 2*pi;
            end
            
            % Store last input images (for auto-update)
            app.oldImages = background;
            
            % Get parameters from user interface
            locations = {};
            detectors = {};
            amplitudes = {};
            radius = [];
            for ii = 1:length(app.TabGroup.Children)
                tab = app.TabGroup.Children(ii);
                
                locationEntry = findall(tab, 'UserData', 'location');
                radiusEntry = findall(tab, 'UserData', 'radius');
                targetEntry = findall(tab, 'UserData', 'target');
                filterEntry = findall(tab, 'UserData', 'filter');
                filterParamsEntry = findall(tab, 'UserData', 'filter_params');
                
                locations{ii} = evalin('base', ['[', locationEntry.Value, ']']);
                detectors{ii} = evalin('base', ['[', targetEntry.Value, ']']);
                radius(ii) = evalin('base', ['[', radiusEntry.Value, ']']);
                amplitudes{ii} = {filterEntry.Value};
                amplitudes{ii} = [amplitudes{ii}, evalin('base', ['{', filterParamsEntry.Value, '}'])];
            end
            
            % Generate the pattern
            pattern = otslm.tools.sample_region(sz, locations, detectors, ...
                'background', background, 'amplitude', amplitudes, ...
                'radii', radius);
            
            % Save the pattern in the base workspace
            if ~isempty(name)
                if ~isvarname(name)
                    warning('Name must be valid variable name');
                end
                
                assignin('base', name, pattern);
            end
            
            % Display image on the device
            slm = [];
            sname = app.DeviceDropDown.Value;
            if ~isempty(sname)
                if ~isvarname(sname)
                    warning('Invalid device name specified');
                end
                slm = evalin('base', sname);
                
                % Validate the handle (make sure its not deleted)
                if ~isvalid(slm)
                    warning('Invalid or deleted device');
                    slm = [];
                end
            end
            if app.UpdateDeviceCheckBox.Value && ~isempty(slm)
                slm.show(pattern);
            end
            
            % Generate display pattern
            if app.EnableDisplayCheckBox.Value
                
                % Generate the pattern to display
                dpattern = [];
                switch app.DisplayDropDown.Value
                    case 'Phase mask'
                        dpattern = otslm.tools.finalize(pattern, 'colormap', 'pmpi');
                    case 'Raw phase mask'
                        dpattern = pattern;
                    case 'Device image'
                        if ~isempty(slm)
                            dpattern = slm.view(pattern);
                        else
                            warning('Device not setup');
                        end
                    case 'Simulated farfield'
                        dpattern = otslm.tools.finalize(pattern, 'colormap', 'pmpi');
                        dpattern = otslm.tools.visualise(dpattern, 'method', 'fft', 'trim_padding', true, ...
                            'padding', ceil(size(dpattern)/2), 'incident', ones(size(dpattern)));
                        dpattern = abs(dpattern).^2;
                    otherwise
                        error('Invalid drop down option selected');
                end
                
                % Show the pattern in the window
                if ~isempty(dpattern)
                    imagesc(app.UIAxes, dpattern);
                    axis(app.UIAxes, 'image');
                    set(app.UIAxes, 'xtick', [], 'xticklabel', []);
                    set(app.UIAxes, 'ytick', [], 'yticklabel', []);
                end
                
                % Write pattern to workspace
                if ~isempty(app.DisplayVariableEditField.Value)
                    dname = app.DisplayVariableEditField.Value;
                    if ~isvarname(dname)
                        warning('Display variable must be valid variable name');
                    end
                    
                    assignin('base', dname, dpattern);
                end
            end
            
        end

        % Close request function: GenerateSampleRegionUIFigure
        function GenerateSampleRegionUIFigureCloseRequest(app, event)
            try
                stop(app.watchTimer);
                delete(app.watchTimer);
            catch ME
                warning(ME.message);
            end
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GenerateSampleRegionUIFigure = uifigure;
            app.GenerateSampleRegionUIFigure.Name = 'Generate Sample Region';
            app.GenerateSampleRegionUIFigure.CloseRequestFcn = createCallbackFcn(app, @GenerateSampleRegionUIFigureCloseRequest, true);
            app.Panel = uipanel(app.GenerateSampleRegionUIFigure);
            app.TabGroup = uitabgroup(app.Panel);
            app.RegionTab = uitab(app.TabGroup);
            app.RegionTab.Title = 'Region';
            app.PositionEditFieldLabel = uilabel(app.RegionTab);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.RegionTab, 'text');
            app.PositionEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.PositionEditField.Value = '100,100';
            app.TargetEditFieldLabel = uilabel(app.RegionTab);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TargetEditField = uieditfield(app.RegionTab, 'text');
            app.TargetEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.TargetEditField.Value = '10,10';
            app.FilterDropDownLabel = uilabel(app.RegionTab);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.RegionTab);
            app.FilterDropDown.Items = {'Step', 'Gaussian Dither', 'Gaussian Noise', 'Gaussian Scale'};
            app.FilterDropDown.ItemsData = {'step', 'gaussian_dither', 'gaussian_noise', 'gaussian_scale'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.FilterDropDown.Value = 'step';
            app.FilterParamsEditFieldLabel = uilabel(app.RegionTab);
            app.FilterParamsEditFieldLabel.Text = 'Filter Params';
            app.FilterParamsEditField = uieditfield(app.RegionTab, 'text');
            app.FilterParamsEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.RadiusEditFieldLabel = uilabel(app.RegionTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.RegionTab, 'text');
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.RadiusEditField.Value = '10';
            app.AddRegionButton = uibutton(app.Panel, 'push');
            app.AddRegionButton.ButtonPushedFcn = createCallbackFcn(app, @AddRegionButtonPushed, true);
            app.AddRegionButton.Text = 'Add Region';
            app.RemoveRegionButton = uibutton(app.Panel, 'push');
            app.RemoveRegionButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRegionButtonPushed, true);
            app.RemoveRegionButton.Text = 'Remove Region';
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.BackgroundDropDownLabel = uilabel(app.Panel);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.Panel);
            app.BackgroundDropDown.Items = {'Zero', 'NaN', 'Checker Board', 'Random', 'Random Binary'};
            app.BackgroundDropDown.ItemsData = {'zero', 'nan', 'checkerboard', 'random', 'randombin'};
            app.BackgroundDropDown.Editable = 'on';
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.BackgroundDropDown.Value = 'zero';
            app.SizeEditFieldLabel = uilabel(app.Panel);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.Panel, 'text');
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.SizeEditField.Value = '256, 256';
            app.UIAxes = uiaxes(app.GenerateSampleRegionUIFigure);
            app.UpdateDeviceCheckBox = uicheckbox(app.GenerateSampleRegionUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.GenerateSampleRegionUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.GenerateSampleRegionUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.GenerateSampleRegionUIFigure);
            app.DisplayDropDown.Items = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ItemsData = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Phase mask';
            app.DisplayVariableEditFieldLabel = uilabel(app.GenerateSampleRegionUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.GenerateSampleRegionUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDownLabel = uilabel(app.GenerateSampleRegionUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.GenerateSampleRegionUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = SampleRegion

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GenerateSampleRegionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GenerateSampleRegionUIFigure)
        end
    end
end