classdef Visualise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualiseUIFigure              matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        TrimCheckBox                   matlab.ui.control.CheckBox
        AmplitudeDropDownLabel         matlab.ui.control.Label
        AmplitudeDropDown              matlab.ui.control.DropDown
        PhaseDropDownLabel             matlab.ui.control.Label
        PhaseDropDown                  matlab.ui.control.DropDown
        IncidentDropDownLabel          matlab.ui.control.Label
        IncidentDropDown               matlab.ui.control.DropDown
        AxialpositionSpinnerLabel      matlab.ui.control.Label
        AxialpositionSpinner           matlab.ui.control.Spinner
        PaddingSpinnerLabel            matlab.ui.control.Label
        PaddingSpinner                 matlab.ui.control.Spinner
        MethodDropDownLabel            matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        VSWFNmaxSpinnerLabel           matlab.ui.control.Label
        VSWFNmaxSpinner                matlab.ui.control.Spinner
        NumericalApertureSpinnerLabel  matlab.ui.control.Label
        NumericalApertureSpinner       matlab.ui.control.Spinner
        SamplingSpinnerLabel           matlab.ui.control.Label
        SamplingSpinner                matlab.ui.control.Spinner
        FinalizeCheckBox               matlab.ui.control.CheckBox
        DataOutputEditFieldLabel       matlab.ui.control.Label
        DataOutputEditField            matlab.ui.control.EditField
        VisualizationOutputEditFieldLabel  matlab.ui.control.Label
        VisualizationOutputEditField   matlab.ui.control.EditField
        LogscaleCheckBox               matlab.ui.control.CheckBox
        ShowColorbarCheckBox           matlab.ui.control.CheckBox
        ZoomButton                     matlab.ui.control.StateButton
        PanButton                      matlab.ui.control.StateButton
        ResetViewButton                matlab.ui.control.Button
        ContoursEditFieldLabel         matlab.ui.control.Label
        ContoursEditField              matlab.ui.control.EditField
        ShowColormapCheckBox           matlab.ui.control.CheckBox
        ShowContoursCheckBox           matlab.ui.control.CheckBox
        RotateButton                   matlab.ui.control.StateButton
        FieldDropDownLabel             matlab.ui.control.Label
        FieldDropDown                  matlab.ui.control.DropDown
    end


    properties (Access = private)
        data      % Plot data calculated at last run
        oldSize   % Old size of the data (for axis scaling)

        oldImages;      % Old images from last watch
        watchTimer;     % Timer to watch if input images change
    end

    methods (Access = private)
        
        function image = getSingleImage(app, name)
            
            if isempty(name)
                image = [];
            else
                try
                    image = evalin('base', name);
                catch ME
                    warning(ME.message);
                    image = [];
                end
            end
        end
        
        function images = getImages(app)
            
            % Parse images
            phase = app.getSingleImage(app.PhaseDropDown.Value);
            amplitude = app.getSingleImage(app.AmplitudeDropDown.Value);
            incident = app.getSingleImage(app.IncidentDropDown.Value);
            
            % Package result in struct
            images = struct();
            images.incident = incident;
            images.phase = phase;
            images.amplitude = amplitude;
        end
    
        function checkImagesChanged(app, timer, event)
            
            % Get latest images
            newImages = struct2cell(app.getImages());
            oldImages = struct2cell(app.oldImages);
            
            % If images have changed, update the display
            changed = false;
            for ii = 1:length(newImages)
                if any(size(newImages{ii}) ~= size(oldImages{ii})) || ...
                    any(newImages{ii}(:) ~= oldImages{ii}(:))
                    changed = true;
                    break;
                end
            end
            if changed
                app.calculateData([]);
            end
        end
        
    end

    methods (Access = public)
    
        function updateView(app)
            % Redraw the main display
            app.calculateData([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Populate input array with variables
            varnames = evalin('base', 'who');
            app.PhaseDropDown.Items = varnames;
            app.AmplitudeDropDown.Items = varnames;
            app.IncidentDropDown.Items = varnames;
            app.PhaseDropDown.Value = '';
            app.AmplitudeDropDown.Value = '';
            app.IncidentDropDown.Value = '';
            
            app.oldSize = [];
            
            % Add a figure for testing buttons
            app.calculateData([]);
            app.replotData([]);
            
            % Setup a timer to watch the images
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.checkImagesChanged;
            start(app.watchTimer);
            
        end

        % Value changed function: ShowColorbarCheckBox
        function ShowColorbarCheckBoxValueChanged(app, event)
            if app.ShowColorbarCheckBox.Value
                colorbar(app.UIAxes);
            else
                colorbar(app.UIAxes, 'off');
            end
        end

        % Value changed function: ZoomButton
        function ZoomButtonValueChanged(app, event)
            if app.ZoomButton.Value
                zoom(app.UIAxes, 'on');
                app.PanButton.Value = false;
                app.RotateButton.Value = false;
            else
                zoom(app.UIAxes, 'off');
            end
        end

        % Value changed function: PanButton
        function PanButtonValueChanged(app, event)
            if app.PanButton.Value
                pan(app.UIAxes, 'on');
                app.ZoomButton.Value = false;
                app.RotateButton.Value = false;
            else
                pan(app.UIAxes, 'off');
            end
        end

        % Value changed function: RotateButton
        function RotateButtonValueChanged(app, event)
            if app.RotateButton.Value
                rotate3d(app.UIAxes, 'on');
                app.PanButton.Value = false;
                app.ZoomButton.Value = false;
            else
                rotate3d(app.UIAxes, 'off');
            end
        end

        % Button pushed function: ResetViewButton
        function ResetViewButtonPushed(app, event)
            reset(app.UIAxes);
            axis(app.UIAxes, 'image');
            set(app.UIAxes,'YDir','reverse');
        end

        % Value changed function: ContoursEditField, FieldDropDown, 
        % LogscaleCheckBox, ShowColormapCheckBox, ShowContoursCheckBox, 
        % VisualizationOutputEditField
        function replotData(app, event)
            
            zposition = app.AxialpositionSpinner.Value;
            
            % Choose method
            switch app.MethodDropDown.Value
                case 'fft2d'
                    odata = app.data;
                    shape = '2d';
                case 'fft3d'
                    odata = app.data;
                    shape = '3d';
                case 'ott2d'
                    odata = app.data.visualise('axis', 'z', ...
                        'offset', zposition, 'field', 'irradiance', ...
                        'range', 2.*[1,1].*ott.utils.nmax2ka(app.data.Nmax)./abs(app.data.k_medium));
                    shape = '2d';
                case 'ott3d'
                    
                    range = 2.*[1,1,1].*ott.utils.nmax2ka(app.data.Nmax)./abs(app.data.k_medium);
                    sz = [80, 80, 80];
                    
                    xrange = linspace(-1, 1, sz(1))*range(1);
                    yrange = linspace(-1, 1, sz(2))*range(2);
                    zrange = linspace(-1, 1, sz(3))*range(3);
                    [xx, yy, zz] = meshgrid(xrange, yrange, zrange);
                    
                    xyz = [xx(:), yy(:), zz(:)];
                    
                    % Calculate the electric field
                    [E, ~, ~] = app.data.emFieldXyz(xyz.', 'calcE', true, 'calcH', false);
                
                    odata = reshape(sqrt(sum(abs(E).^2, 1)), sz);
                    
                    shape = '3d';
                otherwise
                    error('Invalid drop down selection');
            end
            
            switch app.FieldDropDown.Value
                case 'Amplitude'
                    odata = abs(odata).^2;
                case 'Phase'
                    odata = angle(odata);
                case 'Real'
                    odata = real(odata);
                case 'Real Ex'
                    error('Not yet implemented');
                case 'Real Ey'
                    error('Not yet implemented');
                case 'Real Ez'
                    error('Not yet implemented');
                case 'Amplitude Ex'
                    error('Not yet implemented');
                case 'Amplitude Ey'
                    error('Not yet implemented');
                case 'Amplitude Ez'
                    error('Not yet implemented');
                otherwise
                    error('Invalid drop down option selected');
            end
            
            % If log scale requested, log data
            if app.LogscaleCheckBox.Value && strcmpi(app.FieldDropDown.Value, 'Amplitude')
                odata = log10(odata);
            end
            
            % Save the current axis state
            oldaxis = axis(app.UIAxes);
            
            % Plot the colormap if requested
            if app.ShowColormapCheckBox.Value && ~strcmpi(shape, '3d')
                imagesc(app.UIAxes, odata);
                hold(app.UIAxes, 'on');
            end
            
            % Calculate contours if requested
            if app.ShowContoursCheckBox.Value
                levels = evalin('base', ['[', app.ContoursEditField.Value, ']']);
                
                if strcmpi(shape, '2d')
                
                    % If we are already showing the colour map, no need for colour
                    if app.ShowColormapCheckBox.Value
                        spec = '-w';
                    else
                        spec = '-';
                    end
                    
                    contour(app.UIAxes, odata, levels, spec);
                else
                    cla(app.UIAxes);
                    disp('Starting contour calculation...');
%                     odata = reducevolume(odata, [2, 2, 2]);

                    cmap = colormap(app.UIAxes);

                    for ii = 1:length(levels)
                        scaled_level = (levels(ii) - min(levels))./(max(levels)-min(levels));
                        if isnan(scaled_level)
                            scaled_level = 0.5;
                        end
                        color = interp1(linspace(0, 1, size(cmap, 1)), cmap, scaled_level);
                        p = patch(app.UIAxes, isosurface(odata, levels(ii)), 'EdgeColor', 'none', 'FaceColor', color);
                        isonormals(odata,p);
                    end
                    camlight(app.UIAxes);
                    lighting(app.UIAxes, 'phong');
                    
                    disp('done contour calculation');
                end
                
                hold(app.UIAxes, 'on');
            end
            
            hold(app.UIAxes, 'off');
            
            if ~isempty(app.oldSize) && length(app.oldSize) == length(size(app.data)) ...
                    && all(app.oldSize == size(app.data))
                % Ensure the axis has the correct shape
                axis(app.UIAxes, oldaxis);
            else
                % Reset the axis for new data shape
                axis(app.UIAxes, 'image');
            end
            app.oldSize = size(app.data);
            
            % Save the image if requested
            if isvarname(app.VisualizationOutputEditField.Value)
                imdata = getimage(app.UIAxes);
                assignin('base', app.VisualizationOutputEditField.Value, imdata);
            end
            
        end

        % Value changed function: AmplitudeDropDown, 
        % AxialpositionSpinner, DataOutputEditField, FinalizeCheckBox, 
        % IncidentDropDown, NumericalApertureSpinner, PaddingSpinner, 
        % PhaseDropDown, SamplingSpinner, TrimCheckBox, VSWFNmaxSpinner
        function calculateData(app, event)
            
            % Get images
            app.oldImages = app.getImages();
            phase = app.oldImages.phase;
            amplitude = app.oldImages.amplitude;
            incident = app.oldImages.incident;
            
            % Check if we have anything to do
            if isempty(phase) && isempty(incident) && isempty(amplitude)
                app.data = [];
                return;
            end
            
            % Get other UI inputs
            zposition = app.AxialpositionSpinner.Value;
            padding = app.PaddingSpinner.Value;
            trim_padding = app.TrimCheckBox.Value;
            NA = app.NumericalApertureSpinner.Value;
            sampling = app.SamplingSpinner.Value;
            Nmax = app.VSWFNmaxSpinner.Value;
            
            % Finalize the pattern (convert from 0-1 to -pi-pi)
            if isreal(phase) && app.FinalizeCheckBox.Value
                phase = otslm.tools.finalize(phase, 'colormap', 'pmpi');
            end
            
            % Remove NaN values from amplitude
            amplitude(isnan(amplitude)) = 0.0;
            incident(isnan(incident)) = 0.0;
            
            % Generate U for ott methods
            U = otslm.tools.make_beam(phase, 'incident', incident, 'amplitude', amplitude);
            
            % Choose method
            switch app.MethodDropDown.Value
                case 'fft2d'
                    app.data = otslm.tools.visualise(phase, 'amplitude', amplitude, ...
                        'incident', incident, 'method', 'fft', 'padding', padding, ...
                        'trim_padding', trim_padding, 'z', zposition, 'NA', NA, ...
                        'resample', sampling);
                case 'fft3d'
                    app.data = otslm.tools.visualise(phase, 'amplitude', amplitude, ...
                        'incident', incident, 'method', 'fft3', 'padding', padding, ...
                        'trim_padding', trim_padding, 'z', zposition, 'NA', NA, ...
                        'resample', sampling);
                case 'ott2d'
                    app.data = otslm.tools.hologram2bsc(U, 'NA', NA, ...
                        'index_medium', 1.0, 'Nmax', Nmax, 'incident', incident, ...
                        'amplitude', amplitude);
                case 'ott3d'
                    app.data = otslm.tools.hologram2bsc(U, 'NA', NA, ...
                        'index_medium', 1.0, 'Nmax', Nmax, 'incident', incident, ...
                        'amplitude', amplitude);
                otherwise
                    error('Invalid drop down selection');
            end
            
            % Save data if requested
            if isvarname(app.DataOutputEditField.Value)
                assignin('base', app.DataOutputEditField.Value, app.data);
            end
            
            % Update the visualisation
            app.replotData(event);
            
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            switch app.MethodDropDown.Value
                case 'fft2d'
                    app.AxialpositionSpinner.Enable = 'on';
                    app.TrimCheckBox.Enable = 'on';
                    app.VSWFNmaxSpinner.Enable = 'off';
                case 'fft3d'
                    app.AxialpositionSpinner.Enable = 'off';
                    app.TrimCheckBox.Enable = 'on';
                    app.VSWFNmaxSpinner.Enable = 'off';
                case 'ott2d'
                    app.AxialpositionSpinner.Enable = 'on';
                    app.TrimCheckBox.Enable = 'off';
                    app.VSWFNmaxSpinner.Enable = 'on';
                case 'ott3d'
                    app.AxialpositionSpinner.Enable = 'off';
                    app.TrimCheckBox.Enable = 'off';
                    app.VSWFNmaxSpinner.Enable = 'on';
                otherwise
                    error('Invalid drop down selection');
            end
            
            % Clear the figure window
            cla(app.UIAxes);
            
            % Continue to process the event
            app.calculateData(event);
        end

        % Close request function: VisualiseUIFigure
        function VisualiseUIFigureCloseRequest(app, event)
            stop(app.watchTimer);
            delete(app.watchTimer);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualiseUIFigure = uifigure;
            app.VisualiseUIFigure.Name = 'Visualise';
            app.VisualiseUIFigure.CloseRequestFcn = createCallbackFcn(app, @VisualiseUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.VisualiseUIFigure);
            app.Panel = uipanel(app.VisualiseUIFigure);
            app.TrimCheckBox = uicheckbox(app.Panel);
            app.TrimCheckBox.ValueChangedFcn = createCallbackFcn(app, @calculateData, true);
            app.TrimCheckBox.Text = 'Trim';
            app.TrimCheckBox.Value = true;
            app.AmplitudeDropDownLabel = uilabel(app.Panel);
            app.AmplitudeDropDownLabel.Text = 'Amplitude';
            app.AmplitudeDropDown = uidropdown(app.Panel);
            app.AmplitudeDropDown.Items = {};
            app.AmplitudeDropDown.Editable = 'on';
            app.AmplitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @calculateData, true);
            app.AmplitudeDropDown.Value = {};
            app.PhaseDropDownLabel = uilabel(app.Panel);
            app.PhaseDropDownLabel.Text = 'Phase';
            app.PhaseDropDown = uidropdown(app.Panel);
            app.PhaseDropDown.Items = {};
            app.PhaseDropDown.Editable = 'on';
            app.PhaseDropDown.ValueChangedFcn = createCallbackFcn(app, @calculateData, true);
            app.PhaseDropDown.Value = {};
            app.IncidentDropDownLabel = uilabel(app.Panel);
            app.IncidentDropDownLabel.Text = 'Incident';
            app.IncidentDropDown = uidropdown(app.Panel);
            app.IncidentDropDown.Items = {};
            app.IncidentDropDown.Editable = 'on';
            app.IncidentDropDown.ValueChangedFcn = createCallbackFcn(app, @calculateData, true);
            app.IncidentDropDown.Value = {};
            app.AxialpositionSpinnerLabel = uilabel(app.Panel);
            app.AxialpositionSpinnerLabel.Text = 'Axial position';
            app.AxialpositionSpinner = uispinner(app.Panel);
            app.AxialpositionSpinner.Step = 0.1;
            app.AxialpositionSpinner.ValueChangedFcn = createCallbackFcn(app, @calculateData, true);
            app.PaddingSpinnerLabel = uilabel(app.Panel);
            app.PaddingSpinnerLabel.Text = 'Padding';
            app.PaddingSpinner = uispinner(app.Panel);
            app.PaddingSpinner.Limits = [0 Inf];
            app.PaddingSpinner.RoundFractionalValues = 'on';
            app.PaddingSpinner.ValueDisplayFormat = '%.0f';
            app.PaddingSpinner.ValueChangedFcn = createCallbackFcn(app, @calculateData, true);
            app.PaddingSpinner.Value = 100;
            app.MethodDropDownLabel = uilabel(app.Panel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Panel);
            app.MethodDropDown.Items = {'2D Fourier Transform', '2D OTT/VSWF', '3D Fourier Transform', '3D OTT/VSWF'};
            app.MethodDropDown.ItemsData = {'fft2d', 'ott2d', 'fft3d', 'ott3d'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'fft2d';
            app.VSWFNmaxSpinnerLabel = uilabel(app.Panel);
            app.VSWFNmaxSpinnerLabel.Text = 'VSWF Nmax';
            app.VSWFNmaxSpinner = uispinner(app.Panel);
            app.VSWFNmaxSpinner.Limits = [1 Inf];
            app.VSWFNmaxSpinner.RoundFractionalValues = 'on';
            app.VSWFNmaxSpinner.ValueDisplayFormat = '%.0f';
            app.VSWFNmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @calculateData, true);
            app.VSWFNmaxSpinner.Enable = 'off';
            app.VSWFNmaxSpinner.Value = 10;
            app.NumericalApertureSpinnerLabel = uilabel(app.Panel);
            app.NumericalApertureSpinnerLabel.Text = 'Numerical Aperture';
            app.NumericalApertureSpinner = uispinner(app.Panel);
            app.NumericalApertureSpinner.Step = 0.1;
            app.NumericalApertureSpinner.Limits = [0 1];
            app.NumericalApertureSpinner.ValueChangedFcn = createCallbackFcn(app, @calculateData, true);
            app.NumericalApertureSpinner.Value = 0.9;
            app.SamplingSpinnerLabel = uilabel(app.Panel);
            app.SamplingSpinnerLabel.Text = 'Sampling';
            app.SamplingSpinner = uispinner(app.Panel);
            app.SamplingSpinner.Limits = [0 Inf];
            app.SamplingSpinner.ValueChangedFcn = createCallbackFcn(app, @calculateData, true);
            app.SamplingSpinner.Value = 1;
            app.FinalizeCheckBox = uicheckbox(app.Panel);
            app.FinalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @calculateData, true);
            app.FinalizeCheckBox.Text = 'Finalize';
            app.FinalizeCheckBox.Value = true;
            app.DataOutputEditFieldLabel = uilabel(app.Panel);
            app.DataOutputEditFieldLabel.Text = 'Data Output';
            app.DataOutputEditField = uieditfield(app.Panel, 'text');
            app.DataOutputEditField.ValueChangedFcn = createCallbackFcn(app, @calculateData, true);
            app.VisualizationOutputEditFieldLabel = uilabel(app.Panel);
            app.VisualizationOutputEditFieldLabel.Text = 'Visualization Output';
            app.VisualizationOutputEditField = uieditfield(app.Panel, 'text');
            app.VisualizationOutputEditField.ValueChangedFcn = createCallbackFcn(app, @replotData, true);
            app.LogscaleCheckBox = uicheckbox(app.VisualiseUIFigure);
            app.LogscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @replotData, true);
            app.LogscaleCheckBox.Text = 'Log scale';
            app.ShowColorbarCheckBox = uicheckbox(app.VisualiseUIFigure);
            app.ShowColorbarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowColorbarCheckBoxValueChanged, true);
            app.ShowColorbarCheckBox.Text = 'Show Colorbar';
            app.ZoomButton = uibutton(app.VisualiseUIFigure, 'state');
            app.ZoomButton.ValueChangedFcn = createCallbackFcn(app, @ZoomButtonValueChanged, true);
            app.ZoomButton.Text = 'Zoom';
            app.PanButton = uibutton(app.VisualiseUIFigure, 'state');
            app.PanButton.ValueChangedFcn = createCallbackFcn(app, @PanButtonValueChanged, true);
            app.PanButton.Text = 'Pan';
            app.ResetViewButton = uibutton(app.VisualiseUIFigure, 'push');
            app.ResetViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetViewButtonPushed, true);
            app.ResetViewButton.Text = 'Reset View';
            app.ContoursEditFieldLabel = uilabel(app.VisualiseUIFigure);
            app.ContoursEditFieldLabel.Text = 'Contours';
            app.ContoursEditField = uieditfield(app.VisualiseUIFigure, 'text');
            app.ContoursEditField.ValueChangedFcn = createCallbackFcn(app, @replotData, true);
            app.ContoursEditField.Value = '5';
            app.ShowColormapCheckBox = uicheckbox(app.VisualiseUIFigure);
            app.ShowColormapCheckBox.ValueChangedFcn = createCallbackFcn(app, @replotData, true);
            app.ShowColormapCheckBox.Text = 'Show Colormap';
            app.ShowColormapCheckBox.Value = true;
            app.ShowContoursCheckBox = uicheckbox(app.VisualiseUIFigure);
            app.ShowContoursCheckBox.ValueChangedFcn = createCallbackFcn(app, @replotData, true);
            app.ShowContoursCheckBox.Text = 'Show Contours';
            app.RotateButton = uibutton(app.VisualiseUIFigure, 'state');
            app.RotateButton.ValueChangedFcn = createCallbackFcn(app, @RotateButtonValueChanged, true);
            app.RotateButton.Text = 'Rotate';
            app.FieldDropDownLabel = uilabel(app.VisualiseUIFigure);
            app.FieldDropDownLabel.Text = 'Field';
            app.FieldDropDown = uidropdown(app.VisualiseUIFigure);
            app.FieldDropDown.Items = {'Amplitude', 'Phase', 'Real'};
            app.FieldDropDown.ItemsData = {'Amplitude', 'Phase', 'Real'};
            app.FieldDropDown.ValueChangedFcn = createCallbackFcn(app, @replotData, true);
            app.FieldDropDown.Value = 'Amplitude';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Visualise

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualiseUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualiseUIFigure)
        end
    end
end