classdef CalibrationSMichelson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibrationSlopedMichelsonUIFigure  matlab.ui.Figure
        CameraUIAxes            matlab.ui.control.UIAxes
        PhaseUIAxes             matlab.ui.control.UIAxes
        Panel                   matlab.ui.container.Panel
        NameEditFieldLabel      matlab.ui.control.Label
        NameEditField           matlab.ui.control.EditField
        SLMDropDownLabel        matlab.ui.control.Label
        SLMDropDown             matlab.ui.control.DropDown
        CameraDropDownLabel     matlab.ui.control.Label
        CameraDropDown          matlab.ui.control.DropDown
        SliceAngleSpinnerLabel  matlab.ui.control.Label
        SliceAngleSpinner       matlab.ui.control.Spinner
        Slice1SpinnerLabel      matlab.ui.control.Label
        Slice1Spinner           matlab.ui.control.Spinner
        Slice2SpinnerLabel      matlab.ui.control.Label
        Slice2Spinner           matlab.ui.control.Spinner
        DelaySpinnerLabel       matlab.ui.control.Label
        DelaySpinner            matlab.ui.control.Spinner
        StrideSpinnerLabel      matlab.ui.control.Label
        StrideSpinner           matlab.ui.control.Spinner
        Slice2WidthSpinner      matlab.ui.control.Spinner
        Slice1WidthSpinner      matlab.ui.control.Spinner
        RandomTestButton        matlab.ui.control.Button
        RunButton               matlab.ui.control.Button
        FrequencySpinnerLabel   matlab.ui.control.Label
        FrequencySpinner        matlab.ui.control.Spinner
        StepAngleSpinnerLabel   matlab.ui.control.Label
        StepAngleSpinner        matlab.ui.control.Spinner
        PsdUIAxes               matlab.ui.control.UIAxes
    end


    properties (Access = public)
        data            % The lookup table we have generated
        
        oneshotIndex    % The index used in the one-shot test
        oneshotImage    % The image acquired using RandomTestButton
    end
    
    methods (Access = private)
        
        function [slm, cam] = getSlmCamera(app)
            
            slm = [];
            cam = [];
            
            if isempty(app.SLMDropDown.Value) || isempty(app.CameraDropDown.Value)
                return;
            end
            
            slm = evalin('base', app.SLMDropDown.Value);
            cam = evalin('base', app.CameraDropDown.Value);
            
        end
    
        function updateResult(app)
            
            % Check there is work to do
            if isempty(app.data)
                return;
            end
            
            % Draw the result of the last run
            plot(app.PhaseUIAxes, 1+(0:(length(app.data.phase)-1))*app.StrideSpinner.Value, ...
                app.data.phase);
            
            % Get the range for the phase values graph
            [slm, ~] = app.getSlmCamera();
            if isempty(slm)
                maxRange = 2*app.oneshotIndex;
            else
                maxRange = slm.valueRangeNumel();
            end
            axis(app.PhaseUIAxes, [1, maxRange, 0, 2*pi]);
            set(app.PhaseUIAxes, 'XTick', 1:round(maxRange/10):maxRange);
            
        end
    
        function drawSlice(app, offset, swidth, color)
            % Draw a single slice on the graph
            
            axrange = axis(app.CameraUIAxes);
            
            imwidth = size(app.oneshotImage, 2);
            imheight = size(app.oneshotImage, 1);
            theta = pi/180 * app.SliceAngleSpinner.Value;
            len = sqrt(imwidth.^2 + imheight.^2);
            
            % Calculate location of slice endpoints
            x0 = len/2 * cos(theta) + imwidth/2 - offset*sin(theta);
            x1 = -len/2 * cos(theta) + imwidth/2 - offset*sin(theta);
            y0 = len/2 * sin(theta) + imheight/2 + offset*cos(theta);
            y1 = -len/2 * sin(theta) + imheight/2 + offset*cos(theta);
            x0_r = x0 - swidth*sin(theta);
            x1_r = x1 - swidth*sin(theta);
            y0_r = y0 + swidth*cos(theta);
            y1_r = y1 + swidth*cos(theta);
            
            % Add slice markers
            hold(app.CameraUIAxes, 'on');
            line(app.CameraUIAxes, [x0, x1], [y0, y1], 'Color', color);
            line(app.CameraUIAxes, [x0_r, x1_r], [y0_r, y1_r], 'Color', color);
            hold(app.CameraUIAxes, 'off');
            axis(app.CameraUIAxes, axrange);
            
        end
    
        function [fvals, freqs] = calculateFrequency(app, offset, swidth)
            
            imwidth = size(app.oneshotImage, 2);
            imheight = size(app.oneshotImage, 1);
            theta = pi/180 * app.SliceAngleSpinner.Value;
            len = sqrt(imwidth.^2 + imheight.^2);
            
            % Calculate frequency of slice
            [xx0, yy0] = meshgrid(1:imwidth, 1:imheight);
            [xx, yy] = meshgrid(-len/2:len/2, (0.5:(swidth-0.5)) + offset);
            xxR = xx .* cos(theta) + imwidth/2 - yy .* sin(theta);
            yyR = xx .* sin(theta) + imheight/2 + yy .* cos(theta);
            vals = interp2(xx0, yy0, app.oneshotImage, xxR, yyR);
            vals(isnan(vals)) = 0.0;
            vals = sum(vals, 1);
            fvals = fft(vals);
            fvals = fvals(1:ceil(length(fvals)/2));
            freqs = 1:length(fvals);
            
        end
    
        function updateDisplays(app)
            % Redraw all the displays
            
            % Only do this if there is an image
            if isempty(app.oneshotImage)
                return;
            end
            
            % Draw the image
            imagesc(app.CameraUIAxes, app.oneshotImage);
            axis(app.CameraUIAxes, 'image');
            
            % Draw visual markers for slices
            app.drawSlice(app.Slice1Spinner.Value, app.Slice1WidthSpinner.Value, 'blue');
            app.drawSlice(app.Slice2Spinner.Value, app.Slice2WidthSpinner.Value, 'red');
            
            [fvals1, freqs1] = app.calculateFrequency(app.Slice1Spinner.Value, app.Slice1WidthSpinner.Value);
            [fvals2, freqs2] = app.calculateFrequency(app.Slice2Spinner.Value, app.Slice2WidthSpinner.Value);
            
            % Draw power spectrum
            semilogy(app.PsdUIAxes, freqs1, abs(fvals1).^2, 'b', ...
                freqs2, abs(fvals2).^2, 'r');
            
            % Draw frequency marker
            axrange = axis(app.PsdUIAxes);
            hold(app.PsdUIAxes, 'on');
            line(app.PsdUIAxes, [1,1].*app.FrequencySpinner.Value, axrange(3:4));
            hold(app.PsdUIAxes, 'off');
            
        end
        
        function writeData(app)
            % Check we have data to write
            if isempty(app.data) || isempty(app.NameEditField.Value)
                return;
            end
            
            % Check we have a valid variable name
            if isvarname(app.NameEditField.Value)
                assignin('base', app.NameEditField.Value, app.data);
            else
                error('Invalid variable name for lookup table name field');
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            varnames = evalin('base', 'who');
            
            % Populate the drop down menus
            cameras = {};
            slms = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'otslm.utils.Showable')
                    slms{end+1} = varnames{ii};
                end
                if isa(evalin('base', varnames{ii}), 'otslm.utils.Viewable')
                    cameras{end+1} = varnames{ii};
                end
            end
            app.SLMDropDown.Items = slms;
            app.SLMDropDown.ItemsData = slms;
            app.SLMDropDown.Value = '';
            app.CameraDropDown.Items = cameras;
            app.CameraDropDown.ItemsData = cameras;
            app.CameraDropDown.Value = '';
            
            % Setup the axis labels for the phase plot
            set(app.PhaseUIAxes, 'YTick', [0, pi/2, pi, 3*pi/2, 2*pi], ...
                'YTickLabel', {'0', '\pi/2', '\pi', '3\pi/2', '2\pi'});
        end

        % Value changed function: Slice1Spinner, Slice1WidthSpinner, 
        % Slice2Spinner, Slice2WidthSpinner, SliceAngleSpinner, 
        % StepAngleSpinner
        function sliceValueChanged(app, event)
            
            % Update the view of the slice on the graph
            % Update the power spectrum graph
            app.updateDisplays();
            
        end

        % Value changed function: FrequencySpinner
        function frequencyValueChanged(app, event)
            
            % Display the new frequency
            app.updateDisplays();
            
        end

        % Button pushed function: RandomTestButton
        function RandomTestButtonPushed(app, event)
            
            % Get the SLM and camera
            [slm, cam] = app.getSlmCamera();
            if isempty(slm) || isempty(cam)
                return;
            end
            
            step_angle = app.StepAngleSpinner.Value;
            
            % Generate a random image for the SLM and display
            ridx = randi(slm.valueRangeNumel(), 1);
            step = otslm.simple.step(slm.size, 'angle_deg', step_angle, 'value', [1, ridx]);
            slm.showIndexed(step);
            
            % Acquire image of the device
            app.oneshotImage = cam.viewTarget();
            app.oneshotIndex = ridx;
            
            % Update camera view, power spectrum and phase estimate
            app.updateDisplays();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % Get the SLM and camera
            [slm, cam] = app.getSlmCamera();
            if isempty(slm) || isempty(cam)
                return;
            end
            
            % Handle no delay case
            delay = app.DelaySpinner.Value;
            if abs(delay) <= eps(0)
                delay = [];
            end
            
            % Run the full method
            app.data = otslm.utils.calibration.smichelson(slm, cam, ...
                'slice1_offset', app.Slice1Spinner.Value, ...
                'slice1_width', app.Slice1WidthSpinner.Value, ...
                'slice2_offset', app.Slice2Spinner.Value, ...
                'slice2_width', app.Slice2WidthSpinner.Value, ...
                'slice_angle', app.SliceAngleSpinner.Value, ...
                'step_angle', app.StepAngleSpinner.Value, ...
                'freq_index', app.FrequencySpinner.Value, ...
                'delay', delay, ...
                'stride', app.StrideSpinner.Value);
            
            % Write the latest information to the workspace
            app.writeData();
            
            % Update the display with the result
            app.updateResult();
            
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            
            % Write the latest information to the workspace
            app.writeData();
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalibrationSlopedMichelsonUIFigure = uifigure;
            app.CalibrationSlopedMichelsonUIFigure.Name = 'Calibration: Sloped Michelson';
            app.CameraUIAxes = uiaxes(app.CalibrationSlopedMichelsonUIFigure);
            app.CameraUIAxes.XTick = [];
            app.CameraUIAxes.XTickLabel = {'[ ]'};
            app.CameraUIAxes.YTick = [];
            app.PhaseUIAxes = uiaxes(app.CalibrationSlopedMichelsonUIFigure);
            title(app.PhaseUIAxes, 'Phase-Index')
            xlabel(app.PhaseUIAxes, 'Linear Index')
            ylabel(app.PhaseUIAxes, 'Measured Phase')
            app.Panel = uipanel(app.CalibrationSlopedMichelsonUIFigure);
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.SLMDropDownLabel = uilabel(app.Panel);
            app.SLMDropDownLabel.Text = 'SLM';
            app.SLMDropDown = uidropdown(app.Panel);
            app.SLMDropDown.Items = {};
            app.SLMDropDown.Editable = 'on';
            app.SLMDropDown.Value = {};
            app.CameraDropDownLabel = uilabel(app.Panel);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.Panel);
            app.CameraDropDown.Items = {};
            app.CameraDropDown.Editable = 'on';
            app.CameraDropDown.Value = {};
            app.SliceAngleSpinnerLabel = uilabel(app.Panel);
            app.SliceAngleSpinnerLabel.Text = 'Slice Angle';
            app.SliceAngleSpinner = uispinner(app.Panel);
            app.SliceAngleSpinner.Step = 5;
            app.SliceAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @sliceValueChanged, true);
            app.Slice1SpinnerLabel = uilabel(app.Panel);
            app.Slice1SpinnerLabel.Text = 'Slice 1';
            app.Slice1Spinner = uispinner(app.Panel);
            app.Slice1Spinner.ValueChangedFcn = createCallbackFcn(app, @sliceValueChanged, true);
            app.Slice2SpinnerLabel = uilabel(app.Panel);
            app.Slice2SpinnerLabel.Text = 'Slice 2';
            app.Slice2Spinner = uispinner(app.Panel);
            app.Slice2Spinner.ValueChangedFcn = createCallbackFcn(app, @sliceValueChanged, true);
            app.DelaySpinnerLabel = uilabel(app.Panel);
            app.DelaySpinnerLabel.Text = 'Delay';
            app.DelaySpinner = uispinner(app.Panel);
            app.DelaySpinner.Step = 0.1;
            app.DelaySpinner.Limits = [0 Inf];
            app.StrideSpinnerLabel = uilabel(app.Panel);
            app.StrideSpinnerLabel.Text = 'Stride';
            app.StrideSpinner = uispinner(app.Panel);
            app.StrideSpinner.Limits = [1 Inf];
            app.StrideSpinner.RoundFractionalValues = 'on';
            app.StrideSpinner.ValueDisplayFormat = '%.0f';
            app.StrideSpinner.Value = 1;
            app.Slice2WidthSpinner = uispinner(app.Panel);
            app.Slice2WidthSpinner.Limits = [1 Inf];
            app.Slice2WidthSpinner.RoundFractionalValues = 'on';
            app.Slice2WidthSpinner.ValueDisplayFormat = '%.0f';
            app.Slice2WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @sliceValueChanged, true);
            app.Slice2WidthSpinner.Value = 1;
            app.Slice1WidthSpinner = uispinner(app.Panel);
            app.Slice1WidthSpinner.Limits = [1 Inf];
            app.Slice1WidthSpinner.RoundFractionalValues = 'on';
            app.Slice1WidthSpinner.ValueDisplayFormat = '%.0f';
            app.Slice1WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @sliceValueChanged, true);
            app.Slice1WidthSpinner.Value = 1;
            app.RandomTestButton = uibutton(app.Panel, 'push');
            app.RandomTestButton.ButtonPushedFcn = createCallbackFcn(app, @RandomTestButtonPushed, true);
            app.RandomTestButton.Text = 'Random Test';
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FrequencySpinnerLabel = uilabel(app.Panel);
            app.FrequencySpinnerLabel.Text = 'Frequency';
            app.FrequencySpinner = uispinner(app.Panel);
            app.FrequencySpinner.Limits = [0 Inf];
            app.FrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @frequencyValueChanged, true);
            app.StepAngleSpinnerLabel = uilabel(app.Panel);
            app.StepAngleSpinnerLabel.Text = 'Step Angle';
            app.StepAngleSpinner = uispinner(app.Panel);
            app.StepAngleSpinner.Step = 5;
            app.StepAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @sliceValueChanged, true);
            app.PsdUIAxes = uiaxes(app.CalibrationSlopedMichelsonUIFigure);
            title(app.PsdUIAxes, 'Power Spectrum')
            xlabel(app.PsdUIAxes, 'Frequency')
            ylabel(app.PsdUIAxes, 'Power')
        end
    end

    methods (Access = public)

        % Construct app
        function app = CalibrationSMichelson

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalibrationSlopedMichelsonUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibrationSlopedMichelsonUIFigure)
        end
    end
end