classdef ImageScan1d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageDeviceScan1DUIFigure  matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        SLMDropDownLabel           matlab.ui.control.Label
        SLMDropDown                matlab.ui.control.DropDown
        CameraDropDownLabel        matlab.ui.control.Label
        CameraDropDown             matlab.ui.control.DropDown
        OutputEditFieldLabel       matlab.ui.control.Label
        OutputEditField            matlab.ui.control.EditField
        WidthSpinnerLabel          matlab.ui.control.Label
        WidthSpinner               matlab.ui.control.Spinner
        StrideSpinnerLabel         matlab.ui.control.Label
        StrideSpinner              matlab.ui.control.Spinner
        PaddingSpinnerLabel        matlab.ui.control.Label
        PaddingSpinner             matlab.ui.control.Spinner
        AngledegSpinnerLabel       matlab.ui.control.Label
        AngledegSpinner            matlab.ui.control.Spinner
        DelaySpinnerLabel          matlab.ui.control.Label
        DelaySpinner               matlab.ui.control.Spinner
        RunButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        data % data from the last run
    end

    methods (Access = private)
    
        function enableUiComponents(app, state)
            
            % Handle default argument value
            if nargin == 1
                state = 'on';
            end
            
            app.SLMDropDown.Enable = state;
            app.CameraDropDown.Enable = state;
            app.OutputEditField.Enable = state;
            app.WidthSpinner.Enable = state;
            app.StrideSpinner.Enable = state;
            app.PaddingSpinner.Enable = state;
            app.AngledegSpinner.Enable = state;
            app.DelaySpinner.Enable = state;
            
        end
    
        function updateGraph(app)
            
            plot(app.UIAxes, 1:length(app.data), app.data);
            xlabel(app.UIAxes, 'Step');
            ylabel(app.UIAxes, 'Value');
            title(app.UIAxes, []);
            ax = axis(app.UIAxes);
            axis(app.UIAxes, [ax(1:2), 0, ax(4)]);
            
        end
    
        function writeData(app)
            % Check we have data to write
            if isempty(app.data) || isempty(app.OutputEditField.Value)
                return;
            end
            
            % Check we have a valid variable name
            if isvarname(app.OutputEditField.Value)
                assignin('base', app.OutputEditField.Value, app.data);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            varnames = evalin('base', 'who');
            
            % Populate the drop down menus
            cameras = {};
            slms = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'otslm.utils.Showable')
                    slms{end+1} = varnames{ii};
                end
                if isa(evalin('base', varnames{ii}), 'otslm.utils.Viewable')
                    cameras{end+1} = varnames{ii};
                end
            end
            app.SLMDropDown.Items = slms;
            app.SLMDropDown.ItemsData = slms;
            app.SLMDropDown.Value = '';
            app.CameraDropDown.Items = cameras;
            app.CameraDropDown.ItemsData = cameras;
            app.CameraDropDown.Value = '';
            
            % Initialize the data and plot
            app.data = [];
            app.updateGraph();
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % Disable UI Components
            app.enableUiComponents('off');
            
            try
                % Get values from GUI
                slm = evalin('base', app.SLMDropDown.Value);
                cam = evalin('base', app.CameraDropDown.Value);
                width = app.WidthSpinner.Value;
                delay = app.DelaySpinner.Value;
                stride = app.StrideSpinner.Value;
                angle_deg = app.AngledegSpinner.Value;
                padding = app.PaddingSpinner.Value;
                
                % Do the work
                app.data = otslm.utils.imaging.scan1d(slm, cam, 'width', width, ...
                    'delay', delay, 'stride', stride, ...
                    'angle_deg', angle_deg, 'padding', padding, ...
                    'verbose', true);
            catch ME
                % Enable UI Components
                app.enableUiComponents('on');
                
                % Continue with the error
                rethrow(ME);
            end
            
            % Update the graph with the latest data
            app.updateGraph();
            
            % Write the data to the workspace
            app.writeData();
            
            % Enable UI Components
            app.enableUiComponents('on');
            
        end

        % Value changed function: OutputEditField
        function OutputEditFieldValueChanged(app, event)
            app.writeData();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageDeviceScan1DUIFigure = uifigure;
            app.ImageDeviceScan1DUIFigure.Name = 'Image Device: Scan 1D';
            app.UIAxes = uiaxes(app.ImageDeviceScan1DUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel = uipanel(app.ImageDeviceScan1DUIFigure);
            app.SLMDropDownLabel = uilabel(app.Panel);
            app.SLMDropDownLabel.Text = 'SLM';
            app.SLMDropDown = uidropdown(app.Panel);
            app.SLMDropDown.Items = {};
            app.SLMDropDown.Editable = 'on';
            app.SLMDropDown.Value = {};
            app.CameraDropDownLabel = uilabel(app.Panel);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.Panel);
            app.CameraDropDown.Items = {};
            app.CameraDropDown.Editable = 'on';
            app.CameraDropDown.Value = {};
            app.OutputEditFieldLabel = uilabel(app.Panel);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.Panel, 'text');
            app.OutputEditField.ValueChangedFcn = createCallbackFcn(app, @OutputEditFieldValueChanged, true);
            app.WidthSpinnerLabel = uilabel(app.Panel);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.Panel);
            app.WidthSpinner.Limits = [1 Inf];
            app.WidthSpinner.RoundFractionalValues = 'on';
            app.WidthSpinner.ValueDisplayFormat = '%.0f';
            app.WidthSpinner.Value = 16;
            app.StrideSpinnerLabel = uilabel(app.Panel);
            app.StrideSpinnerLabel.Text = 'Stride';
            app.StrideSpinner = uispinner(app.Panel);
            app.StrideSpinner.Limits = [1 Inf];
            app.StrideSpinner.RoundFractionalValues = 'on';
            app.StrideSpinner.ValueDisplayFormat = '%.0f';
            app.StrideSpinner.Value = 16;
            app.PaddingSpinnerLabel = uilabel(app.Panel);
            app.PaddingSpinnerLabel.Text = 'Padding';
            app.PaddingSpinner = uispinner(app.Panel);
            app.PaddingSpinner.RoundFractionalValues = 'on';
            app.PaddingSpinner.ValueDisplayFormat = '%.0f';
            app.AngledegSpinnerLabel = uilabel(app.Panel);
            app.AngledegSpinnerLabel.Text = 'Angle (deg)';
            app.AngledegSpinner = uispinner(app.Panel);
            app.AngledegSpinner.Step = 5;
            app.DelaySpinnerLabel = uilabel(app.Panel);
            app.DelaySpinnerLabel.Text = 'Delay';
            app.DelaySpinner = uispinner(app.Panel);
            app.DelaySpinner.Step = 0.1;
            app.DelaySpinner.Limits = [0 Inf];
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageScan1d

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageDeviceScan1DUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageDeviceScan1DUIFigure)
        end
    end
end