classdef ImageScan2d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageDeviceScan1DUIFigure  matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        SLMDropDownLabel           matlab.ui.control.Label
        SLMDropDown                matlab.ui.control.DropDown
        CameraDropDownLabel        matlab.ui.control.Label
        CameraDropDown             matlab.ui.control.DropDown
        OutputEditFieldLabel       matlab.ui.control.Label
        OutputEditField            matlab.ui.control.EditField
        RegionSizeSpinnerLabel     matlab.ui.control.Label
        RegionSizeXSpinner         matlab.ui.control.Spinner
        AngledegSpinnerLabel       matlab.ui.control.Label
        AngledegSpinner            matlab.ui.control.Spinner
        DelaySpinnerLabel          matlab.ui.control.Label
        DelaySpinner               matlab.ui.control.Spinner
        RunButton                  matlab.ui.control.Button
        RegionSizeYSpinner         matlab.ui.control.Spinner
        StrideSpinnerLabel         matlab.ui.control.Label
        StrideXSpinner             matlab.ui.control.Spinner
        StrideYSpinner             matlab.ui.control.Spinner
        PaddingSpinnerLabel        matlab.ui.control.Label
        PaddingXSpinner            matlab.ui.control.Spinner
        PaddingYSpinner            matlab.ui.control.Spinner
    end


    properties (Access = private)
        data % data from the last run
    end

    methods (Access = private)
    
        function enableUiComponents(app, state)
            
            % Handle default argument value
            if nargin == 1
                state = 'on';
            end
            
            app.SLMDropDown.Enable = state;
            app.CameraDropDown.Enable = state;
            app.OutputEditField.Enable = state;
            app.RegionSizeXSpinner.Enable = state;
            app.RegionSizeYSpinner.Enable = state;
            app.StrideXSpinner.Enable = state;
            app.StrideYSpinner.Enable = state;
            app.PaddingXSpinner.Enable = state;
            app.PaddingYSpinner.Enable = state;
            app.AngledegSpinner.Enable = state;
            app.DelaySpinner.Enable = state;
            
        end
    
        function updateGraph(app)
            
            imagesc(app.UIAxes, app.data);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
%             xlabel(app.UIAxes, 'Grid Axis 1');
%             ylabel(app.UIAxes, 'Grid Axis 2');
            colorbar(app.UIAxes);
            caxis(app.UIAxes, [0, max(app.data(:))]);
            title(app.UIAxes, []);
            axis(app.UIAxes, 'image');
            
        end
    
        function writeData(app)
            % Check we have data to write
            if isempty(app.data) || isempty(app.OutputEditField.Value)
                return;
            end
            
            % Check we have a valid variable name
            if isvarname(app.OutputEditField.Value)
                assignin('base', app.OutputEditField.Value, app.data);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            varnames = evalin('base', 'who');
            
            % Populate the drop down menus
            cameras = {};
            slms = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'otslm.utils.Showable')
                    slms{end+1} = varnames{ii};
                end
                if isa(evalin('base', varnames{ii}), 'otslm.utils.Viewable')
                    cameras{end+1} = varnames{ii};
                end
            end
            app.SLMDropDown.Items = slms;
            app.SLMDropDown.ItemsData = slms;
            app.SLMDropDown.Value = '';
            app.CameraDropDown.Items = cameras;
            app.CameraDropDown.ItemsData = cameras;
            app.CameraDropDown.Value = '';
            
            % Initialize the data and plot
            app.data = [];
            app.updateGraph();
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % Disable UI Components
            app.enableUiComponents('off');
            
            try
                % Get values from GUI
                slm = evalin('base', app.SLMDropDown.Value);
                cam = evalin('base', app.CameraDropDown.Value);
                width = [app.RegionSizeXSpinner.Value, app.RegionSizeYSpinner.Value];
                delay = app.DelaySpinner.Value;
                stride = [app.StrideXSpinner.Value, app.StrideYSpinner.Value];
                angle_deg = app.AngledegSpinner.Value;
                padding = [app.PaddingXSpinner.Value, app.PaddingYSpinner.Value];
                
                % Do the work
                app.data = otslm.utils.imaging.scan2d(slm, cam, 'width', width, ...
                    'delay', delay, 'stride', stride, ...
                    'angle_deg', angle_deg, 'padding', padding, ...
                    'verbose', true);
            catch ME
                % Enable UI Components
                app.enableUiComponents('on');
                
                % Continue with the error
                rethrow(ME);
            end
            
            % Update the graph with the latest data
            app.updateGraph();
            
            % Write the data to the workspace
            app.writeData();
            
            % Enable UI Components
            app.enableUiComponents('on');
            
        end

        % Value changed function: OutputEditField
        function OutputEditFieldValueChanged(app, event)
            app.writeData();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageDeviceScan1DUIFigure = uifigure;
            app.ImageDeviceScan1DUIFigure.Name = 'Image Device: Scan 1D';
            app.UIAxes = uiaxes(app.ImageDeviceScan1DUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel = uipanel(app.ImageDeviceScan1DUIFigure);
            app.SLMDropDownLabel = uilabel(app.Panel);
            app.SLMDropDownLabel.Text = 'SLM';
            app.SLMDropDown = uidropdown(app.Panel);
            app.SLMDropDown.Items = {};
            app.SLMDropDown.Editable = 'on';
            app.SLMDropDown.Value = {};
            app.CameraDropDownLabel = uilabel(app.Panel);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.Panel);
            app.CameraDropDown.Items = {};
            app.CameraDropDown.Editable = 'on';
            app.CameraDropDown.Value = {};
            app.OutputEditFieldLabel = uilabel(app.Panel);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.Panel, 'text');
            app.OutputEditField.ValueChangedFcn = createCallbackFcn(app, @OutputEditFieldValueChanged, true);
            app.RegionSizeSpinnerLabel = uilabel(app.Panel);
            app.RegionSizeSpinnerLabel.Text = 'Region Size';
            app.RegionSizeXSpinner = uispinner(app.Panel);
            app.RegionSizeXSpinner.Limits = [1 Inf];
            app.RegionSizeXSpinner.RoundFractionalValues = 'on';
            app.RegionSizeXSpinner.ValueDisplayFormat = '%.0f';
            app.RegionSizeXSpinner.Value = 32;
            app.AngledegSpinnerLabel = uilabel(app.Panel);
            app.AngledegSpinnerLabel.Text = 'Angle (deg)';
            app.AngledegSpinner = uispinner(app.Panel);
            app.AngledegSpinner.Step = 5;
            app.DelaySpinnerLabel = uilabel(app.Panel);
            app.DelaySpinnerLabel.Text = 'Delay';
            app.DelaySpinner = uispinner(app.Panel);
            app.DelaySpinner.Step = 0.1;
            app.DelaySpinner.Limits = [0 Inf];
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RegionSizeYSpinner = uispinner(app.Panel);
            app.RegionSizeYSpinner.Limits = [1 Inf];
            app.RegionSizeYSpinner.RoundFractionalValues = 'on';
            app.RegionSizeYSpinner.ValueDisplayFormat = '%.0f';
            app.RegionSizeYSpinner.Value = 32;
            app.StrideSpinnerLabel = uilabel(app.Panel);
            app.StrideSpinnerLabel.Text = 'Stride';
            app.StrideXSpinner = uispinner(app.Panel);
            app.StrideXSpinner.Limits = [1 Inf];
            app.StrideXSpinner.RoundFractionalValues = 'on';
            app.StrideXSpinner.ValueDisplayFormat = '%.0f';
            app.StrideXSpinner.Value = 32;
            app.StrideYSpinner = uispinner(app.Panel);
            app.StrideYSpinner.Limits = [1 Inf];
            app.StrideYSpinner.RoundFractionalValues = 'on';
            app.StrideYSpinner.ValueDisplayFormat = '%.0f';
            app.StrideYSpinner.Value = 32;
            app.PaddingSpinnerLabel = uilabel(app.Panel);
            app.PaddingSpinnerLabel.Text = 'Padding';
            app.PaddingXSpinner = uispinner(app.Panel);
            app.PaddingXSpinner.RoundFractionalValues = 'on';
            app.PaddingXSpinner.ValueDisplayFormat = '%.0f';
            app.PaddingYSpinner = uispinner(app.Panel);
            app.PaddingYSpinner.RoundFractionalValues = 'on';
            app.PaddingYSpinner.ValueDisplayFormat = '%.0f';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageScan2d

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageDeviceScan1DUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageDeviceScan1DUIFigure)
        end
    end
end