classdef LoadColormap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadColormapUIFigure        matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        Panel                       matlab.ui.container.Panel
        NameEditFieldLabel          matlab.ui.control.Label
        NameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel      matlab.ui.control.Label
        FilenameEditField           matlab.ui.control.EditField
        BrowseButton                matlab.ui.control.Button
        HeaderLinesSpinnerLabel     matlab.ui.control.Label
        HeaderLinesSpinner          matlab.ui.control.Spinner
        InputFormatDropDownLabel    matlab.ui.control.Label
        InputFormatDropDown         matlab.ui.control.DropDown
        OutputFormatDropDownLabel   matlab.ui.control.Label
        OutputFormatDropDown        matlab.ui.control.DropDown
        ValueColumnsEditFieldLabel  matlab.ui.control.Label
        ValueColumnsEditField       matlab.ui.control.EditField
        PhaseColumnEditFieldLabel   matlab.ui.control.Label
        PhaseColumnEditField        matlab.ui.control.EditField
        BitOrderDropDownLabel       matlab.ui.control.Label
        BitOrderDropDown            matlab.ui.control.DropDown
        ColumnMasksEditFieldLabel   matlab.ui.control.Label
        ColumnMasksEditField        matlab.ui.control.EditField
        DeliminatorDropDownLabel    matlab.ui.control.Label
        DeliminatorDropDown         matlab.ui.control.DropDown
        PreviewDropDownLabel        matlab.ui.control.Label
        PreviewDropDown             matlab.ui.control.DropDown
        UITable                     matlab.ui.control.Table
        ReloadButton                matlab.ui.control.Button
    end


    properties (Access = public)
        LookupTable      % The current lookup table or empty
    end

    methods (Access = private)
        
        function saveLookupTable(app)
            % Save the lookup table to the base workspace
            
            % If no name or no lookup table, nothing to do
            if isempty(app.NameEditField.Value) || isempty(app.LookupTable)
                return;
            end
            
            % Check variable name is valid
            if ~isvarname(app.NameEditField.Value)
                error('Name must be valid variable name');
            end
            
            % Write colormap to base workspace
            assignin('base', app.NameEditField.Value, app.LookupTable);
            
        end
    
        function updateDataDisplays(app)
            % Update the table and graph on the GUI
            
            % Clear the current graph and table
            cla(app.UIAxes, 'reset');
            app.UITable.Data = {};
            app.UITable.ColumnName = {};
            
            % If no lookup table, nothing more to do
            if isempty(app.LookupTable)
                return;
            end
            
            % Update the graph
            switch app.PreviewDropDown.Value
                case 'linear'
                    pattern = otslm.simple.linear([128, 128], 128).*2*pi;
                    pattern = otslm.tools.finalize(pattern, 'colormap', app.LookupTable);
                    image(app.UIAxes, pattern);
                    axis(app.UIAxes, 'image');
                    xlabel(app.UIAxes, []);
                    ylabel(app.UIAxes, []);
                case 'channel'
                    plot(app.UIAxes, app.LookupTable.phase, app.LookupTable.value);
                    xlabel(app.UIAxes, 'Phase');
                    ylabel(app.UIAxes, 'Values');
                    legarray = cell(1, size(app.LookupTable.value, 2));
                    for ii = 1:length(legarray)
                        legarray{ii} = ['Channel ' num2str(ii)];
                    end
                    legend(app.UIAxes, legarray);
                case 'indexed'
                    plot(app.UIAxes, 1:length(app.LookupTable.phase), app.LookupTable.phase);
                    xlabel(app.UIAxes, 'Index');
                    ylabel(app.UIAxes, 'Phase');
                otherwise
                    error('Invalid drop down value selected');
            end
            
            % Update the table
            colnames = {'Phase'};
            coldatacells = cell(1, size(app.LookupTable.value, 2));
            for ii = 1:size(app.LookupTable.value, 2)
                colnames{ii+1} = ['Channel ' num2str(ii)];
                coldatacells{ii} = app.LookupTable.value(:, ii);
            end
            app.UITable.ColumnName = colnames;
            app.UITable.Data = table(app.LookupTable.phase, coldatacells{:});
        end
        
    end


    methods (Access = private)

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            % Save the lookup table to the base workspace
            app.saveLookupTable();
        end

        % Callback function: BitOrderDropDown, ColumnMasksEditField, 
        % DeliminatorDropDown, FilenameEditField, HeaderLinesSpinner, 
        % InputFormatDropDown, OutputFormatDropDown, 
        % PhaseColumnEditField, ReloadButton, ValueColumnsEditField
        function inputValueChanged(app, event)
            
            % Clear the LookupTable
            app.LookupTable = [];
            
            filename = app.FilenameEditField.Value;
            channels = evalin('base', ['[', app.ValueColumnsEditField.Value, ']']);
            phase = evalin('base', ['[', app.PhaseColumnEditField.Value, ']']);
            oformat = app.OutputFormatDropDown.Value;
            delim = app.DeliminatorDropDown.Value;
            nheaderlines = app.HeaderLinesSpinner.Value;
            
            masks = app.ColumnMasksEditField.Value;
            if isempty(masks)
                masks = [];
            else
                masks = cellfun(@hex2dec, strsplit(masks, ','));
            end
            
            switch app.InputFormatDropDown.Value
                case 'uint8'
                    format = @uint8;
                case 'uint16'
                    format = @uint16;
                case 'uint32'
                    format = @uint32;
                otherwise
                    error('Invalid input format type');
            end
            
            switch app.BitOrderDropDown.Value
                case 'normal'
                    morder = 1:8;
                case 'reverse'
                    morder = 8:-1:1;
                otherwise
                    error('Invalid bit order value');
            end

            % Check that there is a file to read
            if isempty(filename)
                return;
            end
            
            % Read the file and update app.LookupTable
            app.LookupTable = otslm.utils.LookupTable.load(filename, ...
                'channels', channels, 'phase', phase, 'format', format, ...
                'mask', masks, 'oformat', oformat, ...
                'delim', delim, 'nheaderlines', nheaderlines, 'morder', morder);
            
            % Update the graph and value table
            app.updateDataDisplays();
            
            % Save the lookup table to the base workspace
            app.saveLookupTable();
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Display a file chooser dialog
            [file, path] = uigetfile('*.*','Select a File');
            
            % Check if the user selected a file
            if ~isequal(file,0)
                
                % Update the filename field and process the event
                app.FilenameEditField.Value = fullfile(path, file);
                app.inputValueChanged(event);
            end
        end

        % Value changed function: PreviewDropDown
        function PreviewDropDownValueChanged(app, event)
            
            % Change the content of the graph
            app.updateDataDisplays();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadColormapUIFigure = uifigure;
            app.LoadColormapUIFigure.Name = 'Load Colormap';
            app.UIAxes = uiaxes(app.LoadColormapUIFigure);
            app.Panel = uipanel(app.LoadColormapUIFigure);
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.FilenameEditFieldLabel = uilabel(app.Panel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.Panel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.BrowseButton = uibutton(app.Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.HeaderLinesSpinnerLabel = uilabel(app.Panel);
            app.HeaderLinesSpinnerLabel.Text = 'Header Lines';
            app.HeaderLinesSpinner = uispinner(app.Panel);
            app.HeaderLinesSpinner.Limits = [0 Inf];
            app.HeaderLinesSpinner.RoundFractionalValues = 'on';
            app.HeaderLinesSpinner.ValueDisplayFormat = '%.0f';
            app.HeaderLinesSpinner.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.InputFormatDropDownLabel = uilabel(app.Panel);
            app.InputFormatDropDownLabel.Text = 'Input Format';
            app.InputFormatDropDown = uidropdown(app.Panel);
            app.InputFormatDropDown.Items = {'uint8', 'uint16', 'uint32'};
            app.InputFormatDropDown.ItemsData = {'uint8', 'uint16', 'uint32'};
            app.InputFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.InputFormatDropDown.Value = 'uint8';
            app.OutputFormatDropDownLabel = uilabel(app.Panel);
            app.OutputFormatDropDownLabel.Text = 'Output Format';
            app.OutputFormatDropDown = uidropdown(app.Panel);
            app.OutputFormatDropDown.Items = {'uint8', 'uint16', 'uint32', 'double', 'single'};
            app.OutputFormatDropDown.ItemsData = {'uint8', 'uint16', 'uint32', 'double', 'single'};
            app.OutputFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.OutputFormatDropDown.Value = 'uint8';
            app.ValueColumnsEditFieldLabel = uilabel(app.Panel);
            app.ValueColumnsEditFieldLabel.Text = 'Value Columns';
            app.ValueColumnsEditField = uieditfield(app.Panel, 'text');
            app.ValueColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.ValueColumnsEditField.Value = '2, 0, 0';
            app.PhaseColumnEditFieldLabel = uilabel(app.Panel);
            app.PhaseColumnEditFieldLabel.Text = 'Phase Column';
            app.PhaseColumnEditField = uieditfield(app.Panel, 'text');
            app.PhaseColumnEditField.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.BitOrderDropDownLabel = uilabel(app.Panel);
            app.BitOrderDropDownLabel.Text = 'Bit Order';
            app.BitOrderDropDown = uidropdown(app.Panel);
            app.BitOrderDropDown.Items = {'Normal', 'Reverse'};
            app.BitOrderDropDown.ItemsData = {'normal', 'reverse'};
            app.BitOrderDropDown.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.BitOrderDropDown.Value = 'normal';
            app.ColumnMasksEditFieldLabel = uilabel(app.Panel);
            app.ColumnMasksEditFieldLabel.Text = 'Column Masks';
            app.ColumnMasksEditField = uieditfield(app.Panel, 'text');
            app.ColumnMasksEditField.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.DeliminatorDropDownLabel = uilabel(app.Panel);
            app.DeliminatorDropDownLabel.Text = 'Deliminator';
            app.DeliminatorDropDown = uidropdown(app.Panel);
            app.DeliminatorDropDown.Items = {'Tab', 'Comma'};
            app.DeliminatorDropDown.ItemsData = {'\t', '', ''};
            app.DeliminatorDropDown.Editable = 'on';
            app.DeliminatorDropDown.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.DeliminatorDropDown.Value = '\t';
            app.PreviewDropDownLabel = uilabel(app.LoadColormapUIFigure);
            app.PreviewDropDownLabel.Text = 'Preview';
            app.PreviewDropDown = uidropdown(app.LoadColormapUIFigure);
            app.PreviewDropDown.Items = {'Linear Ramp', 'Channel Values', 'Phase Index'};
            app.PreviewDropDown.ItemsData = {'linear', 'channel', 'indexed'};
            app.PreviewDropDown.ValueChangedFcn = createCallbackFcn(app, @PreviewDropDownValueChanged, true);
            app.PreviewDropDown.Value = 'linear';
            app.UITable = uitable(app.LoadColormapUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.ReloadButton = uibutton(app.LoadColormapUIFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.ReloadButton.Text = 'Re-load';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LoadColormap

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoadColormapUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadColormapUIFigure)
        end
    end
end