classdef ScreenDevice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetupScreenDeviceUIFigure  matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        NameEditFieldLabel         matlab.ui.control.Label
        NameEditField              matlab.ui.control.EditField
        LookupTableDropDownLabel   matlab.ui.control.Label
        LookupTableDropDown        matlab.ui.control.DropDown
        SizeSpinnerLabel           matlab.ui.control.Label
        WidthSpinner               matlab.ui.control.Spinner
        HeightSpinner              matlab.ui.control.Spinner
        PositionSpinnerLabel       matlab.ui.control.Label
        PosXSpinner                matlab.ui.control.Spinner
        PosYSpinner                matlab.ui.control.Spinner
        ScreenSpinnerLabel         matlab.ui.control.Label
        ScreenSpinner              matlab.ui.control.Spinner
        UITable                    matlab.ui.control.Table
        ScreenPropertiesLabel      matlab.ui.control.Label
        ColorspaceDropDownLabel    matlab.ui.control.Label
        ColorspaceDropDown         matlab.ui.control.DropDown
        TypeDropDownLabel          matlab.ui.control.Label
        TypeDropDown               matlab.ui.control.DropDown
        ShowButton                 matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        ShowPreviewCheckBox        matlab.ui.control.CheckBox
    end


    properties (Access = public)
        device      otslm.utils.ScreenDevice % ScreenDevice we have created
        watchTimer  % Timer to update the preview
    end

    methods (Access = public)
    
        function saveDevice(app)
            % Save the device to the base workspace
            
            % Check if there is anyghing to do
            if isempty(app.device) || isempty(app.NameEditField.Value)
                return;
            end
            
            % Check variable name is valid
            if ~isvarname(app.NameEditField.Value)
                error('Name must be valid variable name');
            end
            
            % Write colormap to base workspace
            assignin('base', app.NameEditField.Value, app.device);
            
        end
    
        function updatePreview(app, timer, event)
            
            % Clear the current content
            cla(app.UIAxes, 'reset');
            set(app.UIAxes, 'XTick', []);
            set(app.UIAxes, 'YTick', []);
            
            % Check we have a device to watch
            if isempty(app.device) || ~isvalid(app.device)
                return;
            end
            
            % Check we have a screen to display
            if isempty(app.device.figure_handle) || ~ishandle(app.device.figure_handle)
                return;
            end
            
            % Get the image and display it
            im = getimage(app.device.figure_handle);
            image(app.UIAxes, im);
            axis(app.UIAxes, 'image');
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Get screen size
            set(0,'units','pixels');
            screenSize = get(0,'screensize');
            
            % Populate the table with screen properties
            app.UITable.Data = table(screenSize(:, 3), screenSize(:, 4), ...
                screenSize(:, 1), screenSize(:, 2));
            app.UITable.RowName = num2cell(1:size(screenSize, 1));
            app.UITable.ColumnWidth = {'auto', 50, 50, 50}; %num2cell([1, 1, 1, 1].*50);
            
            % Set limits on screen selector
            if size(screenSize, 1) == 1
                app.ScreenSpinner.Enable = 'off';
            else
                app.ScreenSpinner.Limits = [1, size(screenSize, 1)];
            end
            
            % Populate lookup table list
            varnames = evalin('base', 'who');
            lookupTables = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'otslm.utils.LookupTable')
                    lookupTables{end+1} = varnames{ii};
                end
            end
            app.LookupTableDropDown.Items = lookupTables;
            app.LookupTableDropDown.ItemsData = lookupTables;
            app.LookupTableDropDown.Value = '';
            
            % Setup the initial device
            app.inputValueChanged([]);
            
            % Setup a timer to update the preview
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.updatePreview;

        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.saveDevice();
        end

        % Value changed function: ColorspaceDropDown, HeightSpinner, 
        % LookupTableDropDown, PosXSpinner, PosYSpinner, ScreenSpinner, 
        % WidthSpinner
        function inputValueChanged(app, event)
            
            % Clear the old device
            delete(app.device);
            app.device = otslm.utils.ScreenDevice.empty();
            
            % Get properity values from interface
            width = app.WidthSpinner.Value;
            height = app.HeightSpinner.Value;
            xpos = app.PosXSpinner.Value;
            ypos = app.PosYSpinner.Value;
            id = app.ScreenSpinner.Value;
            lookuptableStr = app.LookupTableDropDown.Value;
            typeStr = app.ColorspaceDropDown.Value;
            type = app.TypeDropDown.Value;
            
            % Get lookup table
            if ~isempty(lookuptableStr)
                lookuptable = evalin('base', lookuptableStr);
            else
                lookuptable = [];
            end
            
            % Get colorspace data
            switch typeStr
                case 'binary'
                    colorspace = { [0, 255] };
                case 'gray'
                    colorspace = { 0:255 };
                case 'color'
                    colorspace = { 0:255, 0:255, 0:255 };
                case 'redgreen'
                    colorspace = { 0:255, 0:255, 0 };
                case 'redblue'
                    colorspace = { 0:255, 0, 0:255 };
                case 'greenblue'
                    colorspace = { 0, 0:255, 0:255 };
                otherwise
                    error('Unknown colorspace option');
            end
            
            % Create a new device
            app.device = otslm.utils.ScreenDevice(id, 'target_size', [height, width], ...
                'target_offset', [xpos, ypos], 'lookup_table', lookuptable, ...
                'value_range', colorspace, 'pattern_type', type);
            
            % Save the device back to the workspace
            app.saveDevice();
            
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            
            % Show the device window
            if ~isempty(app.device)
                app.device.show();
            end
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            % Hide the device window
            if ~isempty(app.device)
                app.device.close();
            end
            
        end

        % Value changed function: ShowPreviewCheckBox
        function ShowPreviewCheckBoxValueChanged(app, event)
            
            if app.ShowPreviewCheckBox.Value
                start(app.watchTimer);
            else
                stop(app.watchTimer);
                cla(app.UIAxes, 'reset');
                set(app.UIAxes, 'XTick', []);
                set(app.UIAxes, 'YTick', []);
            end
        end

        % Close request function: SetupScreenDeviceUIFigure
        function SetupScreenDeviceUIFigureCloseRequest(app, event)
            stop(app.watchTimer);
            delete(app.watchTimer);
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetupScreenDeviceUIFigure = uifigure;
            app.SetupScreenDeviceUIFigure.Name = 'Setup Screen Device';
            app.SetupScreenDeviceUIFigure.CloseRequestFcn = createCallbackFcn(app, @SetupScreenDeviceUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.SetupScreenDeviceUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.Panel = uipanel(app.SetupScreenDeviceUIFigure);
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.LookupTableDropDownLabel = uilabel(app.Panel);
            app.LookupTableDropDownLabel.Text = 'Lookup Table';
            app.LookupTableDropDown = uidropdown(app.Panel);
            app.LookupTableDropDown.Items = {};
            app.LookupTableDropDown.Editable = 'on';
            app.LookupTableDropDown.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.LookupTableDropDown.Value = {};
            app.SizeSpinnerLabel = uilabel(app.Panel);
            app.SizeSpinnerLabel.Text = 'Size';
            app.WidthSpinner = uispinner(app.Panel);
            app.WidthSpinner.Limits = [1 Inf];
            app.WidthSpinner.RoundFractionalValues = 'on';
            app.WidthSpinner.ValueDisplayFormat = '%.0f';
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.WidthSpinner.Value = 256;
            app.HeightSpinner = uispinner(app.Panel);
            app.HeightSpinner.Limits = [1 Inf];
            app.HeightSpinner.RoundFractionalValues = 'on';
            app.HeightSpinner.ValueDisplayFormat = '%.0f';
            app.HeightSpinner.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.HeightSpinner.Value = 256;
            app.PositionSpinnerLabel = uilabel(app.Panel);
            app.PositionSpinnerLabel.Text = 'Position';
            app.PosXSpinner = uispinner(app.Panel);
            app.PosXSpinner.RoundFractionalValues = 'on';
            app.PosXSpinner.ValueDisplayFormat = '%.0f';
            app.PosXSpinner.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.PosYSpinner = uispinner(app.Panel);
            app.PosYSpinner.RoundFractionalValues = 'on';
            app.PosYSpinner.ValueDisplayFormat = '%.0f';
            app.PosYSpinner.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.ScreenSpinnerLabel = uilabel(app.Panel);
            app.ScreenSpinnerLabel.Text = 'Screen';
            app.ScreenSpinner = uispinner(app.Panel);
            app.ScreenSpinner.Limits = [1 Inf];
            app.ScreenSpinner.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.ScreenSpinner.Value = 1;
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Width'; 'Height'; 'X'; 'Y'};
            app.UITable.RowName = {};
            app.ScreenPropertiesLabel = uilabel(app.Panel);
            app.ScreenPropertiesLabel.Text = 'Screen Properties';
            app.ColorspaceDropDownLabel = uilabel(app.Panel);
            app.ColorspaceDropDownLabel.Text = 'Colorspace';
            app.ColorspaceDropDown = uidropdown(app.Panel);
            app.ColorspaceDropDown.Items = {'Binary', 'Grayscale', 'Color', 'Red-Green', 'Red-Blue', 'Green-Blue'};
            app.ColorspaceDropDown.ItemsData = {'binary', 'gray', 'color', 'redgreen', 'redblue', 'greenblue'};
            app.ColorspaceDropDown.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.ColorspaceDropDown.Value = 'color';
            app.TypeDropDownLabel = uilabel(app.Panel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.Panel);
            app.TypeDropDown.Items = {'Phase', 'Amplitude', 'Complex'};
            app.TypeDropDown.ItemsData = {'phase', 'amplitude', 'complex'};
            app.TypeDropDown.Value = 'phase';
            app.ShowButton = uibutton(app.SetupScreenDeviceUIFigure, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.CloseButton = uibutton(app.SetupScreenDeviceUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ShowPreviewCheckBox = uicheckbox(app.SetupScreenDeviceUIFigure);
            app.ShowPreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPreviewCheckBoxValueChanged, true);
            app.ShowPreviewCheckBox.Text = 'Show Preview';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ScreenDevice

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SetupScreenDeviceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetupScreenDeviceUIFigure)
        end
    end
end