classdef SetupImaqCamera < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetupCameraUIFigure             matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        DeviceNameEditFieldLabel        matlab.ui.control.Label
        DeviceNameEditField             matlab.ui.control.EditField
        AdaptorDropDownLabel            matlab.ui.control.Label
        AdaptorDropDown                 matlab.ui.control.DropDown
        DeviceIdSpinnerLabel            matlab.ui.control.Label
        DeviceIdSpinner                 matlab.ui.control.Spinner
        UITable                         matlab.ui.control.Table
        DeviceListLabel                 matlab.ui.control.Label
        RegionofInterestEditFieldLabel  matlab.ui.control.Label
        RegionofInterestEditField       matlab.ui.control.EditField
        FormatDropDownLabel             matlab.ui.control.Label
        FormatDropDown                  matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        SnapshotButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        data     % The Viewable object
        watchTimer   % Timer object responsible for updating preview
    end
    
    methods (Access = private)
    
        function setupDeviceIdSpinner(app)
            
            % Get the device ids for the current adaptor
            ids = [];
            tAdaptor = imaqhwinfo(app.AdaptorDropDown.Value);
            for jj = 1:length(tAdaptor.DeviceIDs)
                device = imaqhwinfo(app.AdaptorDropDown.Value, tAdaptor.DeviceIDs{jj});
                ids(end+1) = device.DeviceID;
            end
            
            % Enable/disable spinner
            if length(ids) <= 1
                app.DeviceIdSpinner.Enable = 'off';
                app.DeviceIdSpinner.Value = min(ids);
                app.DeviceIdSpinner.Limits = [min(ids), min(ids)+1];
            else
                app.DeviceIdSpinner.Enable = 'on';
                app.DeviceIdSpinner.Limits = [min(ids), max(ids)];
                app.DeviceIdSpinner.Value = min(ids);
            end
            
            % Continue setting up UI
            app.setupDeviceFormatSpinner();
        end
    
        function setupDeviceFormatSpinner(app)
            
            device_info = imaqhwinfo(app.AdaptorDropDown.Value, app.DeviceIdSpinner.Value);
            app.FormatDropDown.Items = device_info.SupportedFormats;
            app.FormatDropDown.ItemsData = device_info.SupportedFormats;
            app.FormatDropDown.Value = device_info.SupportedFormats{1};
            
        end
    
        function writeData(app)
            % Check we have data to write
            if isempty(app.data) || isempty(app.DeviceNameEditField.Value)
                return;
            end
            
            % Check we have a valid variable name
            if isvarname(app.DeviceNameEditField.Value)
                assignin('base', app.DeviceNameEditField.Value, app.data);
            end
        end
    
        function updatePreview(app, timer, event)
            
            % Check if we have work to do
            if isempty(app.data) || ~isvalid(app.data)
                return;
            end
            
            % Get a snapshot and display it
            im = app.data.viewTarget();
            imagesc(app.UIAxes, im);
            axis(app.UIAxes, 'image');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Populate adaptor list
            hwinfo = imaqhwinfo();
            adaptors = hwinfo.InstalledAdaptors;
            if isempty(adaptors)
                error('No available image acquisition adaptors found');
            end
            app.AdaptorDropDown.Items = adaptors;
            app.AdaptorDropDown.ItemsData = adaptors;
            app.AdaptorDropDown.Value = adaptors{1};
            
            % Setup initial device selector range and value
            app.setupDeviceIdSpinner();
            
            % Populate device list
            device_names = {};
            device_ids = {};
            device_adaptors = {};
            for ii = 1:length(adaptors)
                tAdaptor = imaqhwinfo(adaptors{ii});
                for jj = 1:length(tAdaptor.DeviceIDs)
                    device = imaqhwinfo(adaptors{ii}, tAdaptor.DeviceIDs{jj});
                    device_names{end+1} = device.DeviceName;
                    device_ids{end+1} = device.DeviceID;
                    device_adaptors{end+1} = adaptors{ii};
                end
            end
            app.UITable.Data = table(device_adaptors, device_ids, device_names);
            
            % Setup a timer to update the preview
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.updatePreview;
            
            % Configure the device with the current settings
            app.configValueChanged([]);
            
        end

        % Value changed function: AdaptorDropDown
        function AdaptorDropDownValueChanged(app, event)
                        
            % Setup initial device selector range and value
            app.setupDeviceIdSpinner();
            
            % Continue to handle events
            app.configValueChanged(event);
        end

        % Callback function
        function ShowPreviewCheckBoxValueChanged(app, event)
            
%             % Start or stop the preview update timer
%             if app.ShowPreviewCheckBox.Value
%                 start(app.watchTimer);
%             else
%                 stop(app.watchTimer);
%             end
        end

        % Value changed function: FormatDropDown, 
        % RegionofInterestEditField
        function configValueChanged(app, event)
            
%             stop(app.watchTimer);
%             app.ShowPreviewCheckBox.Value = false;
%             drawnow;
            
            % Delete the old device
            delete(app.data);
            app.data = [];
            
            adaptor = app.AdaptorDropDown.Value;
            id = app.DeviceIdSpinner.Value;
            format = app.FormatDropDown.Value;
            roi = evalin('base', ['[', app.RegionofInterestEditField.Value, ']']);
            
            % Setup the device
            app.data = otslm.utils.ImaqCamera(adaptor, id, 'format', format);
            
            % Apply the region of interest
            if ~isempty(roi)
                app.data.crop(roi);
            end
            
            % Write the new device to workspace
            app.writeData();
        end

        % Value changed function: DeviceNameEditField
        function DeviceNameEditFieldValueChanged(app, event)
            app.writeData();
        end

        % Value changed function: DeviceIdSpinner
        function DeviceIdSpinnerValueChanged(app, event)
            
            % Setup values in device format spinner
            app.setupDeviceFormatSpinner();
            
            % Continue to handle events
            app.configValueChanged(event);
        end

        % Close request function: SetupCameraUIFigure
        function SetupCameraUIFigureCloseRequest(app, event)
            try
                stop(app.watchTimer);
                delete(app.watchTimer);
            catch ME
                warning(ME);
            end
            
            try
                delete(app.data);
            catch ME
                warning(ME);
            end
                
            delete(app)
        end

        % Button pushed function: SnapshotButton
        function SnapshotButtonPushed(app, event)
            app.updatePreview([], []);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetupCameraUIFigure = uifigure;
            app.SetupCameraUIFigure.Name = 'Setup Camera';
            app.SetupCameraUIFigure.CloseRequestFcn = createCallbackFcn(app, @SetupCameraUIFigureCloseRequest, true);
            app.Panel = uipanel(app.SetupCameraUIFigure);
            app.DeviceNameEditFieldLabel = uilabel(app.Panel);
            app.DeviceNameEditFieldLabel.Text = 'Device Name';
            app.DeviceNameEditField = uieditfield(app.Panel, 'text');
            app.DeviceNameEditField.ValueChangedFcn = createCallbackFcn(app, @DeviceNameEditFieldValueChanged, true);
            app.AdaptorDropDownLabel = uilabel(app.Panel);
            app.AdaptorDropDownLabel.Text = 'Adaptor';
            app.AdaptorDropDown = uidropdown(app.Panel);
            app.AdaptorDropDown.Items = {};
            app.AdaptorDropDown.ValueChangedFcn = createCallbackFcn(app, @AdaptorDropDownValueChanged, true);
            app.AdaptorDropDown.Value = {};
            app.DeviceIdSpinnerLabel = uilabel(app.Panel);
            app.DeviceIdSpinnerLabel.Text = 'Device Id';
            app.DeviceIdSpinner = uispinner(app.Panel);
            app.DeviceIdSpinner.Limits = [1 Inf];
            app.DeviceIdSpinner.ValueChangedFcn = createCallbackFcn(app, @DeviceIdSpinnerValueChanged, true);
            app.DeviceIdSpinner.Value = 1;
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Adaptor'; 'ID'; 'Name'};
            app.UITable.RowName = {};
            app.DeviceListLabel = uilabel(app.Panel);
            app.DeviceListLabel.Text = 'Device List';
            app.RegionofInterestEditFieldLabel = uilabel(app.Panel);
            app.RegionofInterestEditFieldLabel.Text = 'Region of Interest';
            app.RegionofInterestEditField = uieditfield(app.Panel, 'text');
            app.RegionofInterestEditField.ValueChangedFcn = createCallbackFcn(app, @configValueChanged, true);
            app.FormatDropDownLabel = uilabel(app.Panel);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.Panel);
            app.FormatDropDown.Items = {};
            app.FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @configValueChanged, true);
            app.FormatDropDown.Value = {};
            app.UIAxes = uiaxes(app.SetupCameraUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.SnapshotButton = uibutton(app.SetupCameraUIFigure, 'push');
            app.SnapshotButton.ButtonPushedFcn = createCallbackFcn(app, @SnapshotButtonPushed, true);
            app.SnapshotButton.Text = 'Snapshot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SetupImaqCamera

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SetupCameraUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetupCameraUIFigure)
        end
    end
end