classdef TestFarfield < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulatedFarfieldImagingUIFigure  matlab.ui.Figure
        SlmUIAxes                 matlab.ui.control.UIAxes
        Panel                     matlab.ui.container.Panel
        SLMNameEditFieldLabel     matlab.ui.control.Label
        SLMNameEditField          matlab.ui.control.EditField
        SizeSpinnerLabel          matlab.ui.control.Label
        WidthSpinner              matlab.ui.control.Spinner
        HeightSpinner             matlab.ui.control.Spinner
        CameraNameEditFieldLabel  matlab.ui.control.Label
        CameraNameEditField       matlab.ui.control.EditField
        ShowPreviewCheckBox       matlab.ui.control.CheckBox
        SLMTypeDropDownLabel      matlab.ui.control.Label
        SLMTypeDropDown           matlab.ui.control.DropDown
        OffsetSpinnerLabel        matlab.ui.control.Label
        OffsetSpinner             matlab.ui.control.Spinner
        NASpinnerLabel            matlab.ui.control.Label
        NASpinner                 matlab.ui.control.Spinner
        CameraUIAxes              matlab.ui.control.UIAxes
    end


    properties (Access = public)
        viewable     otslm.utils.TestFarfield  % Viewable device
        showable     % Showable device
        watchTimer   % Timer for updating the gui
    end

    methods (Access = public)
        
        function saveDevice(app, name, var, tip)
            % Save the device to the base workspace
            
            % Check if there is anyghing to do
            if isempty(var) || isempty(name)
                return;
            end
            
            % Check variable name is valid
            if ~isvarname(name)
                error([tip ' name must be valid variable name']);
            end
            
            % Write colormap to base workspace
            assignin('base', name, var);
        end
    
        function saveDevices(app)
            app.saveDevice(app.SLMNameEditField.Value, app.showable, 'SLM');
            app.saveDevice(app.CameraNameEditField.Value, app.viewable, 'Camera');
        end
    
        function setupDevices(app)
            
            % Delete old slm
            delete(app.showable);
            app.showable = otslm.utils.TestSlm.empty();
            
            % Delete old camera
            delete(app.viewable);
            app.viewable = otslm.utils.TestFarfield.empty();
            
            % Get inputs from UI
            width = app.WidthSpinner.Value;
            height = app.HeightSpinner.Value;
            NA = app.NASpinner.Value;
            offset = app.OffsetSpinner.Value;
            device_type = app.SLMTypeDropDown.Value;
            
            % Construct the devices
            switch device_type
                case 'phase'
                    app.showable = otslm.utils.TestSlm('size', [height, width]);
                case 'binamplitude'
                    app.showable = otslm.utils.TestDmd('size', [height, width]);
                otherwise
                    error('Unknown device type selected');
            end
            app.viewable = otslm.utils.TestFarfield(app.showable, 'NA', NA, 'offset', offset);
            
            % Save the devices to the base workspace
            app.saveDevices();
            
        end
    
        function updatePreview(app, timer, event)
            
            % Check we have a device to watch
            if isempty(app.showable) || ~isvalid(app.showable) ...
                    || isempty(app.viewable) || ~isvalid(app.viewable)
                return;
            end
            
            % Display the SLM pattern
            switch app.SLMTypeDropDown.Value
                case 'phase'
                    imagesc(app.SlmUIAxes, angle(app.showable.pattern));
                case 'binamplitude'
                    imagesc(app.SlmUIAxes, abs(app.showable.pattern));
                otherwise
                    error('Unknown device type selected');
            end
            axis(app.SlmUIAxes, 'image');
            
            % Display the camera pattern
            imagesc(app.CameraUIAxes, app.viewable.view());
            axis(app.CameraUIAxes, 'image');
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setupDevices();
            
            % Display colorbars on figures
            colorbar(app.SlmUIAxes);
            colorbar(app.CameraUIAxes);
            
            % Setup a timer to update the preview
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.5, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedDelay', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.updatePreview;
            start(app.watchTimer);
        end

        % Value changed function: HeightSpinner, SLMTypeDropDown, 
        % WidthSpinner
        function inputValueChanged(app, event)
            app.setupDevices();
        end

        % Value changed function: CameraNameEditField, 
        % SLMNameEditField
        function nameValueChanged(app, event)
            app.saveDevices();
        end

        % Value changed function: ShowPreviewCheckBox
        function ShowPreviewCheckBoxValueChanged(app, event)
            if app.ShowPreviewCheckBox.Value
                start(app.watchTimer);
            else
                stop(app.watchTimer);
            end
        end

        % Close request function: SimulatedFarfieldImagingUIFigure
        function SimulatedFarfieldImagingUIFigureCloseRequest(app, event)
            try
                stop(app.watchTimer);
                delete(app.watchTimer);
            catch ME
                disp(getReport( ME, 'extended', 'hyperlinks', 'on' ));
            end
            delete(app)
        end

        % Value changed function: NASpinner, OffsetSpinner
        function cameraValueChanged(app, event)
            app.viewable.NA = app.NASpinner.Value;
            app.viewable.offset = app.OffsetSpinner.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulatedFarfieldImagingUIFigure = uifigure;
            app.SimulatedFarfieldImagingUIFigure.Name = 'Simulated Farfield Imaging';
            app.SimulatedFarfieldImagingUIFigure.CloseRequestFcn = createCallbackFcn(app, @SimulatedFarfieldImagingUIFigureCloseRequest, true);
            app.SlmUIAxes = uiaxes(app.SimulatedFarfieldImagingUIFigure);
            title(app.SlmUIAxes, 'SLM')
            app.SlmUIAxes.XTick = [];
            app.SlmUIAxes.XTickLabel = {'[ ]'};
            app.SlmUIAxes.YTick = [];
            app.Panel = uipanel(app.SimulatedFarfieldImagingUIFigure);
            app.SLMNameEditFieldLabel = uilabel(app.Panel);
            app.SLMNameEditFieldLabel.Text = 'SLM Name';
            app.SLMNameEditField = uieditfield(app.Panel, 'text');
            app.SLMNameEditField.ValueChangedFcn = createCallbackFcn(app, @nameValueChanged, true);
            app.SizeSpinnerLabel = uilabel(app.Panel);
            app.SizeSpinnerLabel.Text = 'Size';
            app.WidthSpinner = uispinner(app.Panel);
            app.WidthSpinner.Limits = [1 Inf];
            app.WidthSpinner.RoundFractionalValues = 'on';
            app.WidthSpinner.ValueDisplayFormat = '%.0f';
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.WidthSpinner.Value = 256;
            app.HeightSpinner = uispinner(app.Panel);
            app.HeightSpinner.Limits = [1 Inf];
            app.HeightSpinner.RoundFractionalValues = 'on';
            app.HeightSpinner.ValueDisplayFormat = '%.0f';
            app.HeightSpinner.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.HeightSpinner.Value = 256;
            app.CameraNameEditFieldLabel = uilabel(app.Panel);
            app.CameraNameEditFieldLabel.Text = 'Camera Name';
            app.CameraNameEditField = uieditfield(app.Panel, 'text');
            app.CameraNameEditField.ValueChangedFcn = createCallbackFcn(app, @nameValueChanged, true);
            app.ShowPreviewCheckBox = uicheckbox(app.Panel);
            app.ShowPreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPreviewCheckBoxValueChanged, true);
            app.ShowPreviewCheckBox.Text = 'Show Preview';
            app.ShowPreviewCheckBox.Value = true;
            app.SLMTypeDropDownLabel = uilabel(app.Panel);
            app.SLMTypeDropDownLabel.Text = 'SLM Type';
            app.SLMTypeDropDown = uidropdown(app.Panel);
            app.SLMTypeDropDown.Items = {'Phase', 'Binary Amplitude'};
            app.SLMTypeDropDown.ItemsData = {'phase', 'binamplitude'};
            app.SLMTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.SLMTypeDropDown.Value = 'phase';
            app.OffsetSpinnerLabel = uilabel(app.Panel);
            app.OffsetSpinnerLabel.Text = 'Offset';
            app.OffsetSpinner = uispinner(app.Panel);
            app.OffsetSpinner.Step = 0.5;
            app.OffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @cameraValueChanged, true);
            app.NASpinnerLabel = uilabel(app.Panel);
            app.NASpinnerLabel.Text = 'NA';
            app.NASpinner = uispinner(app.Panel);
            app.NASpinner.Step = 0.1;
            app.NASpinner.Limits = [0 1];
            app.NASpinner.ValueChangedFcn = createCallbackFcn(app, @cameraValueChanged, true);
            app.NASpinner.Value = 1;
            app.CameraUIAxes = uiaxes(app.SimulatedFarfieldImagingUIFigure);
            title(app.CameraUIAxes, 'Camera')
            app.CameraUIAxes.XTick = [];
            app.CameraUIAxes.XTickLabel = {'[ ]'};
            app.CameraUIAxes.YTick = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = TestFarfield

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulatedFarfieldImagingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulatedFarfieldImagingUIFigure)
        end
    end
end