classdef TestMichelson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulatedMichelsonInterferometerUIFigure  matlab.ui.Figure
        SlmUIAxes                 matlab.ui.control.UIAxes
        Panel                     matlab.ui.container.Panel
        SLMNameEditFieldLabel     matlab.ui.control.Label
        SLMNameEditField          matlab.ui.control.EditField
        SizeSpinnerLabel          matlab.ui.control.Label
        WidthSpinner              matlab.ui.control.Spinner
        HeightSpinner             matlab.ui.control.Spinner
        CameraNameEditFieldLabel  matlab.ui.control.Label
        CameraNameEditField       matlab.ui.control.EditField
        TiltSpinnerLabel          matlab.ui.control.Label
        TiltSpinner               matlab.ui.control.Spinner
        ShowPreviewCheckBox       matlab.ui.control.CheckBox
        CameraUIAxes              matlab.ui.control.UIAxes
    end


    properties (Access = public)
        viewable     otslm.utils.TestMichelson % The michelson interferometer
        showable     otslm.utils.TestSlm       % The SLM we can display patterns to
        watchTimer   % Timer for updating the gui
    end

    methods (Access = public)
        
        function saveDevice(app, name, var, tip)
            % Save the device to the base workspace
            
            % Check if there is anyghing to do
            if isempty(var) || isempty(name)
                return;
            end
            
            % Check variable name is valid
            if ~isvarname(name)
                error([tip ' name must be valid variable name']);
            end
            
            % Write colormap to base workspace
            assignin('base', name, var);
        end
    
        function saveDevices(app)
            app.saveDevice(app.SLMNameEditField.Value, app.showable, 'SLM');
            app.saveDevice(app.CameraNameEditField.Value, app.viewable, 'Camera');
        end
    
        function setupCamera(app)
            
            % Delete old camera
            delete(app.viewable);
            app.viewable = otslm.utils.TestMichelson.empty();
            
            % Get inputs from UI
            tilt = app.TiltSpinner.Value;
            
            % Setup device
            app.viewable = otslm.utils.TestMichelson(app.showable, 'tilt', tilt);
            
            % Save the devices to the base workspace
            app.saveDevices();
        end
    
        function setupDevices(app)
            
            % Delete old slm
            delete(app.showable);
            app.showable = otslm.utils.TestSlm.empty();
            
            % Delete old camera
            delete(app.viewable);
            app.viewable = otslm.utils.TestMichelson.empty();
            
            % Get inputs from UI
            width = app.WidthSpinner.Value;
            height = app.HeightSpinner.Value;
            tilt = app.TiltSpinner.Value;
            
            % Construct the devices
            app.showable = otslm.utils.TestSlm('size', [height, width]);
            app.viewable = otslm.utils.TestMichelson(app.showable, 'tilt', tilt);
            
            % Save the devices to the base workspace
            app.saveDevices();
            
        end
    
        function updatePreview(app, timer, event)
            
            % Clear the current content
%             cla(app.SlmUIAxes, 'reset');
%             set(app.SlmUIAxes, 'XTick', []);
%             set(app.SlmUIAxes, 'YTick', []);
%             app.SlmUIAxes.Title.String = 'SLM';
%             cla(app.CameraUIAxes, 'reset');
%             set(app.CameraUIAxes, 'XTick', []);
%             set(app.CameraUIAxes, 'YTick', []);
%             app.CameraUIAxes.Title.String = 'Camera';
            
            % Check we have a device to watch
            if isempty(app.showable) || ~isvalid(app.showable) ...
                    || isempty(app.viewable) || ~isvalid(app.viewable)
                return;
            end
            
            % Display the SLM pattern
            imagesc(app.SlmUIAxes, angle(app.showable.pattern));
            axis(app.SlmUIAxes, 'image');
            
            % Display the camera pattern
            imagesc(app.CameraUIAxes, app.viewable.view());
            axis(app.CameraUIAxes, 'image');
            
%             drawnow;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setupDevices();
            
            % Display colorbars on figures
            colorbar(app.SlmUIAxes);
            colorbar(app.CameraUIAxes);
            
            % Setup a timer to update the preview
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.updatePreview;
            start(app.watchTimer);
        end

        % Value changed function: HeightSpinner, WidthSpinner
        function inputValueChanged(app, event)
            app.setupDevices();
        end

        % Value changed function: CameraNameEditField, 
        % SLMNameEditField
        function nameValueChanged(app, event)
            app.saveDevices();
        end

        % Value changed function: ShowPreviewCheckBox
        function ShowPreviewCheckBoxValueChanged(app, event)
            if app.ShowPreviewCheckBox.Value
                start(app.watchTimer);
            else
                stop(app.watchTimer);
            end
        end

        % Close request function: 
        % SimulatedMichelsonInterferometerUIFigure
        function SimulatedMichelsonInterferometerUIFigureCloseRequest(app, event)
            try
                stop(app.watchTimer);
                delete(app.watchTimer);
            catch ME
                disp(getReport( ME, 'extended', 'hyperlinks', 'on' ));
            end
            delete(app)
        end

        % Value changed function: TiltSpinner
        function cameraValueChanged(app, event)
            app.setupCamera();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulatedMichelsonInterferometerUIFigure = uifigure;
            app.SimulatedMichelsonInterferometerUIFigure.Name = 'Simulated Michelson Interferometer';
            app.SimulatedMichelsonInterferometerUIFigure.CloseRequestFcn = createCallbackFcn(app, @SimulatedMichelsonInterferometerUIFigureCloseRequest, true);
            app.SlmUIAxes = uiaxes(app.SimulatedMichelsonInterferometerUIFigure);
            title(app.SlmUIAxes, 'SLM')
            app.SlmUIAxes.XTick = [];
            app.SlmUIAxes.XTickLabel = {'[ ]'};
            app.SlmUIAxes.YTick = [];
            app.Panel = uipanel(app.SimulatedMichelsonInterferometerUIFigure);
            app.SLMNameEditFieldLabel = uilabel(app.Panel);
            app.SLMNameEditFieldLabel.Text = 'SLM Name';
            app.SLMNameEditField = uieditfield(app.Panel, 'text');
            app.SLMNameEditField.ValueChangedFcn = createCallbackFcn(app, @nameValueChanged, true);
            app.SizeSpinnerLabel = uilabel(app.Panel);
            app.SizeSpinnerLabel.Text = 'Size';
            app.WidthSpinner = uispinner(app.Panel);
            app.WidthSpinner.Limits = [1 Inf];
            app.WidthSpinner.RoundFractionalValues = 'on';
            app.WidthSpinner.ValueDisplayFormat = '%.0f';
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.WidthSpinner.Value = 256;
            app.HeightSpinner = uispinner(app.Panel);
            app.HeightSpinner.Limits = [1 Inf];
            app.HeightSpinner.RoundFractionalValues = 'on';
            app.HeightSpinner.ValueDisplayFormat = '%.0f';
            app.HeightSpinner.ValueChangedFcn = createCallbackFcn(app, @inputValueChanged, true);
            app.HeightSpinner.Value = 256;
            app.CameraNameEditFieldLabel = uilabel(app.Panel);
            app.CameraNameEditFieldLabel.Text = 'Camera Name';
            app.CameraNameEditField = uieditfield(app.Panel, 'text');
            app.CameraNameEditField.ValueChangedFcn = createCallbackFcn(app, @nameValueChanged, true);
            app.TiltSpinnerLabel = uilabel(app.Panel);
            app.TiltSpinnerLabel.Text = 'Tilt';
            app.TiltSpinner = uispinner(app.Panel);
            app.TiltSpinner.ValueChangedFcn = createCallbackFcn(app, @cameraValueChanged, true);
            app.ShowPreviewCheckBox = uicheckbox(app.Panel);
            app.ShowPreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPreviewCheckBoxValueChanged, true);
            app.ShowPreviewCheckBox.Text = 'Show Preview';
            app.ShowPreviewCheckBox.Value = true;
            app.CameraUIAxes = uiaxes(app.SimulatedMichelsonInterferometerUIFigure);
            title(app.CameraUIAxes, 'Camera')
            app.CameraUIAxes.XTick = [];
            app.CameraUIAxes.XTickLabel = {'[ ]'};
            app.CameraUIAxes.YTick = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = TestMichelson

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulatedMichelsonInterferometerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulatedMichelsonInterferometerUIFigure)
        end
    end
end