classdef Launcher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OTSLMLauncherUIFigure  matlab.ui.Figure
        TextArea               matlab.ui.control.TextArea
        Panel                  matlab.ui.container.Panel
        LaunchButton           matlab.ui.control.Button
        TitleLabel             matlab.ui.control.Label
        ItemTextArea           matlab.ui.control.TextArea
        CategoryPanel          matlab.ui.container.Panel
        CategoryListBox        matlab.ui.control.ListBox
        ApplicationPanel       matlab.ui.container.Panel
        ApplicationListBox     matlab.ui.control.ListBox
    end


    methods (Access = private)
    
        function data = ExamplesData(app)
            
            data = struct();
            
            switch app.ApplicationListBox.Value
                case 'Mixing Two Beams'
                    data.Name = 'Mixing Two Beams';
                    data.Description = ['This example shows how to generate a phase only diffraction ' ...
                        'grating to split a beam into two independently controllable spots.'];
                    data.AppName = 'otslm.ui.examples.MixingTwoBeams';
                case 'Dmd'
                    data.Name = 'Digital Micro-mirror Device';
                    data.Description = ['This example demonstrates how a digital micromirror device (DMD) ' ...
                        'can be simulated using the toolbox.  A DMD is a amplitude only device with small ' ...
                        'mirrors which deflect light in two directions corresponding to a on state and an ' ...
                        'off state for each pixel.  In this example we create a binary amplitude pattern ' ...
                        'for transforming a Gaussian beam to a LG beam in the far-field.'];
                    data.AppName = 'otslm.ui.examples.Dmd';
                otherwise
                    error('Unknown app entry');
            end
            
        end
    
        function data = SimpleData(app)
            
            data = struct();
            
            switch app.ApplicationListBox.Value
                case 'Aperture'
                    data.Name = 'Aperture';
                    data.Description = ['Function for generating different shaped apertures.'];
                    data.AppName = 'otslm.ui.simple.aperture';
                case 'Aspheric'
                    data.Name = 'Aspheric';
                    data.Description = ['Generates patterns corresponding to phase pattern for ' ...
                        'aspherical, parabolic and spherical lenses'];
                    data.AppName = 'otslm.ui.simple.aspheric';
                case 'Axicon'
                    data.Name = 'Axicon';
                    data.Description = ['Generates a linear radially increasing pattern that ' ...
                        'represents the phase pattern of a axicon'];
                    data.AppName = 'otslm.ui.simple.axicon';
                case 'Bessel'
                    data.Name = 'Bessel';
                    data.Description = ['Generaets amplitude and phase patterns corresponding to bessel beams'];
                    data.AppName = 'otslm.ui.simple.bessel';
                case 'Checkerboard'
                    data.Name = 'Checkerboard';
                    data.Description = ['Generaets a checkerboard pattern.  This can be useful for ' ...
                        'diffracting light into extreme angles'];
                    data.AppName = 'otslm.ui.simple.checkerboard';
                case 'Cubic'
                    data.Name = 'Cubic';
                    data.Description = ['Generaets a cubic pattern.  These can be used for generating ' ...
                        'airy beams or accelerating beams which appear to curve as they propogate'];
                    data.AppName = 'otslm.ui.simple.cubic';
                case 'Gaussian'
                    data.Name = 'Gaussian';
                    data.Description = ['Generate a Gaussian envelope.  Can be used to describe the ' ...
                        'incident laser profile'];
                    data.AppName = 'otslm.ui.simple.gaussian';
                case 'HG mode'
                    data.Name = 'Hermite-Gaussian Mode';
                    data.Description = ['Generates the amplitude and phase profiles for Hermite-Gaussian beams'];
                    data.AppName = 'otslm.ui.simple.hgmode';
                case 'LG mode'
                    data.Name = 'Laguerre-Gaussian Mode';
                    data.Description = ['Generates the amplitude and phase profiles for Laguerre-Gaussian beams'];
                    data.AppName = 'otslm.ui.simple.lgmode';
                case 'IG mode'
                    data.Name = 'Ince-Gaussian Mode';
                    data.Description = ['Generates the amplitude and phase profiles for Ince-Gaussian beams'];
                    data.AppName = 'otslm.ui.simple.igmode';
                case 'Linear'
                    data.Name = 'Linear';
                    data.Description = ['Generate a linear pattern.  This can be used to create a linear ' ...
                        'diffraction grating for deflecting a beam in the far-field.'];
                    data.AppName = 'otslm.ui.simple.linear';
                case 'Random'
                    data.Name = 'Random';
                    data.Description = ['Generates a random pattern'];
                    data.AppName = 'otslm.ui.simple.random';
                case 'Sinc'
                    data.Name = 'sinc';
                    data.Description = ['Generates the amplitude and phase profiles for sinc patterns'];
                    data.AppName = 'otslm.ui.simple.Sinc';
                case 'Sinusoid'
                    data.Name = 'sinusoid';
                    data.Description = ['Generates a 1-D or 2-D sinusoid profile'];
                    data.AppName = 'otslm.ui.simple.sinusoid';
                case 'Step'
                    data.Name = 'step';
                    data.Description = ['Generate a pattern with a sharp step.'];
                    data.AppName = 'otslm.ui.simple.step';
                case 'Zernike'
                    data.Name = 'Zernike';
                    data.Description = ['Generates zernike polynomial modes.  Can be a useful basis for ' ...
                        'representing light passing through a circular aparture.'];
                    data.AppName = 'otslm.ui.simple.zernike';
                otherwise
                    error('Unknown app entry');
            end
            
        end
    
        function data = IterativeData(app)
            
            data = struct();
            
            switch app.ApplicationListBox.Value
                case 'Gerchberg-Saxton'
                    data.Name = 'Gerchberg-Saxton';
                    data.Description = ['Gerchberg-Saxton and Adaptive-Adaptive algorithms.  Uses fourier ' ...
                        'transforms to iterate between the far-field phase pattern and near-field ' ...
                        'amplitude pattern'];
                    data.AppName = 'otslm.ui.iter.GerchbergSaxton';
                case 'Direct Search'
                    data.Name = 'Direct Search';
                    data.Description = ['Search through each pixel value to optimise hologram'];
                    data.AppName = 'otslm.ui.iter.DirectSearch';
                case 'Simulated Annealing'
                    data.Name = 'Simulated Annealing';
                    data.Description = ['Optimise the pattern using simulated annealing'];
                    data.AppName = 'otslm.ui.iter.SimulatedAnnealing';
%                 case 'BSC'
%                     data.Name = 'Beam Shape Coefficient Optimisation';
%                     data.Description = ['Attempt to optimise the vector spherical wave function ' ...
%                         'description of the beam'];
%                     data.AppName = 'otslm.ui.iter.bsc';
                case 'bowman2017'
                    data.Name = 'Bowman2017';
                    data.Description = ['Bowman 2017 conjugate gradient method.  See Bowman, et al. ' ...
                        'Optics Express 25, 11692 (2017).  If you use this method, please consider citing Bowman 2017.  '...
                        'Requires python and other dependencies to be installed.'];
                    data.AppName = 'otslm.ui.iter.bowman2017';
                otherwise
                    error('Unknown app entry');
            end
            
        end
    
        function data = UtilitiesData(app)
            
            data = struct();
            
            switch app.ApplicationListBox.Value
                case 'LoadColormap'
                    data.Name = 'Load Colormap';
                    data.Description = ['Load a colormap from a file for use with a phase based SLM'];
                    data.AppName = 'otslm.ui.utils.LoadColormap';
                case 'Calibration SMichelson'
                    data.Name = 'Calibration Sloped Michelson';
                    data.Description = ['Program to calibrate SLM using a michelson interferometer with the reference ' ...
                        'mirror tilted slightly with respect to the SLM.  Applies a step patter to the device and looks ' ...
                        'at the change in position of the interference fringes on the two sides of the device.'];
                    data.AppName = 'otslm.ui.utils.CalibrationSMichelson';
                case 'Calibration Step Farfield'
                    data.Name = 'Calibration Step Farfield';
                    data.Description = ['Program to calibrate SLM using a camera in the device far-field.  Uses a step ' ...
                        'function on the SLM and looks at the interference in the far-field.'];
                    data.AppName = 'otslm.ui.utils.CalibrationStepFarfield';
                case 'SetupImaqCamera'
                    data.Name = 'Setup IMAQ Camera';
                    data.Description = ['Setup a camera device using the image acquisition toolbox.  You may need to ' ...
                        'install the image acquisition toolbox and support packages for your camera.'];
                    data.AppName = 'otslm.ui.utils.SetupImaqCamera';
                case 'scan1d'
                    data.Name = 'Image Scan 1-D';
                    data.Description = ['Produce a linear scan of the device'];
                    data.AppName = 'otslm.ui.utils.ImageScan1d';
                case 'scan2d'
                    data.Name = 'Image Scan 2-D';
                    data.Description = ['Produce a raster scan of the device'];
                    data.AppName = 'otslm.ui.utils.ImageScan2d';
                case 'Screen Device'
                    data.Name = 'Screen Device';
                    data.Description = ['Setup a monitor as a SLM device'];
                    data.AppName = 'otslm.ui.utils.ScreenDevice';
                case 'Test Farfield'
                    data.Name = 'Test Far-field';
                    data.Description = ['Setup a virtual camera/slm for simulating far-field visualisation'];
                    data.AppName = 'otslm.ui.utils.TestFarfield';
                case 'Test Michelson'
                    data.Name = 'Test Michelson';
                    data.Description = ['Setup a virtual camera/slm for simulating a Michelson interferometer visualisation'];
                    data.AppName = 'otslm.ui.utils.TestMichelson';
                otherwise
                    error('Unknown app entry');
            end
            
        end
    
        function data = ToolsData(app)
            
            data = struct();
            
            switch app.ApplicationListBox.Value
                case 'Combine'
                    data.Name = 'Combine';
                    data.Description = ['Combine multiple patterns using different method'];
                    data.AppName = 'otslm.ui.tools.Combine';
                case 'Dither'
                    data.Name = 'Dither';
                    data.Description = ['Convert a grayscale pattern to a binary pattern'];
                    data.AppName = 'otslm.ui.tools.Dither';
                case 'Encode1d'
                    data.Name = 'Encode 1-D';
                    data.Description = ['Generate a phase pattern representation of an amplitude pattern'];
                    data.AppName = 'otslm.ui.tools.Encode1d';
                case 'Finalize'
                    data.Name = 'Finalize';
                    data.Description = ['Finalize a pattern.  This may include adding a colormap, converting ' ...
                        'the pattern range from 0-1 to -pi to pi or reshaping the pattern to simulate a ' ...
                        'device with non-rectangular packing'];
                    data.AppName = 'otslm.ui.tools.Finalize';
                case 'MaskRegion'
                    data.Name = 'Mask Region';
                    data.Description = ['Combine patterns by masking different regions'];
                    data.AppName = 'otslm.ui.tools.MaskRegion';
                case 'SampleRegion'
                    data.Name = 'Sample Region';
                    data.Description = ['Place linear gratings on different regions of a SLM to ' ...
                        'sample the light from these regions'];
                    data.AppName = 'otslm.ui.tools.SampleRegion';
                case 'Visualise'
                    data.Name = 'Visualise';
                    data.Description = ['Use different methods to visualise the far-field ' ...
                        'diffraction pattern for different phase and amplitude device patterns'];
                    data.AppName = 'otslm.ui.tools.Visualise';
                otherwise
                    error('Unknown app entry');
            end
            
        end
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Initialize the second list box
            app.CategoryListBox.Value = 'Examples';
            app.CategoryListBoxValueChanged([]);
        end

        % Value changed function: CategoryListBox
        function CategoryListBoxValueChanged(app, event)
            
            % Seutp the contents of the second list box
            switch app.CategoryListBox.Value
                case 'Examples'
                    app.ApplicationListBox.Items = {'Mixing Two Beams', 'DMD'};
                    app.ApplicationListBox.ItemsData = {'Mixing Two Beams', 'Dmd'};
                case 'Simple'
                    app.ApplicationListBox.Items = {'Aperture', 'Aspheric', 'Axicon', ...
                        'Bessel', 'Checkerboard', 'Cubic', 'Gaussian', 'HG mode', ...
                        'LG mode', 'IG mode', 'Linear', 'Random', 'Sinc', 'Sinusoid', ...
                        'Step', 'Zernike'};
                    app.ApplicationListBox.ItemsData = {'Aperture', 'Aspheric', 'Axicon', ...
                        'Bessel', 'Checkerboard', 'Cubic', 'Gaussian', 'HG mode', ...
                        'LG mode', 'IG mode', 'Linear', 'Random', 'Sinc', 'Sinusoid', ...
                        'Step', 'Zernike'};
                case 'Iterative'
                    app.ApplicationListBox.Items = {'Gerchberg-Saxton', 'Direct Search', ...
                        'Simulated Annealing', 'bowman2017'};
                    app.ApplicationListBox.ItemsData = {'Gerchberg-Saxton', 'Direct Search', ...
                        'Simulated Annealing', 'bowman2017'};
                case 'Tools'
                    app.ApplicationListBox.Items = {'Combine', 'Dither', 'Encode1d', ...
                        'Finalize', 'MaskRegion', 'SampleRegion', 'Visualise'};
                    app.ApplicationListBox.ItemsData = {'Combine', 'Dither', 'Encode1d', ...
                        'Finalize', 'MaskRegion', 'SampleRegion', 'Visualise'};
                case 'Utilities'
                    app.ApplicationListBox.Items = {'LoadColormap', 'Calibration SMichelson', 'Calibration Step Farfield', 'IMAQ Camera', ...
                        'Image Scan 1-D', 'Image Scan 2-D', 'Screen Device', 'Test Michelson', 'Test Farfield'};
                    app.ApplicationListBox.ItemsData = {'LoadColormap', 'Calibration SMichelson', 'Calibration Step Farfield', 'SetupImaqCamera', ...
                        'scan1d', 'scan2d', 'Screen Device', 'Test Michelson', 'Test Farfield'};
                otherwise
                    error('Internal error: invalid ListBox item name');
            end
            
            % Set the value of the second list box
            app.ApplicationListBox.Value = app.ApplicationListBox.ItemsData{1};
            app.ApplicationListBoxValueChanged(event);
            
        end

        % Value changed function: ApplicationListBox
        function ApplicationListBoxValueChanged(app, event)
            
            % Get the data for the application
            switch app.CategoryListBox.Value
                case 'Examples'
                    data = app.ExamplesData();
                case 'Simple'
                    data = app.SimpleData();
                case 'Iterative'
                    data = app.IterativeData();
                case 'Tools'
                    data = app.ToolsData();
                case 'Utilities'
                    data = app.UtilitiesData();
                otherwise
                    error('Internal error: invalid ListBox item name');
            end
            
            app.TitleLabel.Text = data.Name;
            app.ItemTextArea.Value = data.Description;
            app.LaunchButton.UserData = data.AppName;
            
            % If the user double clicks, fire the launch event
            % Hmm, doesn't seem to work
%             if strcmpi(get(app.OTSLMLauncherUIFigure,'SelectionType'),'open')
%                 app.LaunchButtonPushed(event);
%             end
                
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            
            % Launch the user interface
            if ~isempty(app.LaunchButton.UserData)
                evalin('base', app.LaunchButton.UserData);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OTSLMLauncherUIFigure = uifigure;
            app.OTSLMLauncherUIFigure.Name = 'OTSLM Launcher';
            app.TextArea = uitextarea(app.OTSLMLauncherUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'otslm - Optical Tweezers SLM Pattern Generator'; ''; 'OTSLM is a set of Matlab functions and graphical user interface for generating patterns for phase and amplitude spatial light modulators (SLMs) such as the digital micromirror device (DMD) and liquid crystal type device. The focus of this toolbox is on patterns for optical tweezers systems but the same functions can probably be used in other applications where amplitude or phase control of light is required.'; ''; 'This is the launcher for the graphical user interface.  If you are using this interface for the first time we suggest you take a look at the examples tab and run a couple of the example to see how the different user interface components fit together.'; ''; 'If you find this package useful in academic work, please consider citing it:'; ''; 'I. C. D. Lenton, A. B. Stilgoe, T. A. Nieminen, H. Rubinsztein-Dunlop, "Routine and simplified generation of SLM patterns for optical tweezers", [Journal to be decided](link to the article)'};
            app.Panel = uipanel(app.OTSLMLauncherUIFigure);
            app.LaunchButton = uibutton(app.Panel, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Text = 'Launch';
            app.TitleLabel = uilabel(app.Panel);
            app.TitleLabel.Text = 'Title';
            app.ItemTextArea = uitextarea(app.Panel);
            app.CategoryPanel = uipanel(app.OTSLMLauncherUIFigure);
            app.CategoryPanel.Title = 'Category';
            app.CategoryListBox = uilistbox(app.CategoryPanel);
            app.CategoryListBox.Items = {'Examples', 'Simple', 'Iterative', 'Tools', 'Utilities'};
            app.CategoryListBox.ItemsData = {'Examples', 'Simple', 'Iterative', 'Tools', 'Utilities'};
            app.CategoryListBox.ValueChangedFcn = createCallbackFcn(app, @CategoryListBoxValueChanged, true);
            app.CategoryListBox.Value = 'Examples';
            app.ApplicationPanel = uipanel(app.OTSLMLauncherUIFigure);
            app.ApplicationPanel.Title = 'Application';
            app.ApplicationListBox = uilistbox(app.ApplicationPanel);
            app.ApplicationListBox.Items = {};
            app.ApplicationListBox.ValueChangedFcn = createCallbackFcn(app, @ApplicationListBoxValueChanged, true);
            app.ApplicationListBox.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Launcher

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OTSLMLauncherUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OTSLMLauncherUIFigure)
        end
    end
end