classdef lgmode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LGBeamUIFigure             matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        DisplayDropDownLabel       matlab.ui.control.Label
        DisplayDropDown            matlab.ui.control.DropDown
        LinearGratingPanel         matlab.ui.container.Panel
        SpacingSpinnerLabel        matlab.ui.control.Label
        SpacingSpinner             matlab.ui.control.Spinner
        AngledegSpinnerLabel       matlab.ui.control.Label
        AngledegSpinner            matlab.ui.control.Spinner
        SizeEditFieldLabel         matlab.ui.control.Label
        SizeEditField              matlab.ui.control.EditField
        PatternCorrectionPanel     matlab.ui.container.Panel
        YOffsetSpinnerLabel        matlab.ui.control.Label
        YOffsetSpinner             matlab.ui.control.Spinner
        RadiusSpinnerLabel         matlab.ui.control.Label
        RadiusSpinner              matlab.ui.control.Spinner
        CorrectionSpinnerLabel     matlab.ui.control.Label
        CorrectionSpinner          matlab.ui.control.Spinner
        XOffsetSpinnerLabel        matlab.ui.control.Label
        XOffsetSpinner             matlab.ui.control.Spinner
        RadialSpinnerLabel         matlab.ui.control.Label
        RadialSpinner              matlab.ui.control.Spinner
        AzimuthalSpinnerLabel      matlab.ui.control.Label
        AzimuthalSpinner           matlab.ui.control.Spinner
        UpdateDeviceCheckBox       matlab.ui.control.CheckBox
        DeviceEditFieldLabel       matlab.ui.control.Label
        DeviceEditField            matlab.ui.control.EditField
        NameEditFieldLabel         matlab.ui.control.Label
        NameEditField              matlab.ui.control.EditField
        EnableDisplayCheckBox      matlab.ui.control.CheckBox
        DisplayNameEditFieldLabel  matlab.ui.control.Label
        DisplayNameEditField       matlab.ui.control.EditField
        EnableCheckBox             matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('../../');
            app.patternValueChanged([]);
        end

        % Value changed function: AngledegSpinner, AzimuthalSpinner, 
        % CorrectionSpinner, EnableCheckBox, RadialSpinner, 
        % RadiusSpinner, SizeEditField, SpacingSpinner, XOffsetSpinner, 
        % YOffsetSpinner
        function patternValueChanged(app, event)
            
            sz = eval(['[', app.SizeEditField.Value, ']']);
            rmode = app.RadialSpinner.Value;
            amode = app.AzimuthalSpinner.Value;
            radius = app.RadiusSpinner.Value;
            p0 = app.CorrectionSpinner.Value;
            cx = app.XOffsetSpinner.Value;
            cy = app.YOffsetSpinner.Value;
            pattern = otslm.simple.lgmode(sz, amode, rmode, ...
                'radius', radius, 'p0', p0, 'centre', ceil(sz/2) + [cx, cy]);
            
            spacing = app.SpacingSpinner.Value;
            if spacing ~= 0 && app.EnableCheckBox.Value == true
                angle = app.AngledegSpinner.Value;
                linear = otslm.simple.linear(sz, 'spacing', spacing, 'angle_deg', angle);
                pattern = pattern + linear;
            end
            
            pattern = otslm.tools.finalize(pattern, 'colormap', 'gray');
            
            % Try displaying the pattern
            try
                slm =  evalin('base', 'slm');
                slm.show(pattern);
            catch e
                disp(e);
            end
            
            imagesc(app.UIAxes, pattern);
            axis(app.UIAxes, 'image');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LGBeamUIFigure = uifigure;
            app.LGBeamUIFigure.Name = 'LG Beam';
            app.UIAxes = uiaxes(app.LGBeamUIFigure);
            title(app.UIAxes, 'Display')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DisplayDropDownLabel = uilabel(app.LGBeamUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.LGBeamUIFigure);
            app.DisplayDropDown.Items = {'Phase Mask', 'SLM image', 'Simulated farfield'};
            app.DisplayDropDown.Value = 'Phase Mask';
            app.LinearGratingPanel = uipanel(app.LGBeamUIFigure);
            app.LinearGratingPanel.Title = 'Linear Grating';
            app.SpacingSpinnerLabel = uilabel(app.LinearGratingPanel);
            app.SpacingSpinnerLabel.Text = 'Spacing';
            app.SpacingSpinner = uispinner(app.LinearGratingPanel);
            app.SpacingSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.AngledegSpinnerLabel = uilabel(app.LinearGratingPanel);
            app.AngledegSpinnerLabel.Text = 'Angle (deg)';
            app.AngledegSpinner = uispinner(app.LinearGratingPanel);
            app.AngledegSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.SizeEditFieldLabel = uilabel(app.LGBeamUIFigure);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.LGBeamUIFigure, 'text');
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.SizeEditField.Value = '512, 512';
            app.PatternCorrectionPanel = uipanel(app.LGBeamUIFigure);
            app.PatternCorrectionPanel.Title = 'Pattern Correction';
            app.YOffsetSpinnerLabel = uilabel(app.PatternCorrectionPanel);
            app.YOffsetSpinnerLabel.Text = 'Y Offset';
            app.YOffsetSpinner = uispinner(app.PatternCorrectionPanel);
            app.YOffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.RadiusSpinnerLabel = uilabel(app.PatternCorrectionPanel);
            app.RadiusSpinnerLabel.Text = 'Radius';
            app.RadiusSpinner = uispinner(app.PatternCorrectionPanel);
            app.RadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.RadiusSpinner.Value = 50;
            app.CorrectionSpinnerLabel = uilabel(app.PatternCorrectionPanel);
            app.CorrectionSpinnerLabel.Text = 'Correction';
            app.CorrectionSpinner = uispinner(app.PatternCorrectionPanel);
            app.CorrectionSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.CorrectionSpinner.Value = 1;
            app.XOffsetSpinnerLabel = uilabel(app.PatternCorrectionPanel);
            app.XOffsetSpinnerLabel.Text = 'X Offset';
            app.XOffsetSpinner = uispinner(app.PatternCorrectionPanel);
            app.XOffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.RadialSpinnerLabel = uilabel(app.LGBeamUIFigure);
            app.RadialSpinnerLabel.Text = 'Radial';
            app.RadialSpinner = uispinner(app.LGBeamUIFigure);
            app.RadialSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.AzimuthalSpinnerLabel = uilabel(app.LGBeamUIFigure);
            app.AzimuthalSpinnerLabel.Text = 'Azimuthal';
            app.AzimuthalSpinner = uispinner(app.LGBeamUIFigure);
            app.AzimuthalSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox = uicheckbox(app.LGBeamUIFigure);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.DeviceEditFieldLabel = uilabel(app.LGBeamUIFigure);
            app.DeviceEditFieldLabel.Text = 'Device';
            app.DeviceEditField = uieditfield(app.LGBeamUIFigure, 'text');
            app.NameEditFieldLabel = uilabel(app.LGBeamUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.LGBeamUIFigure, 'text');
            app.EnableDisplayCheckBox = uicheckbox(app.LGBeamUIFigure);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.DisplayNameEditFieldLabel = uilabel(app.LGBeamUIFigure);
            app.DisplayNameEditFieldLabel.Text = 'Display Name';
            app.DisplayNameEditField = uieditfield(app.LGBeamUIFigure, 'text');
            app.EnableCheckBox = uicheckbox(app.LGBeamUIFigure);
            app.EnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableCheckBox.Text = 'Enable';
        end
    end

    methods (Access = public)

        % Construct app
        function app = lgmode

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LGBeamUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LGBeamUIFigure)
        end
    end
end