classdef sample_region < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GenerateSampleRegionUIFigure  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        RegionTab                     matlab.ui.container.Tab
        PositionEditFieldLabel        matlab.ui.control.Label
        PositionEditField             matlab.ui.control.EditField
        TargetEditFieldLabel          matlab.ui.control.Label
        TargetEditField               matlab.ui.control.EditField
        FilterDropDownLabel           matlab.ui.control.Label
        FilterDropDown                matlab.ui.control.DropDown
        FilterParamsEditFieldLabel    matlab.ui.control.Label
        FilterParamsEditField         matlab.ui.control.EditField
        RadiusEditFieldLabel          matlab.ui.control.Label
        RadiusEditField               matlab.ui.control.EditField
        BeamNameEditFieldLabel        matlab.ui.control.Label
        BeamNameEditField             matlab.ui.control.EditField
        BackgroundDropDownLabel       matlab.ui.control.Label
        BackgroundDropDown            matlab.ui.control.DropDown
        SizeEditFieldLabel            matlab.ui.control.Label
        SizeEditField                 matlab.ui.control.EditField
        UIAxes                        matlab.ui.control.UIAxes
        AddRegionButton               matlab.ui.control.Button
        RemoveRegionButton            matlab.ui.control.Button
    end


    properties (Access = private)
        beamData; % The beam data this object has generated
    end

    methods (Access = private)
    
        function saveData(app)
            varname = app.BeamNameEditField.Value;
            if isvarname(varname)
                assignin('base', varname, app.beamData);
            end
        end
        
        function updateData(app)
            
            % Get beam size
            sz = eval(['[', app.SizeEditField.Value, ']']);
            if length(sz) == 1
                sz = [sz, sz];
            elseif length(sz) == 2
                % nothing to do
            else
                error('Size must be one or two elements');
            end
            
            background = app.BackgroundDropDown.Value;
            
            locations = {};
            detectors = {};
            amplitudes = {};
            radius = [];
            
            % Get parameters from user interface
            for ii = 1:length(app.TabGroup.Children)
                tab = app.TabGroup.Children(ii);
                
                locationEntry = findall(tab, 'UserData', 'location');
                radiusEntry = findall(tab, 'UserData', 'radius');
                targetEntry = findall(tab, 'UserData', 'target');
                filterEntry = findall(tab, 'UserData', 'filter');
                filterParamsEntry = findall(tab, 'UserData', 'filter_params');
                
                locations{ii} = eval(['[', locationEntry.Value, ']']);
                detectors{ii} = eval(['[', targetEntry.Value, ']']);
                radius(ii) = eval(['[', radiusEntry.Value, ']']);
                amplitudes{ii} = {filterEntry.Value};
                amplitudes{ii} = [amplitudes{ii}, eval(['{', filterParamsEntry.Value, '}'])];
            end
            
            % Call the library function
            app.beamData = otslm.tools.sample_region(sz, locations, detectors, ...
                'background', background, 'amplitude', amplitudes, ...
                'radii', radius);
            
            % Display the image
            imagesc(app.UIAxes, app.beamData);
            axis(app.UIAxes, [1, sz(2), 1, sz(1)]);
            axis(app.UIAxes, 'image');
            
            % Always try and save after updating
            app.saveData();
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Hide the template tab
            app.RegionTab.Parent = [];
            app.PositionEditField.UserData = 'location';
            app.TargetEditField.UserData = 'target';
            app.RadiusEditField.UserData = 'radius';
            app.FilterDropDown.UserData = 'filter';
            app.FilterParamsEditField.UserData = 'filter_params';
            app.RemoveRegionButton.Enable = 'off';
            
            app.updateData();
        end

        % Callback function
        function BeamNameEditFieldValueChanged(app, event)
            app.saveData();
        end

        % Value changed function: SizeEditField
        function SizeEditFieldValueChanged(app, event)
            app.updateData();
        end

        % Value changed function: BackgroundDropDown
        function BackgroundDropDownValueChanged(app, event)
            app.updateData();
        end

        % Button pushed function: RemoveRegionButton
        function RemoveRegionButtonPushed(app, event)
            % TODO: Remove the current region
            selectedTab = app.TabGroup.SelectedTab;
            if ~isempty(selectedTab)
                selectedTab.Parent = [];
                if isempty(app.TabGroup.Children)
                    app.RemoveRegionButton.Enable = 'off';
                end
            end
            app.updateData();
        end

        % Button pushed function: AddRegionButton
        function AddRegionButtonPushed(app, event)
            tab = copyobj(app.RegionTab, app.TabGroup);
            
            % Add callbacks to new object
            locationEntry = findall(tab, 'UserData', 'location');
            targetEntry = findall(tab, 'UserData', 'target');
            radiusEntry = findall(tab, 'UserData', 'radius');
            filterEntry = findall(tab, 'UserData', 'filter');
            filterParamsEntry = findall(tab, 'UserData', 'filter_params');
            locationEntry.ValueChangedFcn = createCallbackFcn(app, @RegionValueChanged, true);
            targetEntry.ValueChangedFcn = createCallbackFcn(app, @RegionValueChanged, true);
            radiusEntry.ValueChangedFcn = createCallbackFcn(app, @RegionValueChanged, true);
            filterEntry.ValueChangedFcn = createCallbackFcn(app, @RegionValueChanged, true);
            filterParamsEntry.ValueChangedFcn = createCallbackFcn(app, @RegionValueChanged, true);
            
            app.RemoveRegionButton.Enable = 'on';
            app.updateData();
        end

        % Value changed function: FilterDropDown, 
        % FilterParamsEditField, PositionEditField, TargetEditField
        function RegionValueChanged(app, event)
            app.updateData();
        end

        % Value changed function: BeamNameEditField
        function BeamNameEditFieldValueChanged2(app, event)
            app.updateData();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GenerateSampleRegionUIFigure = uifigure;
            app.GenerateSampleRegionUIFigure.Name = 'Generate Sample Region';
            app.TabGroup = uitabgroup(app.GenerateSampleRegionUIFigure);
            app.RegionTab = uitab(app.TabGroup);
            app.RegionTab.Title = 'Region';
            app.PositionEditFieldLabel = uilabel(app.RegionTab);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.RegionTab, 'text');
            app.PositionEditField.ValueChangedFcn = createCallbackFcn(app, @RegionValueChanged, true);
            app.PositionEditField.Value = '100,100';
            app.TargetEditFieldLabel = uilabel(app.RegionTab);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TargetEditField = uieditfield(app.RegionTab, 'text');
            app.TargetEditField.ValueChangedFcn = createCallbackFcn(app, @RegionValueChanged, true);
            app.TargetEditField.Value = '10,10';
            app.FilterDropDownLabel = uilabel(app.RegionTab);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.RegionTab);
            app.FilterDropDown.Items = {'Step', 'Gaussian Dither', 'Gaussian Noise', 'Gaussian Scale'};
            app.FilterDropDown.ItemsData = {'step', 'gaussian_dither', 'gaussian_noise', 'gaussian_scale'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionValueChanged, true);
            app.FilterDropDown.Value = 'step';
            app.FilterParamsEditFieldLabel = uilabel(app.RegionTab);
            app.FilterParamsEditFieldLabel.Text = 'Filter Params';
            app.FilterParamsEditField = uieditfield(app.RegionTab, 'text');
            app.FilterParamsEditField.ValueChangedFcn = createCallbackFcn(app, @RegionValueChanged, true);
            app.RadiusEditFieldLabel = uilabel(app.RegionTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.RegionTab, 'text');
            app.RadiusEditField.Value = '10';
            app.BeamNameEditFieldLabel = uilabel(app.GenerateSampleRegionUIFigure);
            app.BeamNameEditFieldLabel.Text = 'Beam Name';
            app.BeamNameEditField = uieditfield(app.GenerateSampleRegionUIFigure, 'text');
            app.BeamNameEditField.ValueChangedFcn = createCallbackFcn(app, @BeamNameEditFieldValueChanged2, true);
            app.BackgroundDropDownLabel = uilabel(app.GenerateSampleRegionUIFigure);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.GenerateSampleRegionUIFigure);
            app.BackgroundDropDown.Items = {'Zero', 'NaN', 'Checker Board', 'Random', 'Random Binary'};
            app.BackgroundDropDown.ItemsData = {'zero', 'nan', 'checkerboard', 'random', 'randombin'};
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundDropDownValueChanged, true);
            app.BackgroundDropDown.Value = 'zero';
            app.SizeEditFieldLabel = uilabel(app.GenerateSampleRegionUIFigure);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.GenerateSampleRegionUIFigure, 'text');
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @SizeEditFieldValueChanged, true);
            app.SizeEditField.Value = '512, 512';
            app.UIAxes = uiaxes(app.GenerateSampleRegionUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AddRegionButton = uibutton(app.GenerateSampleRegionUIFigure, 'push');
            app.AddRegionButton.ButtonPushedFcn = createCallbackFcn(app, @AddRegionButtonPushed, true);
            app.AddRegionButton.Text = 'Add Region';
            app.RemoveRegionButton = uibutton(app.GenerateSampleRegionUIFigure, 'push');
            app.RemoveRegionButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRegionButtonPushed, true);
            app.RemoveRegionButton.Text = 'Remove Region';
        end
    end

    methods (Access = public)

        % Construct app
        function app = sample_region

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GenerateSampleRegionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GenerateSampleRegionUIFigure)
        end
    end
end